classdef Analysis_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AverageButton                   matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        SelectGoodTrialButton           matlab.ui.control.Button
        ElectrodetsvButton              matlab.ui.control.Button
        RejectTrialsButton              matlab.ui.control.Button
        PipelinePanel                   matlab.ui.container.Panel
        RunPipelineButton               matlab.ui.control.Button
        ImportPipelineButton            matlab.ui.control.Button
        ClearPipelineButton             matlab.ui.control.Button
        OpenPipelineBuilderButton       matlab.ui.control.Button
        UpdateEventsButton              matlab.ui.control.Button
        UpdateHistoryButton             matlab.ui.control.Button
        ClearEventsButton               matlab.ui.control.Button
        ClearHistoryButton              matlab.ui.control.Button
        PipelineTextArea                matlab.ui.control.TextArea
        EventsDetectedPanel             matlab.ui.container.Panel
        HistoryPanel                    matlab.ui.container.Panel
        ProtocolPanel                   matlab.ui.container.Panel
        ReloadProtocolButton            matlab.ui.control.Button
        DeleteProtocolButton            matlab.ui.control.Button
        LoadProtocolButton              matlab.ui.control.Button
        CreateProtocolButton            matlab.ui.control.Button
        SubjectStudyPanel               matlab.ui.container.Panel
        ReviewRawFilesButton            matlab.ui.control.Button
        DeleteStudySubjectButton        matlab.ui.control.Button
        CreateSubjectButton             matlab.ui.control.Button
        UtilityPanel                    matlab.ui.container.Panel
        DeleteEventsButton              matlab.ui.control.Button
        RenameEventsButton              matlab.ui.control.Button
        ConvertToBIDSButton             matlab.ui.control.Button
        ColorMode                       matlab.ui.control.Button
        ImportinDataBaseButton          matlab.ui.control.Button
        TypeofanalysisDropDownLabel     matlab.ui.control.Label
        TypeofanalysisDropDown          matlab.ui.control.DropDown
        OpenBrainstormButton            matlab.ui.control.Button
        FrequencyAnalysisPanel          matlab.ui.container.Panel
        PowerSpectrumDensityButton      matlab.ui.control.Button
        BandpassFilterButton            matlab.ui.control.Button
        NotchFilterButton               matlab.ui.control.Button
        TabGroup1                       matlab.ui.container.TabGroup
        EEGTabGr1                       matlab.ui.container.Tab
        ImportEventsButton              matlab.ui.control.Button
        DetectHeartbeatsButton          matlab.ui.control.Button
        ProjectElectrodesonScalpButton  matlab.ui.control.Button
        RefineRegistrationButton        matlab.ui.control.Button
        AddEEGPositionsButton           matlab.ui.control.Button
        MEGTabGr1                       matlab.ui.container.Tab
        ConvertEpochstoContinueButton   matlab.ui.control.Button
        SelectDeselectallButton         matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        EEGTabGr2                       matlab.ui.container.Tab
        ICAEEGButton                    matlab.ui.control.Button
        AverageReferenceButton          matlab.ui.control.Button
        MEGTabGr2                       matlab.ui.container.Tab
        ICAMEGButton                    matlab.ui.control.Button
        SSPButton                       matlab.ui.control.Button
        DetectArtifactButton            matlab.ui.control.Button
        RemoveSimultaneousEventsButton  matlab.ui.control.Button
        CurrentProtocolLabel            matlab.ui.control.Label
        CurrentProtocolEditField        matlab.ui.control.EditField
        UpdateTreeButton                matlab.ui.control.Button
    end


    properties (Access = public)

        % Global variables initialized as empty.

        % Global Variable
        AnalysisType = "";
        SensorType = "";
        FileFormat = "";
        AnatomyFileFormat = "";
        UseDefaultAnatomy = "";
        PipelineSelected = struct();
        
        % Folder Name
        AppFolderName = "__APP__";
        
        % Paths
        Alab1Path = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/"
        RawDataPath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/" % By default
        BsToolboxPath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/rg/toolboxes/brainstorm3/";
        BsDatabasePath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/rg/bs_db/";
        ToolPath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/scripts/AnalysisTool/";
        WorkingFolderPath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/DefaultWorkingFolder/"; % Default Working Folder
        ProtocolPath = "";
        

    end

    methods (Access = public)

        function []  = switchAnalysisTab(app, trigger)
            % This function is called when the type of
            % analysis is changed. When called, we specify which component triggered the function.
            
            % Input>    [string]: The component that triggered the function
        
            switch trigger
                case "TabGroup1"
                    analysisType = app.TabGroup1.SelectedTab.Title;
                case "TabGroup2"
                    analysisType = app.TabGroup2.SelectedTab.Title;
                case "DropDown"
                    analysisType = app.TypeofanalysisDropDown.Value;
                case "EEG"
                    analysisType = 'EEG';
                case "MEG"
                    analysisType = 'MEG';
            end

            
            switch analysisType
                case 'EEG'
                    app.TabGroup1.SelectedTab = app.EEGTabGr1;
                    app.TabGroup2.SelectedTab = app.EEGTabGr2;
                    app.TypeofanalysisDropDown.Value = 'EEG';

                    app.AnalysisType = 'EEG';
                    app.SensorType = 'EEG';
                    app.FileFormat = 'EEG-BRAINAMP';    %ou EEG-DELTAMED. Default value, it is modified when reviewing raw file.
                    app.AnatomyFileFormat = 'FreeSurfer-fast';

                case 'MEG'
                    app.TabGroup1.SelectedTab = app.MEGTabGr1;
                    app.TabGroup2.SelectedTab = app.MEGTabGr2;
                    app.TypeofanalysisDropDown.Value = 'MEG';

                    app.AnalysisType = 'MEG';
                    app.SensorType = 'MEG';
                    app.FileFormat = 'CTF';
                    app.AnatomyFileFormat = 'FreeSurfer';
            end
        end

        function Infos = getCurrentInformations(app)
            % Create (or update if already created) a structure with the
            % current informations
            
            % Output> [structure]: Current informations
            %   fields: CurrentSubject, ProtocolInfo, Studies, Subjects
 
            Infos.ProtocolInfo = bst_get('ProtocolInfo');
            Infos.Studies = bst_get('ProtocolStudies');
            Infos.Subjects =  bst_get('ProtocolSubjects');
            %Infos.CurrentSubject = bst_get('Subject');

            % Check if there is an empty field.
            index = find(structfun(@isempty, Infos), 1);
            if ~isempty(index)
                
                switch index
                case 1
                    errorMessage = 'ProtocolInfo field is empty';
                case 2
                    errorMessage = 'ProtocolStudies field is empty';
                case 3
                    errorMessage = 'ProtocolSubjects field is empty';
                case 4
                    errorMessage = 'CurrentSubject field is empty';
                end   
                uialert(app.UIFigure, errorMessage, 'Error');    
            end
        end

        function [] = updateTree(app)
            % Update Informations in Tree

            Infos = app.getCurrentInformations;

            % Delete current Tree
            app.Tree.Children.delete;

            % Create a temporary node
            temporaryNode = uitreenode(app.Tree);
            temporaryNode.Text = "Temporary Node";

            % Iterate on all Studies in Protocol
            for i = 1:length(Infos.Studies.Study)
                % Get Subject Nodes already in Tree
                subjectNodes = app.Tree.Children;
                subjectNames = {subjectNodes.Text};

                % Get Subject Name of Study
                subjectNameChar = strsplit(Infos.Studies.Study(i).BrainStormSubject, '/');
                subjectNameString = convertCharsToStrings(subjectNameChar{1});

                % Check if Subject Name is in Subject Nodes
                index = find(contains(subjectNames,subjectNameString));
                if isempty(index)
                    % Create Subject Node in Tree
                    nodeSubject = uitreenode(app.Tree);
                    nodeSubject.Text = subjectNameString;
                else
                    % Get Subject node from Tree
                    nodeSubject = subjectNodes(index);
                end
                % Create Study Node in Subject Node
                nodeStudy = uitreenode(nodeSubject);
                nodeStudy.Text = Infos.Studies.Study(i).Name;
            end
            % Delete Temporary Node
            temporaryNode.delete
            
            expand(app.Tree, "all");
            
            app.updateCurrentProtocolCurrentSubject;
            
        end

        function [] = updateTreeAndCheckNewStudies(app)
            % Update and check the new studies.
            % date format: 01-JAN-2001
            
            oldStudies = string.empty();
            count = 0;
            
            subjects = app.Tree.Children;
            for i = 1:length(subjects)
                
                studies = subjects(i).Children;
                for j = 1:length(studies)
                    
                    count = count + 1;
                    oldStudies(count) = strcat(subjects(i).Text, '-', studies(j).Text);
                end
            end

            app.updateTree;

            
            NodetoCheck = matlab.ui.container.TreeNode.empty();
            nbNodeToCheck = 0;  
            
            subjects = app.Tree.Children;
            for i = 1:length(subjects)
                
                studies = subjects(i).Children;
                for j = 1:length(studies)
                    
                    newStudies = strcat(subjects(i).Text, '-', studies(j).Text);
                    
                    if ~any(oldStudies(:) == newStudies)
                    
                        nbNodeToCheck = nbNodeToCheck + 1;
                        NodetoCheck(nbNodeToCheck) = studies(j);
                    end
                end
            end
            
            if ~isempty(NodetoCheck)
                app.Tree.CheckedNodes = NodetoCheck;
            end            
        end
        
        function cFiles = selectedStudies(app)
             % Create (or update if already created) a cell containing the
            % current studies that are checked in the tree.
            
            % Output> [cell]: Paths to Raw Files (subject/study/data_0study...) that were copied in the Protocol Folder

            % Get all Checked Nodes
            sStudies_checked = app.Tree.CheckedNodes;
            cFiles = {};
            for i = 1:length(sStudies_checked)
                %study_folder = [];
                
                % Remove Subject Nodes that are checked (we keep only Study
                % Nodes)
                if class(sStudies_checked(i).Parent) == "matlab.ui.container.CheckBoxTree"
                    continue
                end

                % Concatenation to create File Path
                study_path = fullfile(sStudies_checked(i).Parent.Text, sStudies_checked(i).Text);
  
                
                % If the study is 'intra' or 'default', ignore it!
                if contains(sStudies_checked(i).Text, 'intra') || contains(sStudies_checked(i).Text, 'default')
                    %study_folder = fullfile(study_path, 'brainstormstudy.mat');
                    continue;
                    

                % If study is '@raw...', it means the study has NOT been imported. We get the path to the study .mat
                % file
                elseif sStudies_checked(i).Text(1:4) == "@raw"
                    studyFolder = fullfile(study_path, strcat('data_0raw_', sStudies_checked(i).Text(5:end), '.mat'));
                
                
                % If study name does not start with '@raw...', it means the
                % study has been imported. we extract all the trial from
                % the folder
                else
                    importedStudySplit = split(study_path, '/');
                    subjectName = importedStudySplit{1};
                    condition = importedStudySplit{2};
                    studyFolder = {};
                    
                    % Building Structure
                    structure = struct();
                    structure.SelectFiles.ToRun = true;
                    structure.SelectFiles.SubjectName = subjectName;
                    structure.SelectFiles.Condition = condition;
                    
                    % Select Files
                    cGoodTrial = Utility_Pipeline(structure);
                    
                    studyFolder = [studyFolder cGoodTrial];
                end
                
                % Save path in cFiles
                cFiles = [cFiles studyFolder];
            end
  
            % Remove empty value (if 'intra' or 'default' are checked)            
            cFiles = cFiles(~cellfun('isempty', cFiles));
            
            
            % Throw error if cFiles is empty
            if isempty(cFiles)
                uialert(app.UIFigure, 'There no study selected, this might cause an error!', 'Error');
                uiwait(gcbf)
            end 
        end
        
        function [] = updateCurrentProtocolCurrentSubject(app)
            % Update the Current Protocol and Current Subject values in the text boxes.
        
            Infos = app.getCurrentInformations;
        
            app.CurrentProtocolEditField.Value = Infos.ProtocolInfo.Comment;
        
            %if ~isempty(Infos.CurrentSubject)
             %   app.CurrentSubjectEditField.Value = Infos.CurrentSubject.Name;
            %end
        
            app.ProtocolPath = fullfile('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/rg/bs_db/', Infos.ProtocolInfo.Comment, '/');
        
        end 

        function [] = selectAllStudies(app)
            
            % Select or Deselect all the studies in the tree.
     
            if isempty(app.Tree.CheckedNodes)
                app.Tree.CheckedNodes = app.Tree.Children;
            else
                app.Tree.CheckedNodes = [];
            end
 
        end

        function [path, extensionOut] = getFilePath(app, title, extensionIn, startingFolder)
            
            % If extension variable not given
            if ~exist('extensionIn','var')
                extensionIn = {'*'};
            end
            
            if ~exist('startingFolder','var')
                startingFolder = app.Alab1Path;
            end
            
            
            % File Manager                    
            [fileName, folderPath, index] = uigetfile(extensionIn, title, startingFolder, 'MultiSelect', 'on');
           
            % Stop if no file is selected
            if isequal(fileName, 0)
                    uialert(app.UIFigure, 'No file has been selected!', 'Error');
                    uiwait(gcbf)
            end
            
            
            % If the option 'All Files' is selected, get extension from
            % file name
            if length(extensionIn) == 1 || index == length(extensionIn)+1
                
                fileNameSplit = split(fileName, '.');
                extensionOut = fileNameSplit{end};
               
            else
                
                extensionOut = extensionIn{index}(2:end); 
            end
            
            % Build path
            path = fullfile(folderPath, fileName);
        
            % Convert char to cell when only one file is selected so that
            % the function always returns a cell
            if class(path) == "char"
                path = {path};
            end
            return
        end
        
        function allEvents = getEvents(app, cFiles)
            % Extract all events for every study in cFiles (sEvents).
            
            % Input>    cFiles [struc]

            % Output>   allEvents [cell]: Cell with all events that are
            %                               in at least one study.

            allEvents = {};
            compt = 1;
            
            % Loop through all studies
            for i = 1:length(cFiles)
                [~, ~, studyMATfile] = app.getFilesPathFromcFile(cFiles(i));
                studyFile = load(studyMATfile);
                eventsStruct = studyFile.F.events;
    
                % Loop through all events
                for j = 1:length(eventsStruct)
                    
                    % If event not already in allEvent cell
                    if ~any(strcmp(allEvents, eventsStruct(j).label))
                        allEvents{compt} = eventsStruct(j).label;
                        compt = compt + 1;
                    end
                end
            end
        end

        function [] = createHistoryEventButton(app, cFiles, type)
 
            % Folder to save the file
            folder = app.WorkingFolderPath;

            for i = 1:length(cFiles)
                 % Create Hyperlink
                 if type == "history"
                    pathToFile = app.createProvenanceFile(cFiles(i), folder);
                    panel = app.HistoryPanel;
                    
                 elseif type == "event"
                    pathToFile = app.createJsonEventFile(cFiles(i), folder);
                    panel = app.EventsDetectedPanel;
                    
                 end
                 panelChildren = panel.Children;
                 [~, studyName] = app.getFilesPathFromcFile(cFiles(i));

                 link = pathToFile;
                 
                 
                 % Create Button
                 button = uibutton(panel, 'ButtonPushedFcn', @(button,event) app.openFileWithButtonPushed(button, link), 'Text', studyName);

                 
                 % Button Size and Position
                 height = 20;
                 positionPanel = panel.Position;
                 if isempty(panelChildren)
                     position = [1 positionPanel(4)-height*2 positionPanel(3)-2 height];
                 else
                     position = panelChildren(1).Position + [0 -height 0 0];
                 end
                 button.Position = position;
            end
        end

        function [TSVpath, JSONpath, PROVpath, EDFpath, ELECTRODEpath] = getBIDSpath(app, cFile, BIDSpath)
               
            % Get date of study and convert (01-JAN-2001 to 20010101)
            date = app.getDateFromBrainstormStudyMAT(cFile);
            dateSplit = split(date, '-');
            dateContracted = strcat(dateSplit{3}, app.monthConversionStrNum(dateSplit{2}), dateSplit{1});
            
            
            % Get the number of subject folder (/sub-000X/)
            contentOfBIDSFolder = dir(BIDSpath);
            count = 0;
            for j = 1:length(contentOfBIDSFolder)
            
                if contains(contentOfBIDSFolder(j).name, 'sub-')
                    strSplit = split(contentOfBIDSFolder(j).name, '-');
                    strNum = strSplit{2};
                    strSubNumber = str2double(strNum);
                
                    if strSubNumber > count
                        count = strSubNumber;
                    end
                end
            end
            
            
            % Build Each Path
            TSVpath = fullfile(BIDSpath, strcat('sub-', sprintf('%04d', count)), strcat('ses-', dateContracted), lower(app.AnalysisType), '/');
            JSONpath = fullfile(BIDSpath, strcat('sub-', sprintf('%04d', count)), strcat('ses-', dateContracted), lower(app.AnalysisType), '/');
            PROVpath = fullfile(BIDSpath, strcat('sub-', sprintf('%04d', count)), strcat('ses-', dateContracted), lower(app.AnalysisType), '/');
            EDFpath = fullfile(BIDSpath, strcat('sub-', sprintf('%04d', count)), strcat('ses-', dateContracted), lower(app.AnalysisType), '/');
            ELECTRODEpath = fullfile(BIDSpath, strcat('sub-', sprintf('%04d', count)), strcat('ses-', dateContracted), lower(app.AnalysisType), '/');
            
        end

        function edfFile = exportToEDF(app, cFiles, destination)
            % Convert brut datas in EDF file format and saves it in BIDS
            % folder
            
            % Input>    cFile [cell]
            %           destination [char]: path indicating where to save EDF file
            

             for i = 1:length(cFiles)
                
                 [~, studyName, ~, ~, channelFile] = app.getFilesPathFromcFile(cFiles(i));
                 
                 dest = convertStringsToChars(fullfile(destination, strcat(studyName, '.edf')));
                 
                 [edfFile] = export_data(cFiles, channelFile, dest, []);
             end
        end
        
        function pathToFile = createTsvFile(app, cFile, destination)
            % Create TSV file that lists the events with time and duration.
            
            % Input>    cFiles [cell]
            %           destination [char]: path indicating where to save the TSV file 
            
            
             % By default, file saved in Working Folder
            if ~exist('destination','var')
                destination = app.WorkingFolderPath;
            end
            
            % Load Event structure
            [~, studyName, studyMATfile] = app.getFilesPathFromcFile(cFile);
            studyMATfile = load(studyMATfile);
            eventStruct = studyMATfile.F.events;
            
                            
            % Initialiaze tsvFile
            tsvFile = strings([1,3]);
            count = 1;
            tsvFile(count,:) = ["onset" "duration" "trial type"];
            count = count + 1;
        
            % Loop through all event types
            for j = 1:length(eventStruct)
        
                % Loop through all time (each occurence of an event)
                for k = 1:length(eventStruct(j).times)
        
                    tsvFile(count,:) = [eventStruct(j).times(k) 0 string((eventStruct(j).label))];
                    count = count + 1;
                end
            end
            
            pathToFile = fullfile(destination, strcat(studyName, '_Events.txt'));
            
            % Write file to .txt file
            writematrix(tsvFile, pathToFile, 'Delimiter', 'tab');
  
        end
  
        function pathToFile = createTsvElectrodeFile(app, cFile, destination)
            
            if ~exist('destination','var')
                destination = app.WorkingFolderPath;
            end
            
            % Load Channel File
            [~, ~, ~, ~, channelFilePath] = app.getFilesPathFromcFile(cFile(1));
            channelFile = load(channelFilePath);
            allChannels = channelFile.Channel;
            
            tsvElectrodeFile = strings([1,4]);
            count = 1;
            tsvElectrodeFile(count,:) = ["Name" "X coordinate" "Y coordinate" "Z coordinate"];
            count = count + 1;
                
            for i = 1:length(allChannels)
                location = allChannels(i).Loc;
                tsvElectrodeFile(count,:) = [allChannels(i).Name, string(location(1)), string(location(2)), string(location(3))];
                count = count + 1;
            end
            
            pathToFile = fullfile(destination, strcat('testing', '_Electrodes.txt'));
            
            % Write file to .txt file
            writematrix(tsvElectrodeFile, pathToFile, 'Delimiter', 'tab');
            
        end
        
        function pathToFile = createProvenanceFile(app, cFile, destination)
            % Create Json file containing information about the study
            % (history of all functions ran on it)
            
            % Input>    cFiles [cell]
            %           destination [char]: path to save JSON file
            
            
            % By default, file saved in Working Folder
            if ~exist('destination','var')
                destination = app.WorkingFolderPath;
            end

            % Load study.mat file
            [~, studyName, studyMatFile, ~] = getFilesPathFromcFile(app, cFile);
            studyMat = load(studyMatFile);
            
            % Iterate on all event
            for j = 1:height(studyMat.History)
                activity = struct();
                activity.id = studyMat.History{j,2};
                activity.label = studyMat.History{j,3};
                activity.command = 'button pushed';
                activity.startedAtTime = studyMat.History{j,1}; 
               
                provenance.(strcat('ActivityNo', num2str(j))) = activity; 
            end
            
            % Build Provenance File Name
            fileName = strcat(studyName, '_Provenance.json');
        
            % Save as JSON file
            app.saveAsJSON(provenance, fileName)                
            
            % Cannot add path in 'saveAsJSON' arguments. Save by
            % default in current directory. If current directory is not
            % the destination, then copy/paste and delete.
            if string(destination) ~= string(pwd)
                copyfile(fileName, destination);
                delete(fileName);
            end
            
            % Return File Path
            pathToFile = fullfile(destination, fileName);
        end
        
        function pathToFile = createJsonEventFile(app, cFile, destination)
            % Create JSON file that describes every event.
            
            % Input>    cFiles [cell]
            %           destination [char]: path indicating where to save the JSON file 

            % By default, file saved in Working Folder
            if ~exist('destination','var')
                destination = app.AppFolderPath;
            end
            
            % Load list of all events
            allEvents = app.getEvents(cFile);

            for j = 1:length(allEvents)
                new_event = strrep(allEvents{j}, '-', '_');
                new_event = strrep(new_event, ' ', '_');
                checkFirstChar = isstrprop(new_event, 'digit');
                
                % If first character is a digit
                if (checkFirstChar(1))
                    new_event = "f" + new_event;
                end
                
                s__.(new_event) = 'Description';
            end

            s_.LongName = 'Event category';
            s_.Description = 'Indicator of type of action that is expected';
            s_.Levels = s__;

            s.trial_type = s_;

            % Build Json event Name of file
            [~, studyName] = app.getFilesPathFromcFile(cFile);
            fileName = strcat(studyName, '_Event_Metadata.json');
            
            % Save as Json
            app.saveAsJSON(s, fileName)
            
            % Copy the json file in a new destination and delete the old one
            if string(destination) ~= string(pwd)
                copyfile(fileName, destination);
                delete(fileName);
            end
            
            % Return File Path
            pathToFile = fullfile(destination, fileName);
        end
              
        function [] = switchColorModeForParent(app, obj, backColor, textColor)
            try 
                enfants = obj.Children;
                cls = class(obj);
                
                switch cls
                    case 'matlab.ui.Figure'
                       obj.Color = backColor; 
                       
                    case 'matlab.ui.container.Panel'
                        obj.BackgroundColor = backColor;
                        obj.ForegroundColor = textColor;
                           
                    case 'matlab.ui.container.CheckBoxTree'
                        obj.BackgroundColor = backColor;
                        obj.FontColor = textColor;
                           
                    case 'matlab.ui.container.TabGroup'
                        
                        %obj.BackgroundColor = backColor;
                        %obj.ForegroundColor = textColor;
                        
                    case 'matlab.ui.container.Tab'
                        obj.BackgroundColor = backColor;
                        %obj.ForegroundColor = textColor;
                           
                end
        
        
                for i = 1:length(enfants)
                    app.switchColorModeForParent(enfants(i), backColor, textColor);
                end
                
            catch
                
                app.switchColorModeForChildren(obj, backColor, textColor);
                
            end
        end
    
        function [subjectNames, studyNames, studyMatFiles, brainstormStudyFiles, channelFiles] = getFilesPathFromcFile(app, cFiles)

            % Initialize cell
            subjectNames = cell(1, length(cFiles));
            studyNames = cell(1, length(cFiles));
            studyMatFiles = cell(1, length(cFiles));
            brainstormStudyFiles = cell(1, length(cFiles));
            channelFiles = cell(1, length(cFiles));
            
            for i = 1:length(cFiles)
                cFileSplit = split(cFiles{i}, '/');
                subjectNames{i} = cFileSplit{1};
                studyNames{i} = cFileSplit{2};
                studyMatFileName = cFileSplit{3};
            
                studyFolders = fullfile(app.ProtocolPath, 'data', subjectNames{i}, studyNames{i});
            
                studyMatFiles{i} = fullfile(studyFolders, studyMatFileName);
                brainstormStudyFiles{i} = fullfile(studyFolders, 'brainstormstudy.mat');

                if app.AnalysisType == "EEG"
                    channelFiles{i} = fullfile(studyFolders, 'channel.mat');
                elseif app.AnalysisType == "MEG"
                    channelFiles{i} = fullfile(studyFolders, 'channel_ctf_acc1.mat');
                end
            end     
            
            % If cFile is only one study, return the value (not as a 1x1
            % cell)
             if length(cFiles) == 1
                 subjectNames = subjectNames{1};
                 studyNames = studyNames{1};
                 studyMatFiles = studyMatFiles{1};
                 brainstormStudyFiles = brainstormStudyFiles{1};
                 channelFiles = channelFiles{1};
             end
%             Infos = app.getCurrentInformations;
%             
%             cFileSplit = split(cFile, '/');
%             subjectName = cFileSplit{1};
%             studyName = cFileSplit{2}(2:end);
%             studyMatFileName = cFileSplit{3};
%             
%             studyFolder = fullfile(app.BsDatabasePath, Infos.ProtocolInfo.Comment, 'data', subjectName, strcat('@', studyName));
%             
%             studyMatFile = fullfile(studyFolder, studyMatFileName);
%             brainstormStudyFile = fullfile(studyFolder, 'brainstormstudy.mat');
%             
%             % Name of channel file can vary between MEG and EEG
%             allFiles = dir(studyFolder);
%             for i = 1:length(allFiles)
%                 if contains(allFiles(i).name, 'channel')
%                     channelFile = fullfile(studyFolder, allFiles(i).name);
%                     break
%                 end
%             end

        end
    
        function date = getDateFromBrainstormStudyMAT(app, cFiles)

            date = cell(1, length(cFiles));
            for i = 1:length(cFiles)
                [~, ~, ~, brainstormStudyFile] = app.getFilesPathFromcFile(cFiles(i));
    
                bsStudy = load(brainstormStudyFile);
                date{i} = upper(bsStudy.DateOfStudy);
            end
            
            return
        end
        
        function [] = modifyBrainstormStudyMATDate(app, cFile, date)
            % Date format: 01-JAN-2001

            for i = 1:length(cFile)
                [~, ~, ~, brainstormStudyFile] = app.getFilesPathFromcFile(cFile(i));
                bsStudy = load(brainstormStudyFile);
                bsStudy.DateOfStudy = upper(date{i});
                save(brainstormStudyFile, '-struct', 'bsStudy');
            end
 
        end
   
        function rawFilesPath = MEGReviewRawFiles(app, subjectName)
            
            % Select Data or Noise file
            choice = ["Data Files", "Noise Files", "Cancel"];
            type = uiconfirm(app.UIFigure, 'Wich type of file do you want to import ?', 'Files to import', 'Options', choice);
            switch type
                case choice(1)
                title = "Select the folder containing your data files";
                ChannelAlign = 1;
            case choice(2)
                title = "Select the folder containing your noise files";
                ChannelAlign = 0;
            case choice(3)
                return
            end
            
            % Select Dataset 
            folder = uigetdir(app.WorkingFolderPath, title);      
            
            if isequal(folder, 0)
                return
            end
            
            % If only one dataset (folder) selected
            if (folder(end-2:end) == ".ds")
                rawFilesPath = folder;
            
            % If the folder selected contains multiple dataset
            else
                % Keep only data files (.ds)
                FilesName = dir([folder filesep '*.' 'ds']);
            
                % Saving FileNames in a cell (Path)
                rawFilesPath = cell(1, length(FilesName));
                
                for i=1:length(rawFilesPath)
                    rawFilesPath{i} = fullfile(FilesName(i).folder, FilesName(i).name);
                end
            end
            
            
            % Building structure
            structure.ReviewRawFiles = struct();
            structure.ReviewRawFiles.SubjectName = subjectName;
            structure.ReviewRawFiles.ChannelAlign = ChannelAlign;
            structure.ReviewRawFiles.FileFormat = app.FileFormat;
            structure.ReviewRawFiles.RawFilesPath = rawFilesPath;
            
            % Review Raw Files
            
            %cFile = Utility_Pipeline(structure);
            Utility_Pipeline(structure);
            
            msgbox('Dont know how to extract date from raw MEG files');
            
            % Extract Recording Date and modifiy brainstormstudy.mat
            % (DateOfStudy) (Don't know where to find recording date
            % for meg)
            
        end
        
        function rawFilesPath = EEGReviewRawFiles(app, subjectName)
            
                % Building File Manager Interface
                title = "Select your EEG data file";
                extensionIn = {'*.eeg'; '*.bin'};       % Extension supported
                
                
                [rawFilesPath, extension] = app.getFilePath(title, extensionIn, app.RawDataPath);
                pathSplit = split(rawFilesPath, '/');
                app.RawDataPath = "/" + fullfile(pathSplit{1:end-1});
                
                
                % Modify file format based on extension
                switch extension
                    case '.bin'
                        app.FileFormat = 'EEG-DELTAMED';
                        
                    case '.eeg'          
                        % Data Colected with Brainvision
                        app.FileFormat = 'EEG-BRAINAMP';
                        
                    otherwise 
                        return
                end 

                % Building Strucutre
                structure.ReviewRawFiles = struct();
                structure.ReviewRawFiles.SubjectName = subjectName;
                structure.ReviewRawFiles.ChannelAlign = 0;
                structure.ReviewRawFiles.FileFormat = app.FileFormat;
                structure.ReviewRawFiles.RawFilesPath = rawFilesPath;


                % Review Raw Files
                cFiles = Utility_Pipeline(structure);
                
                % Get .vmrk file and extract recording date
                recordingDate = app.getDateFromVMRK(rawFilesPath, extension);
                    
                % Modify Date Of Study in brainstormstudy.mat
                app.modifyBrainstormStudyMATDate(cFiles, recordingDate);
                
        end
        
        function [] = EEGConvertToBIDS(app, cFiles, BIDSpath)
            % Create BIDS folder and populates it through sub-function
            
            % Input>    cFiles [cell]
            
            %% STEPS to IMPLEMANT: 
            % 1. CONVERT OG STUDY TO EDF
            % 2. REVIEW EDF (CREATES EDF STUDY)
            % 3. CONVERT EDF STUDY TO BIDS
            % 4. CREATE SIDE FILES (EVENT/PROVENANCE) FROM THE OG
            % STUDY(!!!)

            % Create folder
            mkdir(BIDSpath);
            
            % Iterate for every study
            for i = 1:length(cFiles)
                
                % Export all study to EDF
                edfFilePath = app.exportToEDF(cFiles(i), app.WorkingFolderPath);
                date = app.getDateFromBrainstormStudyMAT(cFiles(i));
                app.modifyEDFDate(edfFilePath, date);
       
                
                % Reimport EDF file in Brainstorm 
                        % (it creates a new study that will later be deleted)
                [subjectName] = app.getFilesPathFromcFile(cFiles(i));
                
                structure = struct();
                structure.ReviewRawFiles.ToRun = true;
                structure.ReviewRawFiles.SubjectName = subjectName;
                structure.ReviewRawFiles.RawFilesPath = edfFilePath;
                structure.ReviewRawFiles.FileFormat = 'EEG-EDF';
                structure.ReviewRawFiles.ChannelAlign = 0;
    
                cFilesEDF = Utility_Pipeline(structure);
    
                
                % Modify date in new brainstormstudy.mat
                app.modifyBrainstormStudyMATDate(cFilesEDF, date)
            
                
                % Convert EDF study to BIDS
                structure = struct();
                %structure.ConvertToBIDS.cFile = cFilesEDF;
                structure.ConvertToBIDS.BIDSpath = BIDSpath;
                
                EEG_Pipeline(cFilesEDF, structure); 

                % Get Path for TSV, JSON and PROVENANCE files.
                [TSVpath, JSONpath, PROVpath, ELECTRODEpath] = app.getBIDSpath(cFiles(i), BIDSpath);
                
                
                % Create .tsv file (Events)
                app.createTsvFile(cFiles(i), TSVpath);
                
                
                % Create Json event description file
                app.createJsonEventFile(cFiles(i), JSONpath);
                
                
                % Create provenance file
                app.createProvenanceFile(cFiles(i), PROVpath);
                
                
                % Create electrode file
                app.createTsvElectrodeFile(cFiles(i), ELECTRODEpath);

                
                % Update Tree and Check new Studies 
                    % (Order is importantsince the 'review raw files' of EDF study 
                    % will add a newstudy that we need to delete.
                app.updateTreeAndCheckNewStudies;
                bst_process('CallProcess', 'process_delete', cFilesEDF, [], ...
                'target', 2); % 1: fichier 2: folder 3: subjects
     
            end
            
            app.updateTree;
            
        end
    
        function [] = MEGConvertToBIDS(app, cFiles, BIDSpath)
    
            % Iterate for every Study
            for i = 1:length(cFiles)
            
                % Convert to BIDS
                structure = struct();
                %structure.ConvertToBIDS.cFile = cFiles(i);
                structure.ConvertToBIDS.BIDSpath = BIDSpath;
                
                MEG_Pipeline(cFiles(i), structure);
            
                % Get Path for TSV, JSON and PROVENANCE files.
                [TSVpath, JSONpath, PROVpath, EDFpath]  = app.getBIDSpath(cFiles(i), BIDSpath);


                % Create .edf file
                app.exportToEDF(cFiles(i), EDFpath);

                
                % Create .tsv file (Events)
                app.createTsvFile(cFiles(i), TSVpath);
                
                
                % Create Json event description file
                app.createJsonEventFile(cFiles(i), JSONpath);
                
                
                % Create provenance file
                app.createProvenanceFile(cFiles(i), PROVpath);     
                
                
            end
            
            uiconfirm(app.UIFigure, 'BIDS created!', 'Files created');
            app.updateTree;
        end
    
        function newDate = getDateFromVMRK(app, rawFilesPath, extension)
            % Input must be raw file path of type .eeg with a corresponding
            % .vmrk file.
            
            assert(extension == ".eeg", "L'extension doit etre .eeg");
            
            newDate = cell(1,length(rawFilesPath));
            for i = 1:length(rawFilesPath)
            
                
                %vmrkFilePath = strcat(cFiles{i}(1:end-length(extension)), '.vmrk');
                vmrkFilePath = strcat(rawFilesPath{i}(1:end-length(extension)), '.vmrk');
                
                fileid = fopen(vmrkFilePath, 'r');
                for j = 0:10  
                    fgetl(fileid);
                end
                
                textline = fgetl(fileid);

                startDate = 24;
                year = textline(startDate:startDate+3);
                month = textline(startDate+4:startDate+5);
                day = textline(startDate+6:startDate+7);
                hour = textline(startDate+8:startDate+9);
                minute = textline(startDate+10:startDate+11);
                second = textline(startDate+12:startDate+13);
                strMonth = app.monthConversionStrNum(month);
            
                newDate{i} = strcat(day, '-', strMonth, '-', year, '-', hour, '-', minute, '-', second);
            end
            return
        end
    
        function [] = modifyEDFDate(app, edfFilePath, dateIn)
            %date format: 01-JAN-2001-14-12-12


            %for i = 1:length(dateIn)
                date = split(dateIn, '-');
                days = date{1};
                strMonth = date{2};
                
                month = app.monthConversionStrNum(strMonth);
                
                year = date{3};
                hours = date{4};
                minutes = date{5};
                secondes = date{6};
            
                % Copy files TXT file
                newFileName = strcat(edfFilePath(1:end-4), '_edf.txt');
                copyfile(edfFilePath, newFileName)
                
                % Extract line with wrong date from EDF file and replace date (at 2 places) with correct
                % date
                fileid = fopen(newFileName, 'r');
                textline = fgetl(fileid);
                index = strfind(textline, 'Startdate');
                
                % First replacement
                newline = strrep(textline, textline(index+10:index+10+10), strcat(days, '-', strMonth, '-', year));
                
                % Second replacement
                newline = strrep(newline, textline(169:184), strcat(days, '.', month, '.', year(3:4), ...
                                                        hours, '.', minutes, '.', secondes));
                
                fclose(fileid);
                
                fileid = fopen(newFileName, 'r+');
                fprintf(fileid, '%c', newline);
                fclose(fileid);
                
                copyfile(newFileName, edfFilePath)
                delete(newFileName);
            %end

            return
        end
    
    end

    methods(Static)
        
        function allProtocols = getAllProtocols()
            % Return cell of the protocol structure for every protocol
            
            % Save index of current Protocol
            originalProtocol = bst_get('iProtocol');
            
            allProtocols = {};
            i = 1;
            while true
                bst_set('iProtocol', i);
                protocol = bst_get('ProtocolInfo');
                if isempty(protocol)
                    break
                end
                allProtocols{i} = protocol.Comment;
                i = i + 1;
            end
            
            % Set original protocol.
            bst_set('iProtocol', originalProtocol);
        end
                        
        function openFileWithButtonPushed(~, path)
            open(path)
        end
        
        function [] = switchColorModeForChildren(obj, backColor, textColor)
            cls = class(obj);

            switch cls
                case 'matlab.ui.control.Button'
                    obj.FontColor = textColor;
                    obj.BackgroundColor = backColor;
        
                case 'matlab.ui.container.Panel'
                    %obj.BackgroundColor = backColor;
                    %obj.ForegroundColor = textColor;
                    %buttonIntab = obj.Children;
                    
                case 'matlab.ui.control.Hyperlink'
                    obj.VisitedColor = textColor;
                    obj.BackgroundColor = backColor;
                    obj.FontColor = textColor;
                            
                case 'matlab.ui.control.Label'
                    obj.BackgroundColor = backColor;
                    obj.FontColor = textColor;
                    
                case 'matlab.ui.control.EditField'
                    
                case 'matlab.ui.control.DropDown'
                    obj.FontColor = textColor;
                    obj.BackgroundColor = backColor;
                    
                case 'matlab.ui.control.CheckBoxTree'
                    obj.FontColor = textColor;
        
            end
        end
        
        function rep = monthConversionStrNum(input)
            
            % Return a STRING
            monthsName = {'JAN', 'FEB', 'MAR', 'APR', 'MAY', 'JUN', 'JUL', 'AUG', 'SEP', 'OCT', 'NOV', 'DEC'};
            monthsNum = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12'};
            monthStruct = cell2struct(monthsNum, monthsName, 2);

            if isnumeric(input)
                rep = monthsName{input};
                
            elseif isnan(str2double(input))
                strMonth = upper(input);
                rep = monthStruct.(strMonth);

            else
                rep = monthsName{str2double(input)};
                
            end
            
            if length(rep) == 1
                rep = rep{1};
            end
        end
        
        % The next function has been taken here:
        % https://www.mathworks.com/matlabcentral/fileexchange/77284-structure-and-object-to-json?s_tid=srchtitle

        function saveAsJSON(data, jsonFileName)
            % saves the values in the structure 'data' to a file in JSON format.
            % Based on the work of Lior Kirsch at: https://uk.mathworks.com/matlabcentr
            % al/fileexchange/50965-structure-to-json
            %
            % Modification by Arthur Y.C. Liu 24/06/2020
            %
            % Example:
            %     data.name = 'chair';
            %     data.color = 'pink';
            %     data.eye = eye(3);
            %     data.metrics.imageSize = [1920; 1080];
            %     data.metrics.height = 0.3;
            %     data.metrics.width = 1.3;
            %     saveJSONfile(data, 'out.json');
            %
            % Output 'out.json':
            % {
            % 	"name" : "chair",
            % 	"color" : "pink",
            % 	"eye" :
            % 	[
            % 		1,
            % 		0,
            % 		0,
            % 		0,
            % 		1,
            % 		0,
            % 		0,
            % 		0,
            % 		1
            % 	],
            % 	"metrics" :
            % 	{
            % 		"imageSize" :
            % 		[
            % 			1920,
            % 			1080
            % 		],
            % 		"height" : 0.3,
            % 		"width" : 1.3
            % 	}
            % }
            fid = fopen(jsonFileName,'w');
            if isobject(data)
                data = toStruct(data);
            end
            writeElement(fid, data, '', true);
            fprintf(fid,'\n');
            fclose(fid);


            function writeElement(fid, data, tabs, isFirstLine)
                namesOfFields = fieldnames(data);
                numFields = length(namesOfFields);
                tabs = sprintf('%s\t', tabs);
                if nargin == 4
                    if isFirstLine
                        fprintf(fid,'%s{\n%s', tabs(1:end-1), tabs);
                    end
                else
                    fprintf(fid,'\n%s{\n%s', tabs(1:end-1), tabs);
                end
                for i = 1:numFields
                    currentField = namesOfFields{i};
                    currentElementValue = data.(currentField);
                    writeSingleElement(fid, currentField, currentElementValue, tabs);
                    if i == numFields
                        fprintf(fid,'\n%s}',  tabs(1:end-1));
                    else
                        fprintf(fid,',\n%s', tabs);
                    end
                end
            end

            function writeSingleElement(fid, currentField, currentElementValue, tabs)
                % if this is an array/matrix and not a string then iterate on every
                % element, if this is a single element write it
                if ~isstruct(currentElementValue) &&...
                        length(currentElementValue) > 1 && ~ischar(currentElementValue)
                    fprintf(fid,'"%s" : \n%s[\n%s\t',currentField, tabs, tabs);
                    tabs = sprintf('%s\t', tabs);
                    valLength = length(currentElementValue(:));
                    for m = 1:valLength
                        fprintf(fid,'%g' , currentElementValue(m));
                        if m == valLength
                            fprintf(fid,'\n%s]', tabs(1:end-1));
                        else
                            fprintf(fid,',\n%s', tabs);
                        end
                    end
                elseif isstruct(currentElementValue) &&...
                        length(currentElementValue) > 1 && ~ischar(currentElementValue)
                    fprintf(fid,'"%s" : \n%s[',currentField, tabs);
                    tabs = sprintf('%s\t', tabs);
                    valLength = length(currentElementValue(:));
                    for m = 1:valLength
                        writeElement(fid, currentElementValue(m), tabs);
                        if m == valLength
                            fprintf(fid,'\n%s]', tabs(1:end-1));
                        else
                            fprintf(fid,',');
                        end
                    end
                elseif isstruct(currentElementValue)
                    fprintf(fid,'"%s" : ',currentField);
                    writeElement(fid, currentElementValue, tabs);
                elseif isnumeric(currentElementValue) || islogical(currentElementValue)
                    fprintf(fid,'"%s" : %g' , currentField, currentElementValue);
                elseif isempty(currentElementValue)
                    fprintf(fid,'"%s" : "null"' , currentField);
                elseif isobject(currentElementValue)
                    currentElementValue = toStruct(currentElementValue);
                    fprintf(fid,'"%s" : ',currentField);
                    writeElement(fid, currentElementValue, tabs);
                else %ischar or something else ...
                    fprintf(fid,'"%s" : "%s"' , currentField, currentElementValue);
                end
            end

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.UIFigure.WindowState = 'maximized';

            % Input for Type of Analysis
            choice = ["EEG", "MEG"];
            rep = string(uiconfirm(app.UIFigure, 'What type of analysis do you want to conduct ?', 'Type of analysis', 'Options', choice));
            app.switchAnalysisTab(rep);

            
            % Define Working Folder
            title = "Select your Working Folder"; 
            wf = uigetdir(app.Alab1Path, title);
            
            if ~isequal(wf, 0)
                app.WorkingFolderPath = wf;
            else
                % If wf is empty, we keep the default Working Folder.
                uiconfirm(app.UIFigure, strcat("Default Working Folder will be : ", app.WorkingFolderPath), 'Default Working Folder');
            end
            
            app.WorkingFolderPath = fullfile(app.WorkingFolderPath, app.AppFolderName);
            mkdir(app.WorkingFolderPath)
            
            
            % Adding Paths
            addpath(app.ToolPath);        
            addpath(app.BsDatabasePath);
            addpath(app.BsToolboxPath);
            addpath(app.WorkingFolderPath);
            
            % Changing Directory
            cd(app.WorkingFolderPath);
            
            
            % Start Brainstorm without interface
            if ~brainstorm('status')
                brainstorm nogui;
            end
     
        end

        % Button pushed function: ImportPipelineButton
        function ImportPipelineButtonPushed(app, event)
            
            % Input for Pipeline (.mat)
            [fileName, folderPath] = uigetfile('*.mat', 'Select MAT file');
            
            if (fileName == 0)
                return
            end
            
            app.PipelineSelected = load(strcat(folderPath, fileName));

            % Display Pipeline Info

            name = app.PipelineSelected.Name;
            folder = app.PipelineSelected.Folder;
            date = app.PipelineSelected.DateOfCreation;
            strDate = strcat(num2str(date(1)), '-', app.monthConversionStrNum(date(2)), '-', num2str(date(3)), '__', num2str(date(4)), 'h', num2str(date(5)), 'm', num2str(date(6)), 's');
            
            type = app.PipelineSelected.Type;
            numberOfProcess = app.PipelineSelected.NumberOfProcesses;
            
                    
            nameFormat = ['NAME', newline, name];
            folderFormat = ['FOLDER', newline, folder];
            dateFormat = ['DATE OF CREATION', newline, strDate];
            typeFormat = ['TYPE', newline, type];
            nbProcessFormat = ['NUMBER OF PROCESS', newline, num2str(numberOfProcess)];

            
            fields = fieldnames(app.PipelineSelected.Processes);
            processes = []; 
            for i = 1:length(fields)
                param = "";
                if fields(i) == "NotchFilter"
                    param = " (" + strjoin(string(app.PipelineSelected.Processes.NotchFilter.Parameters), ', ') + " Hz)";
    
                elseif fields(i) == "BandPassFilter"
                    param = " (" + strjoin(string(app.PipelineSelected.Processes.BandPassFilter.Parameters), ', ') + " Hz)";
                    
                elseif fields(i) == "AddEEGPosition"
                    param = " (" + string(app.PipelineSelected.Processes.AddEEGPosition.Cap) + ')';
                    
                elseif fields(i) == "ICA"
                    param = " (Nb Components: " + string(app.PipelineSelected.Processes.ICA.NumberOfComponents) + ')';
                
                elseif fields(i) == "DetectArtifact"
                    event = "";
                    if (isfield(app.PipelineSelected.Processes.DetectArtifact,'Cardiac'))
                       event = event + app.PipelineSelected.Processes.DetectArtifact.Cardiac.EventName + ", ";
                    end
                    if (isfield(app.PipelineSelected.Processes.DetectArtifact,'Blink'))
                       event = event + app.PipelineSelected.Processes.DetectArtifact.Blink.EventName + ", ";
                    end
                    if (isfield(app.PipelineSelected.Processes.DetectArtifact,'Other'))
                       if (isfield(app.PipelineSelected.Processes.DetectArtifact.Other,'LowFrequence'))
                            event = event + 'Low Frequence; ';
                       end
                       if (isfield(app.PipelineSelected.Processes.DetectArtifact.Other,'HighFrequence'))
                            event = event + 'High Frequence; ';
                       end
                    end
                   param = " (" + event + ')';
                
                elseif fields(i) == "SSP"
                    
                   if (isfield(app.PipelineSelected.Processes.SSP,'Cardiac'))
                       event = app.PipelineSelected.Processes.SSP.Cardiac.EventName;
                   elseif (isfield(app.PipelineSelected.Processes.SSP,'Blink'))
                       event = app.PipelineSelected.Processes.SSP.Blink.EventName;
                   elseif (isfield(app.PipelineSelected.Processes.SSP,'Generic'))
                       event = app.PipelineSelected.Processes.SSP.Generic.EventName;
                   end 
                   param = " (" + event + ')';
                
                elseif fields(i) == "RemoveSimultaneousEvents"
                    param = " (Remove: " + string(app.PipelineSelected.Processes.RemoveSimultaneousEvents.EventToRemove) + ...
                        ', ' + 'Target: ' + string(app.PipelineSelected.Processes.RemoveSimultaneousEvents.TargetEvent) + ')';
                
                elseif fields(i) == "ConvertToBIDS"
                    param = " (" + app.PipelineSelected.Processes.ConvertToBIDS.BIDSpath + ')';
                end
                
                processes = [processes, fields(i) + param];
            end

            ListProcesses = ['LIST OF PROCESS', processes];
            
            matToDisplay = [nameFormat, newline, folderFormat, newline, dateFormat, newline, ...
                            typeFormat, newline, nbProcessFormat, newline, ListProcesses];

            app.PipelineTextArea.Value = matToDisplay;


        end

        % Button pushed function: ClearPipelineButton
        function ClearPipelineButtonPushed(app, event)
            app.PipelineTextArea.Value = "";
            
            app.PipelineSelected = struct();
        end

        % Button pushed function: RunPipelineButton
        function RunPipelineButtonPushed(app, event)
            cFiles = app.selectedStudies;
            
            if app.PipelineSelected.Type == "EEG"

                EEG_Pipeline(cFiles, app.PipelineSelected.Processes, app);
                
            elseif app.PipelineSelected.Type == "MEG"
                
                MEG_Pipeline(cFiles, app.PipelineSelected.Processes);
                
            else
                uialert(app.UIFigure, "Type of Pipeline is incorrect (EEG or MEG)", "ERROR");
            end
            
            app.updateTreeAndCheckNewStudies;
            
        end

        % Button pushed function: CreateProtocolButton
        function CreateProtocolButtonPushed(app, event)

            % Input for Protocol Name
            title = 'Create Protocol';
            question = 'Name of the protocol to create: ';
            ProtocolName = char(inputdlg(question, title));

            % Create Protocol
            if ~isempty(ProtocolName)
                gui_brainstorm('CreateProtocol', ProtocolName, 0, 0); % 0/1: Use default anatomy / Use default channel
            end

            % Create Protocol Path
            app.ProtocolPath = fullfile(app.BsDatabasePath, ProtocolName);
        end

        % Button pushed function: DeleteProtocolButton
        function DeleteProtocolButtonPushed(app, event)
            
            % Display all Protocols
            allProtocols = app.getAllProtocols;
            
            index = listdlg('Name', 'Protocol to delete', 'ListString', allProtocols);
            if isempty(index)
                return
            end  
            selection = uiconfirm(app.UIFigure, strcat("You're about to delete the following protocol: ", allProtocols{index}), "Protocol Delete");
            
            % Delete Protocol
            if selection == "OK"
                gui_brainstorm('DeleteProtocol', allProtocols{index});
                uiconfirm(app.UIFigure, "Protocol has been delete", "Protocol Delete");         
            end


            app.updateTree;

        end

        % Button pushed function: LoadProtocolButton
        function LoadProtocolButtonPushed(app, event)

            % Display all Protocol
            allProtocols = app.getAllProtocols;
            
            index = listdlg('Name', 'Choose Protocol to Load', 'ListString', allProtocols, 'ListSize', [350, 500]);
            if isempty(index)
                return
            end

            protocolIndex = bst_get('Protocol', allProtocols{index});
            
            % Select Protocol
            f = waitbar(0, 'Wait...');
            bst_set('iProtocol', protocolIndex);
            gui_brainstorm('SetCurrentProtocol', protocolIndex);
            
            protocolName = allProtocols{index};
            app.ProtocolPath = fullfile(app.BsDatabasePath, protocolName);
            
            waitbar(0.5, f, 'Almost Done!')
            app.updateTree
            
            close(f);
            
        end

        % Button pushed function: CreateSubjectButton
        function CreateSubjectButtonPushed(app, event)

            % Input for Subject Name
            title = 'Name your subject';
            question = 'Subject Name: ';
            subjectName = char(inputdlg(question, title));

            if isempty(subjectName)
                return
            end
            
            % Input for type of Anatomy
            choice = ["Sepecify anatomy folder", "Use default anatomy", "Cancel"];
            rep = uiconfirm(app.UIFigure, 'Do you have a specific anatomy folder for your subjet or do you want to use the default anatomy ?', ...
                'Create Subject', 'Options', choice);
            
            switch rep
                
                case choice(1)
                    set(app.ProjectElectrodesonScalpButton,'Enable', 'on');
                    title = "Choose the anatomy folder";
                    AnatomyPath = uigetdir(app.Alab1Path, title);

                    if isequal(AnatomyPath, 0)
                        return
                    end
                    
                    structure.ImportAnatomy = struct();
                    structure.ImportAnatomy.SubjectName = subjectName;
                    structure.ImportAnatomy.AnatomyPath = AnatomyPath;
                    structure.ImportAnatomy.AnalysisType = app.AnalysisType;
                    structure.ImportAnatomy.AnatomyFileFormat = app.AnatomyFileFormat;
                    
                    Utility_Processes(structure);

                case choice(2)
                    set(app.ProjectElectrodesonScalpButton,'Enable', 'off');
                    db_add_subject(subjectName, [], 1, 0);

                case choice(3)
                    return
            end

            app.updateTree;

        end

        % Button pushed function: ReviewRawFilesButton
        function ReviewRawFilesButtonPushed(app, event)

            Infos = app.getCurrentInformations;
            
            % List of all Subjects
            liste = cell(1, length(Infos.Subjects.Subject));
            for i = 1:length(Infos.Subjects.Subject)
                liste{i} = Infos.Subjects.Subject(i).Name;
            end
            
            
            % Select Subject associated with the dataset
            index = listdlg('Name', 'Choose the Subject', 'ListString', liste);
            if isempty(index)
                return
            end
            subjectName = liste{index};


            % MEG Review Raw Files
            if app.AnalysisType == "MEG"
                 
                 app.MEGReviewRawFiles(subjectName);
    
            % EEG Review Raw Files
            elseif app.AnalysisType == "EEG"
                
                app.EEGReviewRawFiles(subjectName);

            end

            app.updateTreeAndCheckNewStudies;
        end

        % Button pushed function: NotchFilterButton
        function NotchFilterButtonPushed(app, event)

            cFiles = app.selectedStudies;
            
            % Input for Frequences
            choice = ["I am in North America (60 Hz, 120 Hz, 180 Hz, ...)", "I am in Europe (50 Hz, 100 Hz, 150 Hz, ...)", "Cancel"];
            rep = uiconfirm(app.UIFigure, 'Where has your protocol been conducted ?', 'Notch filter', 'Options', choice);

            switch rep
                case choice(1)
                    frequence = [60 120 180 240];
                case choice(2)
                    frequence = [50 100 150 200];
                case choice(3)
                    return
            end

            % Building Structure
            structure = struct();
            structure.NotchFilter.ToRun = true;
            structure.NotchFilter.Parameters = frequence;
            
            date = app.getDateFromBrainstormStudyMAT(cFiles);
            
            if app.AnalysisType == "EEG"
                cFiles = EEG_Pipeline(cFiles, structure);
            elseif app.AnalysisType == "MEG"
                cFiles = MEG_Pipeline(cFiles, structure);
            end
            
            app.modifyBrainstormStudyMATDate(cFiles, date);
            app.updateTreeAndCheckNewStudies;
            
            
        end

        % Button pushed function: BandpassFilterButton
        function BandpassFilterButtonPushed(app, event)

            cFiles = app.selectedStudies;

            % Input for Frequences
            title = 'Enter the frequencies cut off';
            
            question = 'Lower cut off frequency (Hz): ';
            LowPass = string(inputdlg(question, title));

            question = 'Higher cut off frequency (Hz): ';
            HighPass = string(inputdlg(question, title));

            if isempty(HighPass) || isempty(LowPass)
                uialert(app.UIFigure, "Please enter an upper and lower frequency cutoff points.", 'Error')
                return
            end
            
            % Building Structure
            structure.BandPassFilter.ToRun = true;
            structure.BandPassFilter.Parameters = [str2double(LowPass) str2double(HighPass)];
            
            date = app.getDateFromBrainstormStudyMAT(cFiles);
            
            if app.AnalysisType == "EEG"
                cFiles = EEG_Pipeline(cFiles, structure);
            elseif app.AnalysisType == "MEG"
                cFiles = MEG_Pipeline(cFiles, structure);
            end

            app.modifyBrainstormStudyMATDate(cFiles, date);
            app.updateTreeAndCheckNewStudies;         
            
        end

        % Button pushed function: PowerSpectrumDensityButton
        function PowerSpectrumDensityButtonPushed(app, event)

            cFiles = app.selectedStudies;
            
            % Select Window Length
            if app.AnalysisType == "EEG"
                winLength = 10;
            elseif app.AnalysisType == "MEG"
                winLength = 4;
            end
            
            
            % Building Structure
            structure.PowerSpectrumDensity.ToRun = true;
            structure.PowerSpectrumDensity.WindowLength = winLength;
            
            if app.AnalysisType == "EEG"
                EEG_Pipeline(cFiles, structure);
            elseif app.AnalysisType == "MEG"
                MEG_Pipeline(cFiles, structure);
            end
            
        end

        % Button pushed function: ConvertEpochstoContinueButton
        function ConvertEpochstoContinueButtonPushed(app, event)

            cFiles = app.selectedStudies;
            
            % Building Structure
            structure.ConvertEpochToContinue.ToRun = true;
            
            MEG_Pipeline(cFiles, structure);
        end

        % Button pushed function: DetectArtifactButton
        function DetectArtifactButtonPushed(app, event)
            cFiles = app.selectedStudies;
             
            % Input for type of artifact
            choice = ["Cardiac", "Blink", "Low Frequency Artifact (1-7 Hz; eye/teeth movement)", ...
                        "High Frequency Artifact (40-240 Hz; muscle movement/sensor artifacts)"];
            
            
            % Select Subject associated with the dataset
            index = listdlg('Name', 'Select the Artifact to detect', 'ListString', choice, ...
                            'ListSize', [500 200], 'SelectionMode', 'multiple');
            if isempty(index)
                return
            end
            
            
            structure = struct();
            if (any(1 == index))
                    structure.DetectArtifact.Cardiac.Channel = 'ECG';
                    structure.DetectArtifact.Cardiac.EventName = 'cardiac';
            end
            
            if (any(2 == index))
                    structure.DetectArtifact.Blink.Channel = 'VEOG';
                    structure.DetectArtifact.Blink.EventName = 'blink';
            end
            
            if (any(3 == index))
                    structure.DetectArtifact.Other.LowFrequence = 1;
            end
            
            if (any(4 == index))
                    structure.DetectArtifact.Other.HighFrequence = 1;
            end

            MEG_Pipeline(cFiles, structure);
        end

        % Button pushed function: RenameEventsButton
        function RenameEventsButtonPushed(app, event)

            cFiles = app.selectedStudies;

            % Display all Events
            choice = app.getEvents(cFiles);

            index = listdlg('ListString', choice, 'PromptString', 'Rename?');
            if length(index) ~= 1
                waitfor(errordlg("Choose one and only one event to rename."))
                return
            end
            oldName = choice{index};

            % Input for New Name
            title = 'Nouveau nom d''évenement';
            question = 'Nouveau nom ?';
            newName = char(inputdlg(question, title));

            if isempty(newName)
                waitfor(errordlg("Please enter a new event name."))
                return
            end
            
            % Changing Name
            bst_process('CallProcess', 'process_evt_rename', cFiles, [], ...
                'src',  oldName, ...
                'dest', newName);
        end

        % Button pushed function: DeleteEventsButton
        function DeleteEventsButtonPushed(app, event)
            cFiles = app.selectedStudies;

            % Display all Events
            choice = app.getEvents(cFiles);

            index = listdlg('ListString', choice, 'PromptString', 'delete?');

            if isempty(index)
                msgbox("Missing event to delete.")
                return
            end

            % Deleting Events
            for i = 1:length(index)

                bst_process('CallProcess', 'process_evt_delete', cFiles, [], ...
                    'eventname', choice{index(i)});
            end
        end

        % Button pushed function: RemoveSimultaneousEventsButton
        function RemoveSimultaneousEventsButtonPushed(app, event)
            cFiles = app.selectedStudies;

            % Display all Events
            choice = app.getEvents(cFiles);

            indexR = listdlg('ListString', choice, 'PromptString', 'Remove the event named: ');
            indexT = listdlg('ListString', choice, 'PromptString', 'When it is too close to the event: ');

            if length(indexR) > 1 || length(indexT) > 1
                waitfor(errordlg("Choose only one event."))
                return
            elseif isempty(indexR) || isempty(indexT)
                waitfor(errordlg("Missing event to remove or to targert."))
                return
            end

            remove = choice{indexR};
            target = choice{indexT};
            
            structure = struct();
            structure.RemoveSimultaneaousEvents.EventToRemove = remove;
            structure.RemoveSimultaneaousEvents.TargetEvent = target;
            
            MEG_Pipeline(cFiles, structure);

%             % Remove Simultaneaous Events
%             bst_process('CallProcess', 'process_evt_remove_simult', cFiles, [], ...
%                 'remove', remove, ...
%                 'target', target, ...
%                 'dt',     0.25, ...
%                 'rename', 0);
            
        end

        % Button pushed function: SSPButton
        function SSPButtonPushed(app, event)
            cFiles = app.selectedStudies;

            % Display all Events
            choice = app.getEvents(cFiles);

            index = listdlg('ListString', choice, 'PromptString', 'Which event do you want to use to compute the SSP ?:');
            
            if length(index) > 1
                waitfor(errordlg("Choose only one event."))
                return
            elseif isempty(index)
                waitfor(errordlg("Missing event to remove or to targert."))
                return
            end

            eventName = choice{index};
            
            
            % Building Structure
            if (eventName == "cardiac")
                structure.SSP.Cardiac.ToRun = true;
                structure.SSP.Cardiac.EventName = eventName;
            
            elseif (eventName == "blink")
                
                structure.SSP.Blink.ToRun = true;
                structure.SSP.Blink.EventName = eventName;
            
            else
                structure.SSP.Generic.ToRun = true;
                structure.SSP.Generic.EventName = eventName;
            end
            
            
            MEG_Pipeline(cFiles, structure);
            
        end

        % Button pushed function: AddEEGPositionsButton
        function AddEEGPositionsButtonPushed(app, event)
            cFiles = app.selectedStudies;

            % Input for either Default Pattern or Import File
            choice = ["Use default pattern", "Import a file ", "Cancel"];
            rep = uiconfirm(app.UIFigure, 'Import a file or use a default ?', ...
                'EEG position', 'Options', choice);
         
            disp(rep)
            
            structure.AddEEGPosition.ToRun = true;
            
            switch rep
                
                case choice(1)
                    
                    % List of available cap
                    capTypes = {'Colin27: BrainProducts EasyCap 128'};
                    capNumber = {22};
                    
                    index = listdlg('Name', 'Choose your Cap!', 'ListString', capTypes, 'ListSize', [350, 150]); 
                    if isempty(index)
                        return
                    end
                    
                    % Selecting Cap
                    capNumber = capNumber{index};
                    
                    % Building Structure
                    structure.AddEEGPosition.FileType = 'Default';
                    structure.AddEEGPosition.CapNumber = capNumber;
                    structure.AddEEGPosition.Cap = capTypes{index};
                    
                    
                case choice(2)
                    
                    title = "Select the electrode file (it should have the *.elc extension)";
                    extension = {'*.elc'};
                    [file, ~] = app.getFilePath(title, extension);
                    
                    % Building Structure
                    structure.AddEEGPosition.FileType = 'Import';
                    structure.AddEEGPosition.ChannelFile = file;
                    
                otherwise
                    return
            end

                    
            EEG_Pipeline(cFiles, structure);
        end

        % Button pushed function: RefineRegistrationButton
        function RefineRegistrationButtonPushed(app, event)
            cFiles = app.selectedStudies;

            structure.RefineRegistration.ToRun = true;
            EEG_Pipeline(cFiles, structure);
        end

        % Button pushed function: ProjectElectrodesonScalpButton
        function ProjectElectrodesonScalpButtonPushed(app, event)

            cFiles = app.selectedStudies;
            structure.ProjectElectrodeOnScalp.ToRun = true;
            EEG_Pipeline(cFiles, structure);
        end

        % Button pushed function: DetectHeartbeatsButton
        function DetectHeartbeatsButtonPushed(app, event)

            cFiles = app.selectedStudies;

            
            structure = struct();
            structure.DetectArtifact.Cardiac.Channel = 'ECG';
            structure.DetectArtifact.Cardiac.EventName = 'cardiac';
            
            EEG_Pipeline(cFiles, structure);
        end

        % Button pushed function: ImportEventsButton
        function ImportEventsButtonPushed(app, event)

            cFiles = app.selectedStudies;

            title = "Select the event file (it should have the *.txt extension)";
            extension = {'*.txt'};
            
            [filePath, ~] = app.getFilePath(title, extension);
            
            title = 'Name the event';
            question = 'Name of the imported events:';
            newName = char(inputdlg(question, title));

            bst_process('CallProcess', 'process_evt_import', cFiles, [], ...
                'evtfile', {filePath, 'ARRAY-TIMES'}, ...
                'evtname', newName);
        end

        % Button pushed function: AverageReferenceButton
        function AverageReferenceButtonPushed(app, event)

            cFiles = app.selectedStudies;

            structure.AverageReference.ToRun = true;
            
            EEG_Pipeline(cFiles, structure);
 
        end

        % Button pushed function: ICAEEGButton
        function ICAEEGButtonPushed(app, event)

            cFiles = app.selectedStudies;

            % Input for number of components
            title = 'ICA components';
            question = 'How many ICA components (nb electrode / 2): ';
            ICAcomponents = inputdlg(question, title);

            if isempty(ICAcomponents)
                return
            end

            
            % Building Structure
            structure.ICA.ToRun = true;
            structure.ICA.NumberOfComponents = str2double(ICAcomponents{1});
            
            
            try
                event.Source
            catch
                MEG_Pipeline(cFiles, structure);
            end
            
            EEG_Pipeline(cFiles, structure);
        end

        % Button pushed function: ICAMEGButton
        function ICAMEGButtonPushed(app, event)
            
            app.ICAEEGButtonPushed();
        end

        % Selection change function: TabGroup1
        function TabGroup1SelectionChanged(app, event)
            app.switchAnalysisTab("TabGroup1");
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            app.switchAnalysisTab("TabGroup2");
        end

        % Value changed function: TypeofanalysisDropDown
        function TypeofanalysisDropDownValueChanged(app, event)
            app.switchAnalysisTab("DropDown");
        end

        % Button pushed function: OpenBrainstormButton
        function OpenBrainstormButtonPushed(app, event)
            brainstorm;
        end

        % Button pushed function: SelectDeselectallButton
        function SelectDeselectallButtonPushed(app, event)
            app.selectAllStudies;
        end

        % Button pushed function: DeleteStudySubjectButton
        function DeleteStudySubjectButtonPushed(app, event)
            cFiles = app.selectedStudies;

            % Choix des types de données a importer.
            choice = ["Delete Folder (recommended)", "Delete File", "Delete Subject", "Cancel"];
            type = uiconfirm(app.UIFigure, "Which file do you want to delete ?", "Files to delete", 'Options', choice);

            switch type
                case choice(1)
                    del = 2;
                case choice(2)
                    del = 1;
                case choice(3)
                    del = 3;
                case choice(4)
                    return
            end

            %for i = 1:length(cFiles)
                bst_process('CallProcess', 'process_delete', cFiles, [], ...
                'target', del); % 1: fichier 2: folder 3: subjects
            %end
            
            
            app.updateTree;
        end

        % Button pushed function: UpdateTreeButton
        function UpdateTreeButtonPushed(app, event)
            app.updateTree;
        end

        % Button pushed function: UpdateEventsButton
        function UpdateEventsButtonPushed(app, event)
            cFiles = app.selectedStudies;
            delete(app.EventsDetectedPanel.Children());
            app.createHistoryEventButton(cFiles, 'event')
        end

        % Button pushed function: UpdateHistoryButton
        function UpdateHistoryButtonPushed(app, event)
            cFiles = app.selectedStudies();
            delete(app.HistoryPanel.Children());
            app.createHistoryEventButton(cFiles, 'history')
        end

        % Callback function
        function AddTagButtonPushed(app, event)
            cFiles = app.selectedStudies;

            title = 'Add tag';
            question = 'Tag name to add: ';
            Tag = char(inputdlg(question, title));
            
            if isempty(Tag)
                return
            end

            choice = ["Add Tag to the file Name", "Add tag to the file path", "Cancel"];

            rep = uiconfirm(app.UIFigure, 'where do you want to add the tag ?', ...
                'Add Tag', 'Options', choice);

            switch rep
                case choice(1)
                    bst_process('CallProcess', 'process_add_tag', cFiles, [], ...
                        'tag',           Tag, ...
                        'output',        1);

                case choice(2)
                    bst_process('CallProcess', 'process_add_tag', cFiles, [], ...
                        'tag',           Tag, ...
                        'output',        2);
                    
                case choice(3)
                    return
            end
        end

        % Callback function
        function CreateEventFiletsvButtonPushed(app, event)
            %BOUTON SUPPRIMÉ ?
            
            cFiles = app.selectedStudies;
            app.createTsvFile(cFiles);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            rep = uiconfirm(app.UIFigure, 'Do you want to close the app ?', 'Close request');

            if rep == "OK"
                delete(app)
            end

        end

        % Button pushed function: ColorMode
        function ColorModePushed(app, event)
           
            if app.ColorMode.Text == "Dark Mode"
                backColor = [0 0 0];
                textColor = [1 1 1];
                app.ColorMode.Text = "Bright Mode";
            elseif app.ColorMode.Text == "Bright Mode"
                backColor = [0.94 0.94 0.94];
                textColor = [0 0 0];
                app.ColorMode.Text = "Dark Mode";
            end

            app.switchColorModeForParent(app.UIFigure, backColor, textColor);
            
        end

        % Button pushed function: OpenPipelineBuilderButton
        function OpenPipelineBuilderButtonPushed(app, event)
            % Open Pipeline Builder
            run('Pipeline_Builder.mlapp');
        end

        % Button pushed function: ConvertToBIDSButton
        function ConvertToBIDSButtonPushed(app, event)
            % Input for BIDS folder
            BIDSfolderName = char(inputdlg('Name of BIDS folder', 'Name of BIDS folder'));
            if isempty(BIDSfolderName)
                return
            end
            
            path = uigetdir(app.Alab1Path, "Where to save the BIDS folder");
            
            if isequal(path, 0)
                return
            end
            
            BIDSpath = fullfile(path, BIDSfolderName, '/');            
            
            cFiles = app.selectedStudies;
            
            if app.AnalysisType == "EEG"
            
                %app.EEGConvertToBIDS(cFiles, BIDSpath);
                structure = struct();
                structure.ConvertToBIDS.ToRun = true;
                structure.ConvertToBIDS.BIDSpath = BIDSpath;
                EEG_Pipeline(cFiles, structure, app)
            
            elseif app.AnalysisType == "MEG"
            
                app.MEGConvertToBIDS(cFiles, BIDSpath);
            
            end
        end

        % Button pushed function: ReloadProtocolButton
        function ReloadProtocolButtonPushed(app, event)
            db_reload_database(bst_get('iProtocol'));
        end

        % Button pushed function: ClearHistoryButton
        function ClearHistoryButtonPushed(app, event)
            delete(app.HistoryPanel.Children);
        end

        % Button pushed function: ClearEventsButton
        function ClearEventsButtonPushed(app, event)
            delete(app.EventsDetectedPanel.Children);
        end

        % Button pushed function: ImportinDataBaseButton
        function ImportinDataBaseButtonPushed(app, event)
            
            cFiles = app.selectedStudies;

            % Select Subject
%             Infos = app.getCurrentInformations;
%             liste = cell(1, length(Infos.Subjects.Subject));
%             for i = 1:length(Infos.Subjects.Subject)
%                 liste{i} = Infos.Subjects.Subject(i).Name;
%             end
%             index = listdlg('Name', 'Choose the Subject', 'ListString', liste);
%             if isempty(index)
%                 return
%             end
%             subjectName = liste{index};
%             
%             % Check Nodes of Selected Subject
%             NodetoCheck = matlab.ui.container.TreeNode.empty();
%             studies = app.Tree.Children(index).Children;
%             for i = 1:length(studies)
%                 if i == 1 || i == 2
%                     continue
%                 end
%                 NodetoCheck(i-2) = studies(i);
%             end
%             app.Tree.CheckedNodes = NodetoCheck;
%             cFiles = app.selectedStudies;
            
            % Select Event
            allEvents = app.getEvents(cFiles);

            index = listdlg('ListString', allEvents, 'PromptString', 'Import Events');
            if isempty(index)
                waitfor(errordlg("Please select at least one event"))
                return
            end
            event = allEvents{index};


            % Input for Epoch Time
            title = 'Enter Epoch Time (typically (-0.3 ; 0.5)';
            question = 'Epoch Time in seconds (format -X.X ; X.X): ';
            epochTime = string(inputdlg(question, title));
            
            
            % Building Structure
            structure = struct();
            structure.ImportEvent.ToRun = true;
            structure.ImportEvent.Event = event;
            structure.ImportEvent.EpochTime = str2double(split(epochTime, ';'));
            
            for i = 1:length(cFiles)
                [subjectName] = app.getFilesPathFromcFile(cFiles(i));
                structure.ImportEvent.SubjectName = subjectName;
                Utility_Pipeline(structure, cFiles(i));
            end
            
            app.updateTreeAndCheckNewStudies;
            
        end

        % Callback function
        function ProcessMenuSelected(app, event)
            
        end

        % Callback function
        function ProcessMenuSelected2(app, event)
            if app.ProcessMenu.Checked
                a=msgbox('PROCESS');
            else
                a=msgbox('NOPE');
            end
            pause(5);
            delete(a);
        end

        % Callback function
        function PreProcessMenuSelected(app, event)
            if app.ProcessMenu.Checked
                a=msgbox('PROCESS');
            else
                a=msgbox('NOPE');
            end
            pause(5);
            delete(a);
        end

        % Button pushed function: RejectTrialsButton
        function RejectTrialsButtonPushed(app, event)

            cFiles = app.selectedStudies;

            options = ["MEG gradio", "MEG magneto", "EEG", "SEEG/ECOG", "EOG", "ECG"];
            index = listdlg('Name', 'Signal Detection Type', 'ListString', options, 'ListSize', [400, 500]);
            if isempty(index)
                return
            end
            
            selectedOptions = options(index);
            
            title = 'Enter the value';
            
            structure = struct();
            if (contains(options(1), selectedOptions))
                
                question = 'MEG gradio minimum (fT): ';
                MEGgradMin = str2double(inputdlg(question, title));
    
                question = 'MEG gradio maximum (fT): ';
                MEGgradMax = str2double(inputdlg(question, title));
                
                structure.RejectTrial.MEGgrad = [MEGgradMin MEGgradMax];
               
            end
            
            if (contains(options(2), selectedOptions))
                
                question = 'MEG magneto minimum (fT): ';
                MEGmagnetoMin = str2double(inputdlg(question, title));
    
                question = 'MEG magneto maximum (fT): ';
                MEGmagnetoMax = str2double(inputdlg(question, title));
                
                structure.RejectTrial.MEGmagneto = [MEGmagnetoMin MEGmagnetoMax];
                
            end
            
            if (contains(options(3), selectedOptions))
                
                question = 'EEG minimum (µV): ';
                EEGmin = str2double(inputdlg(question, title));
    
                question = 'EEG maximum (µV): ';
                EEGmax = str2double(inputdlg(question, title));
                
                structure.RejectTrial.EEG = [EEGmin EEGmax];
                
            end
            
            if (contains(options(4), selectedOptions))
                
                question = 'SEEG/ECOG minimum (µV): ';
                SEEG_ECOGmin = string(inputdlg(question, title));
    
                question = 'SEEG/ECOG maximum (µV): ';
                SEEG_ECOGmax = string(inputdlg(question, title));
                
                structure.RejectTrial.SEEG_ECOG = [SEEG_ECOGmin SEEG_ECOGmax];
                
            end
            
            if (contains(options(5), selectedOptions))
                
                question = 'EOG minimum (µV): ';
                EOGmin = string(inputdlg(question, title));
    
                question = 'EOG maximum (µV): ';
                EOGmax = string(inputdlg(question, title));
                
                structure.RejectTrial.EOG = [EOGmin EOGmax];
                
            end
            
            if (contains(options(6), selectedOptions))
                
                question = 'ECG minimum (mV): ';
                ECGmin = string(inputdlg(question, title));
    
                question = 'ECG maximum (mV): ';
                ECGmax = string(inputdlg(question, title));
                
                structure.RejectTrial.ECG = [ECGmin ECGmax];
                 
            end

            Utility_Pipeline(structure, cFiles);

        end

        % Button pushed function: ElectrodetsvButton
        function ElectrodetsvButtonPushed(app, event)
            cFiles = app.selectedStudies;
            app.createTsvElectrodeFile(cFiles, '/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin');
        end

        % Button pushed function: SelectGoodTrialButton
        function SelectGoodTrialButtonPushed(app, event)
            cFiles = app.selectedStudies;
            assignin("base", "cFiles", cFiles);
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
            
            cFiles = app.selectedStudies;
            
            averageType = ["Everything", "By Subject", "By Folder (subject average)", "By Folder (grand average)", "By Trial Group (folder average)", "By Trial Group (subject average)", "By Trial Group (grand average)"];
            functionType = ["Arithmetic Average", "Average Absolute Values", "Root Mean Square", "Standard Deviation", "Standard Error", "Arithmetic Average + Standard Deviation", "Arithmetic Average + Standard Error", "Median"];
            
            indexAverageType = listdlg('Name', 'Group Files', 'ListString', averageType, 'ListSize', [400, 500], 'SelectionMode', 'single');
            if isempty(indexAverageType)
                return
            end
            
            indexFunctionType = listdlg('Name', 'Function Type', 'ListString', functionType, 'ListSize', [400, 500], 'SelectionMode', 'single');
            if isempty(indexFunctionType)
                return
            end
            
            structure = struct();
            
            structure.Average.AverageType = averageType(indexAverageType);
            structure.Average.AverageTypeNumber = indexAverageType;
            
            structure.Average.AverageFunction = functionType(indexFunctionType);
            structure.Average.AverageFunctionNumber = indexFunctionType;
            
            Utility_Pipeline(structure, cFiles);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UpdateTreeButton = uibutton(app.UIFigure, 'push');
            app.UpdateTreeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTreeButtonPushed, true);
            app.UpdateTreeButton.Text = 'Update Tree';
            app.CurrentProtocolEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentProtocolEditField.Tag = 'CurrentProto';
            app.CurrentProtocolEditField.Editable = 'off';
            app.CurrentProtocolLabel = uilabel(app.UIFigure);
            app.CurrentProtocolLabel.Text = 'Current Protocol:';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.EEGTabGr2 = uitab(app.TabGroup2);
            app.EEGTabGr2.Title = 'EEG';
            app.AverageReferenceButton = uibutton(app.EEGTabGr2, 'push');
            app.AverageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AverageReferenceButtonPushed, true);
            app.AverageReferenceButton.Text = 'Average Reference';
            app.ICAEEGButton = uibutton(app.EEGTabGr2, 'push');
            app.ICAEEGButton.ButtonPushedFcn = createCallbackFcn(app, @ICAEEGButtonPushed, true);
            app.ICAEEGButton.Text = 'ICA';
            app.MEGTabGr2 = uitab(app.TabGroup2);
            app.MEGTabGr2.Title = 'MEG';
            app.RemoveSimultaneousEventsButton = uibutton(app.MEGTabGr2, 'push');
            app.RemoveSimultaneousEventsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSimultaneousEventsButtonPushed, true);
            app.RemoveSimultaneousEventsButton.Text = 'Remove Simultaneous Events';
            app.DetectArtifactButton = uibutton(app.MEGTabGr2, 'push');
            app.DetectArtifactButton.ButtonPushedFcn = createCallbackFcn(app, @DetectArtifactButtonPushed, true);
            app.DetectArtifactButton.Text = 'Detect Artifact';
            app.SSPButton = uibutton(app.MEGTabGr2, 'push');
            app.SSPButton.ButtonPushedFcn = createCallbackFcn(app, @SSPButtonPushed, true);
            app.SSPButton.Text = 'SSP';
            app.ICAMEGButton = uibutton(app.MEGTabGr2, 'push');
            app.ICAMEGButton.ButtonPushedFcn = createCallbackFcn(app, @ICAMEGButtonPushed, true);
            app.ICAMEGButton.Text = 'ICA';
            app.SelectDeselectallButton = uibutton(app.UIFigure, 'push');
            app.SelectDeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDeselectallButtonPushed, true);
            app.SelectDeselectallButton.Text = 'Select/Deselect all';
            app.TabGroup1 = uitabgroup(app.UIFigure);
            app.TabGroup1.SelectionChangedFcn = createCallbackFcn(app, @TabGroup1SelectionChanged, true);
            app.EEGTabGr1 = uitab(app.TabGroup1);
            app.EEGTabGr1.Title = 'EEG';
            app.AddEEGPositionsButton = uibutton(app.EEGTabGr1, 'push');
            app.AddEEGPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @AddEEGPositionsButtonPushed, true);
            app.AddEEGPositionsButton.Text = 'Add EEG Positions';
            app.RefineRegistrationButton = uibutton(app.EEGTabGr1, 'push');
            app.RefineRegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RefineRegistrationButtonPushed, true);
            app.RefineRegistrationButton.Text = 'Refine Registration';
            app.ProjectElectrodesonScalpButton = uibutton(app.EEGTabGr1, 'push');
            app.ProjectElectrodesonScalpButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectElectrodesonScalpButtonPushed, true);
            app.ProjectElectrodesonScalpButton.Text = 'Project Electrodes on Scalp';
            app.DetectHeartbeatsButton = uibutton(app.EEGTabGr1, 'push');
            app.DetectHeartbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectHeartbeatsButtonPushed, true);
            app.DetectHeartbeatsButton.Text = 'Detect Heartbeats';
            app.ImportEventsButton = uibutton(app.EEGTabGr1, 'push');
            app.ImportEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEventsButtonPushed, true);
            app.ImportEventsButton.Text = 'Import Events';
            app.MEGTabGr1 = uitab(app.TabGroup1);
            app.MEGTabGr1.Title = 'MEG';
            app.ConvertEpochstoContinueButton = uibutton(app.MEGTabGr1, 'push');
            app.ConvertEpochstoContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertEpochstoContinueButtonPushed, true);
            app.ConvertEpochstoContinueButton.Text = 'Convert Epochs to Continue';
            app.FrequencyAnalysisPanel = uipanel(app.UIFigure);
            app.FrequencyAnalysisPanel.Title = 'Frequency Analysis';
            app.NotchFilterButton = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.NotchFilterButton.ButtonPushedFcn = createCallbackFcn(app, @NotchFilterButtonPushed, true);
            app.NotchFilterButton.Text = 'Notch Filter';
            app.BandpassFilterButton = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.BandpassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BandpassFilterButtonPushed, true);
            app.BandpassFilterButton.Text = 'Band-pass Filter';
            app.PowerSpectrumDensityButton = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.PowerSpectrumDensityButton.ButtonPushedFcn = createCallbackFcn(app, @PowerSpectrumDensityButtonPushed, true);
            app.PowerSpectrumDensityButton.Text = 'Power Spectrum Density';
            app.UtilityPanel = uipanel(app.UIFigure);
            app.UtilityPanel.Title = 'Utility';
            app.OpenBrainstormButton = uibutton(app.UtilityPanel, 'push');
            app.OpenBrainstormButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBrainstormButtonPushed, true);
            app.OpenBrainstormButton.Text = 'Open Brainstorm ';
            app.TypeofanalysisDropDown = uidropdown(app.UtilityPanel);
            app.TypeofanalysisDropDown.Items = {'EEG', 'MEG'};
            app.TypeofanalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofanalysisDropDownValueChanged, true);
            app.TypeofanalysisDropDown.Tag = 'FormatDeFichier';
            app.TypeofanalysisDropDown.Value = 'MEG';
            app.TypeofanalysisDropDownLabel = uilabel(app.UtilityPanel);
            app.TypeofanalysisDropDownLabel.Text = 'Type of analysis';
            app.ImportinDataBaseButton = uibutton(app.UtilityPanel, 'push');
            app.ImportinDataBaseButton.ButtonPushedFcn = createCallbackFcn(app, @ImportinDataBaseButtonPushed, true);
            app.ImportinDataBaseButton.Text = 'Import in DataBase';
            app.ColorMode = uibutton(app.UtilityPanel, 'push');
            app.ColorMode.ButtonPushedFcn = createCallbackFcn(app, @ColorModePushed, true);
            app.ColorMode.Text = 'Dark Mode';
            app.ConvertToBIDSButton = uibutton(app.UtilityPanel, 'push');
            app.ConvertToBIDSButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertToBIDSButtonPushed, true);
            app.ConvertToBIDSButton.Text = 'Convert To BIDS';
            app.RenameEventsButton = uibutton(app.UtilityPanel, 'push');
            app.RenameEventsButton.ButtonPushedFcn = createCallbackFcn(app, @RenameEventsButtonPushed, true);
            app.RenameEventsButton.Text = 'Rename Events';
            app.DeleteEventsButton = uibutton(app.UtilityPanel, 'push');
            app.DeleteEventsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEventsButtonPushed, true);
            app.DeleteEventsButton.Text = 'Delete Events';
            app.SubjectStudyPanel = uipanel(app.UIFigure);
            app.SubjectStudyPanel.Title = 'Subject/Study';
            app.CreateSubjectButton = uibutton(app.SubjectStudyPanel, 'push');
            app.CreateSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSubjectButtonPushed, true);
            app.CreateSubjectButton.Text = 'Create Subject';
            app.DeleteStudySubjectButton = uibutton(app.SubjectStudyPanel, 'push');
            app.DeleteStudySubjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteStudySubjectButtonPushed, true);
            app.DeleteStudySubjectButton.Text = 'Delete Study/Subject';
            app.ReviewRawFilesButton = uibutton(app.SubjectStudyPanel, 'push');
            app.ReviewRawFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewRawFilesButtonPushed, true);
            app.ReviewRawFilesButton.Text = 'Review Raw Files';
            app.ProtocolPanel = uipanel(app.UIFigure);
            app.ProtocolPanel.Title = 'Protocol';
            app.CreateProtocolButton = uibutton(app.ProtocolPanel, 'push');
            app.CreateProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @CreateProtocolButtonPushed, true);
            app.CreateProtocolButton.Text = 'Create Protocol';
            app.LoadProtocolButton = uibutton(app.ProtocolPanel, 'push');
            app.LoadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProtocolButtonPushed, true);
            app.LoadProtocolButton.Text = 'Load Protocol';
            app.DeleteProtocolButton = uibutton(app.ProtocolPanel, 'push');
            app.DeleteProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProtocolButtonPushed, true);
            app.DeleteProtocolButton.Text = 'Delete Protocol';
            app.ReloadProtocolButton = uibutton(app.ProtocolPanel, 'push');
            app.ReloadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadProtocolButtonPushed, true);
            app.ReloadProtocolButton.Text = 'Reload Protocol';
            app.HistoryPanel = uipanel(app.UIFigure);
            app.HistoryPanel.Title = 'History';
            app.EventsDetectedPanel = uipanel(app.UIFigure);
            app.EventsDetectedPanel.Title = 'Events Detected';
            app.PipelineTextArea = uitextarea(app.UIFigure);
            app.ClearHistoryButton = uibutton(app.UIFigure, 'push');
            app.ClearHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearHistoryButtonPushed, true);
            app.ClearHistoryButton.Text = 'Clear History';
            app.ClearEventsButton = uibutton(app.UIFigure, 'push');
            app.ClearEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearEventsButtonPushed, true);
            app.ClearEventsButton.Text = 'Clear Events';
            app.UpdateHistoryButton = uibutton(app.UIFigure, 'push');
            app.UpdateHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateHistoryButtonPushed, true);
            app.UpdateHistoryButton.Text = 'Update History';
            app.UpdateEventsButton = uibutton(app.UIFigure, 'push');
            app.UpdateEventsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEventsButtonPushed, true);
            app.UpdateEventsButton.Text = 'Update Events';
            app.PipelinePanel = uipanel(app.UIFigure);
            app.PipelinePanel.Title = 'Pipeline';
            app.OpenPipelineBuilderButton = uibutton(app.PipelinePanel, 'push');
            app.OpenPipelineBuilderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPipelineBuilderButtonPushed, true);
            app.OpenPipelineBuilderButton.Text = 'Open Pipeline Builder';
            app.ClearPipelineButton = uibutton(app.PipelinePanel, 'push');
            app.ClearPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPipelineButtonPushed, true);
            app.ClearPipelineButton.Text = 'Clear Pipeline';
            app.ImportPipelineButton = uibutton(app.PipelinePanel, 'push');
            app.ImportPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPipelineButtonPushed, true);
            app.ImportPipelineButton.Text = 'Import Pipeline';
            app.RunPipelineButton = uibutton(app.PipelinePanel, 'push');
            app.RunPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @RunPipelineButtonPushed, true);
            app.RunPipelineButton.Text = 'Run Pipeline';
            app.RejectTrialsButton = uibutton(app.UIFigure, 'push');
            app.RejectTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @RejectTrialsButtonPushed, true);
            app.RejectTrialsButton.Text = 'Reject Trials';
            app.ElectrodetsvButton = uibutton(app.UIFigure, 'push');
            app.ElectrodetsvButton.ButtonPushedFcn = createCallbackFcn(app, @ElectrodetsvButtonPushed, true);
            app.ElectrodetsvButton.Text = 'Electrode.tsv';
            app.SelectGoodTrialButton = uibutton(app.UIFigure, 'push');
            app.SelectGoodTrialButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGoodTrialButtonPushed, true);
            app.SelectGoodTrialButton.Text = 'Select Good Trial';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.AverageButton = uibutton(app.UIFigure, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis_Tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end