classdef EEG_MEG_ANALYSIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Button                          matlab.ui.control.Button
        dateEDFButton                   matlab.ui.control.Button
        OpenPIPBUILDButton              matlab.ui.control.Button
        Hyperlink                       matlab.ui.control.Hyperlink
        TESTINGButton                   matlab.ui.control.Button
        EventsDetectedPanel             matlab.ui.container.Panel
        HistoryPanel                    matlab.ui.container.Panel
        MEGHyperlink                    matlab.ui.control.Hyperlink
        EEGHyperlink                    matlab.ui.control.Hyperlink
        ProtocolPanel                   matlab.ui.container.Panel
        DeleteProtocolButton            matlab.ui.control.Button
        LoadProtocolButton              matlab.ui.control.Button
        CreateProtocolButton            matlab.ui.control.Button
        SubjectStudyPanel               matlab.ui.container.Panel
        ReviewRawFilesButton            matlab.ui.control.Button
        DeleteStudySubjectButton        matlab.ui.control.Button
        CreateSubjectButton             matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        meg_bidsButton                  matlab.ui.control.Button
        eeg_bids_edfButton              matlab.ui.control.Button
        ColorMode                       matlab.ui.control.Button
        ImportinDataBaseButton          matlab.ui.control.Button
        UpdateHistoryButton             matlab.ui.control.Button
        UpdateEventsButton              matlab.ui.control.Button
        AddTagButton                    matlab.ui.control.Button
        TypeofanalysisDropDownLabel     matlab.ui.control.Label
        TypeofanalysisDropDown          matlab.ui.control.DropDown
        OpenBrainstormButton            matlab.ui.control.Button
        FrequencyAnalysisPanel          matlab.ui.container.Panel
        PowerSpectrumDensityButton      matlab.ui.control.Button
        BandpassFilterButton            matlab.ui.control.Button
        NotchFilterButton               matlab.ui.control.Button
        TabGroup1                       matlab.ui.container.TabGroup
        EEGTabGr1                       matlab.ui.container.Tab
        ImportEventsButton              matlab.ui.control.Button
        DetectHeartbeatsButton          matlab.ui.control.Button
        ProjectElectrodesonScalpButton  matlab.ui.control.Button
        RefineRegistrationButton        matlab.ui.control.Button
        AddEEGPositionsButton           matlab.ui.control.Button
        MEGTabGr1                       matlab.ui.container.Tab
        ConvertEpochstoContinueButton   matlab.ui.control.Button
        SelectDeselectallButton         matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        EEGTabGr2                       matlab.ui.container.Tab
        ICAEEGButton                    matlab.ui.control.Button
        AverageReferenceButton          matlab.ui.control.Button
        MEGTabGr2                       matlab.ui.container.Tab
        ICAMEGButton                    matlab.ui.control.Button
        SSPButton                       matlab.ui.control.Button
        DetectArtifactButton            matlab.ui.control.Button
        RemoveSimultaneousEventsButton  matlab.ui.control.Button
        DeleteEventsButton              matlab.ui.control.Button
        RenameEventsButton              matlab.ui.control.Button
        CurrentSubjectLabel             matlab.ui.control.Label
        CurrentSubjectEditField         matlab.ui.control.EditField
        CurrentProtocolLabel            matlab.ui.control.Label
        CurrentProtocolEditField        matlab.ui.control.EditField
        UpdateTreeButton                matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
    end


    properties (Access = private)

        % Global variables initialized as empty.

        % Global Variable
        AnalysisType = "";
        SensorType = "";
        FileFormat = "";
        AnatomyFileFormat = "";
        UseDefaultAnatomy = "";
        
        % Folder Name
        AppFolderName = "__APP__";
        
        % Paths
        BsToolboxPath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/rg/toolboxes/brainstorm3/";
        BsDatabasePath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/rg/bs_db/";
        ToolPath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/scripts/AnalysisTool/";
        WorkingFolderPath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/DefaultWorkingFolder/"; % Default Working Folder
        ProtocolPath = "";
        

    end

    methods (Access = private)

        function []  = switchAnalysisTab(app, trigger)
            % This function is called when the type of
            % analysis is changed. When called, we specify which component triggered the function.
            
            % Input>    [string]: The component that triggered the function
        
            switch trigger
                case "TabGroup1"
                    analysisType = app.TabGroup1.SelectedTab.Title;
                case "TabGroup2"
                    analysisType = app.TabGroup2.SelectedTab.Title;
                case "DropDown"
                    analysisType = app.TypeofanalysisDropDown.Value;
                case "EEG"
                    analysisType = 'EEG';
                case "MEG"
                    analysisType = 'MEG';
            end

            switch analysisType
                case 'EEG'
                    app.TabGroup1.SelectedTab = app.EEGTabGr1;
                    app.TabGroup2.SelectedTab = app.EEGTabGr2;
                    app.TypeofanalysisDropDown.Value = 'EEG';

                    app.AnalysisType = 'EEG';
                    app.SensorType = 'EEG';
                    app.FileFormat = 'EEG-BRAINAMP';    %ou EEG-DELTAMED. Default value, it is modified when reviewing raw file.
                    app.AnatomyFileFormat = 'FreeSurfer-fast';

                case 'MEG'
                    app.TabGroup1.SelectedTab = app.MEGTabGr1;
                    app.TabGroup2.SelectedTab = app.MEGTabGr2;
                    app.TypeofanalysisDropDown.Value = 'MEG';

                    app.AnalysisType = 'MEG';
                    app.SensorType = 'MEG';
                    app.FileFormat = 'CTF';
                    app.AnatomyFileFormat = 'FreeSurfer';
            end
        end

        function Infos = getCurrentInformations(app)
            % Create (or update if already created) a structure with the
            % current informations
            
            % Output> [structure]: Current informations
            %   fields: CurrentSubject, ProtocolInfo, Studies, Subjects
 
            Infos.ProtocolInfo = bst_get('ProtocolInfo');
            Infos.Studies = bst_get('ProtocolStudies');
            Infos.Subjects =  bst_get('ProtocolSubjects');
            %Infos.CurrentSubject = bst_get('Subject');

            % Check if there is an empty field.
            index = find(structfun(@isempty, Infos), 1);
            if ~isempty(index)
                
                switch index
                case 1
                    errorMessage = 'ProtocolInfo field is empty';
                case 2
                    errorMessage = 'ProtocolStudies field is empty';
                case 3
                    errorMessage = 'ProtocolSubjects field is empty';
                case 4
                    errorMessage = 'CurrentSubject field is empty';
                end   
                uialert(app.UIFigure, errorMessage, 'Error');    
            end
        end

        function [] = updateTree(app)
            % Update Informations in Tree

            Infos = app.getCurrentInformations;

            % Delete current Tree
            app.Tree.Children.delete;

            % Create a temporary node
            temporaryNode = uitreenode(app.Tree);
            temporaryNode.Text = "Temporary Node";

            % Iterate on all Studies in Protocol
            for i = 1:length(Infos.Studies.Study)
                % Get Subject Nodes already in Tree
                subjectNodes = app.Tree.Children;
                subjectNames = {subjectNodes.Text};

                % Get Subject Name of Study
                subjectNameChar = strsplit(Infos.Studies.Study(i).BrainStormSubject, '/');
                subjectNameString = convertCharsToStrings(subjectNameChar{1});

                % Check if Subject Name is in Subject Nodes
                index = find(contains(subjectNames,subjectNameString));
                if isempty(index)
                    % Create Subject Node in Tree
                    nodeSubject = uitreenode(app.Tree);
                    nodeSubject.Text = subjectNameString;
                else
                    % Get Subject node from Tree
                    nodeSubject = subjectNodes(index);
                end
                % Create Study Node in Subject Node
                nodeStudy = uitreenode(nodeSubject);
                nodeStudy.Text = Infos.Studies.Study(i).Name;
            end
            % Delete Temporary Node
            temporaryNode.delete
            
            expand(app.Tree, "all");
            
            app.updateCurrentProtocolCurrentSubject;
            
        end

        function [] = updateTreeAndCheckNewStudies(app)
            % Update and check the new studies.
            % date format: 01-JAN-2001
            
            oldStudies = string.empty();
            count = 0;
            
            subjects = app.Tree.Children;
            for i = 1:length(subjects)
                
                studies = subjects.Children;
                for j = 1:length(studies)
                    
                    count = count + 1;
                    oldStudies(count) = strcat(subjects(i).Text, '-', studies(j).Text);
                end
            end

            app.updateTree;

            
            NodetoCheck = matlab.ui.container.TreeNode.empty();
            nbNodeToCheck = 0;  
            
            subjects = app.Tree.Children;
            for i = 1:length(subjects)
                
                studies = subjects.Children;
                for j = 1:length(studies)
                    
                    newStudies = strcat(subjects(i).Text, '-', studies(j).Text);
                    
                    if ~any(oldStudies(:) == newStudies)
                    
                        nbNodeToCheck = nbNodeToCheck + 1;
                        NodetoCheck(nbNodeToCheck) = studies(j);
                    end
                end
            end
            
            if ~isempty(NodetoCheck)
                app.Tree.CheckedNodes = NodetoCheck;
            end            
        end
        
        function cFiles = selectedStudies(app)
            % Create (or update if already created) a cell containing the
            % current studies that are checked in the tree.
            
            % Output> [cell]: Paths to Raw Files (subject/study/data_0study...) that were copied in the Protocol Folder

            % Get all Checked Nodes
            sStudies_checked = app.Tree.CheckedNodes;
            
            cFiles = {};
            for i = 1:length(sStudies_checked)

                % Remove Subject Nodes that are checked (we keep only Study
                % Nodes)
                if class(sStudies_checked(i).Parent) == "matlab.ui.container.CheckBoxTree"
                    continue
                end

                % Concatenation to create File Path
                study_path = fullfile(sStudies_checked(i).Parent.Text, sStudies_checked(i).Text);
                
                if contains(sStudies_checked(i).Text, 'intra') || contains(sStudies_checked(i).Text, 'default')
                    study_folder = fullfile(study_path, 'brainstormstudy.mat');
                else
                    study_folder = fullfile(study_path, strcat('data_0raw_', sStudies_checked(i).Text(5:end), '.mat'));
                end

                % Save path in sFiles
                cFiles{i} = study_folder;
            end

            if isempty(cFiles)
                uialert(app.UIFigure, 'There no study selected, this might cause an error!', 'Error');
                uiwait(gcbf)
            end 
        end
        
        function [] = updateCurrentProtocolCurrentSubject(app)
            % Update the Current Protocol and Current Subject values in the text boxes.
        
            Infos = app.getCurrentInformations;
        
            app.CurrentProtocolEditField.Value = Infos.ProtocolInfo.Comment;
        
            %if ~isempty(Infos.CurrentSubject)
             %   app.CurrentSubjectEditField.Value = Infos.CurrentSubject.Name;
            %end
        
            app.ProtocolPath = fullfile('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/rg/bs_db/', Infos.ProtocolInfo.Comment, '/');
        
        end 

        function [] = selectAllStudies(app)
            
            % Select or Deselect all the studies in the tree.
     
            if isempty(app.Tree.CheckedNodes)
                app.Tree.CheckedNodes = app.Tree.Children;
            else
                app.Tree.CheckedNodes = [];
            end
 
        end

        function [path, extensionOut] = getFilePath(app, title, extensionIn)
            
            % If extension variable not given
            if ~exist('extensionIn','var')
                extensionIn = {'*'};
            end
            
            % File Manager                    
            [fileName, folderPath, index] = uigetfile(extensionIn, title, app.WorkingFolderPath, 'MultiSelect', 'on');
            
           
            % Stop if no file is selected
            if isequal(fileName, 0)
                    uialert(app.UIFigure, 'No file has been selected!', 'Error');
                    uiwait(gcbf)
            end
            
            
            % If the option 'All Files' is selected, get extension from
            % file name
            if length(extensionIn) == 1 || index == length(extensionIn)+1
                
                fileNameSplit = split(fileName, '.');
                extensionOut = fileNameSplit{end};
               
            else
                
                extensionOut = extensionIn{index}(2:end); 
            end
            
            % Build path
            path = fullfile(folderPath, fileName);


            % Convert char to cell when only one file is selected so that
            % the function always returns a cell
            if class(path) == "char"
                path = {path};
            end
            

            return
        end
        
        function path = getFolderPath(app, title)
            % No multiple selection...             
            
            path = uigetdir(app.WorkingFolderPath, title);

            if isequal(path, 0)
                uiconfirm(app.UIFigure, 'No Path has been selected', 'Error');
            end
            
            return
                
        end

        function [] = importAnatomy(app, SubjectName, AnatomyPath)
            % Import the anatomy of the subject.
            
            % Input>    SubjectName [char]: Subject Name
            %           AnatomyPath [char]: Path to folder containing the
            %                               anatomy.
                

             if app.AnalysisType == "MEG"
% 
%                 bst_process('CallProcess', 'process_import_anatomy', [], [], ...
%                     'subjectname', SubjectName, ...
%                     'mrifile', {AnatomyPath, app.AnatomyFileFormat}, ...
%                     'nvertices', 15000, ...
%                     'nas', [127, 213, 139], ...
%                     'lpa', [52, 113, 96], ...
%                     'rpa', [202, 113, 91], ...
%                     'ac', [127 119 149], ...
%                     'pc', [128 93 141], ...
%                     'ih', [131 114 206]);
% 
             elseif app.AnalysisType == "EEG"
% 
%                 bst_process('CallProcess', 'process_import_anatomy', [], [], ...
%                     'subjectname', SubjectName, ...
%                     'mrifile', {AnatomyPath, app.AnatomyFileFormat}, ...
%                     'nvertices', 15000);
            end
        end

        function [] = detectArtifact(app, sFiles, type, channel, name)
            % Detect artifact for the specified study.
            
            % Input>    sFiles [cell]: sFiles
            %           type [string]: type of artifact
            %           channel [string]: channel associated with type
            %           name [string]: name of the events detected
            
            
            if type == "cardiac"
                
                structure.DetectArtifact.ToRun = true;
                structure.DetectArtifact.Type = 'Cardiac';
                
                Pipeline_Template(structure, sFiles, app.SensorType);
                
%                 bst_process('CallProcess', 'process_evt_detect_ecg', sFiles, [], ...
%                     'channelname', channel, ...
%                     'timewindow',  [], ...
%                     'eventname',   name);

            elseif type == "blink"
                bst_process('CallProcess', 'process_evt_detect_eog', sFiles, [], ...
                    'channelname', channel, ...
                    'timewindow',  [], ...
                    'eventname',   name);
                
            % If user wants to detect artifact other than 'Cardiac' or
            % 'Blink'.
            else
                choice = ["YES", "NO"];

                repL = uiconfirm(app.UIFigure, 'Do you want to detect artifact of low frequency (eye/teeth movement) ?', ...
                    'What to detect?', 'Options', choice);

                repH = uiconfirm(app.UIFigure, 'Do you want to detect artifact of high frequency (muscle movement/sensor artifacts) ?', ...
                    'What to detect?', 'Options', choice);

                LowFreq = 0;
                if repL == choice{1}
                    LowFreq = 1;
                end

                HighFreq = 0;
                if repH == choice{1}
                    HighFreq = 1;
                end

                bst_process('CallProcess', 'process_evt_detect_badsegment', sFiles, [], ...
                    'timewindow',  [], ...
                    'sensortypes', 'MEG', ...
                    'threshold',   3, ...
                    'isLowFreq',   LowFreq, ...   % 0 ou 1: detect eyes/teeth movements
                    'isHighFreq',  HighFreq);      % 0 ou 1: detect muscular/sensor artifacts
            end
        end

        function allEvents = getEvents(app, cFiles)
            % Extract all events for every study in sFiles (sEvents).
            
            % Input>    sFiles [struc]

            % Output>   allEvents [cell]: Cell with all events that are
            %                               in at least one study.

            allEvents = {};
            compt = 1;

            for i = 1:length(cFiles)
                [~, ~, studyMATfile] = app.getStudyNameFromsFile(cFiles(i));
                studyFile = load(studyMATfile);
                eventsStruct = studyFile.F.events;

                for j = 1:length(eventsStruct)
                    if ~any(strcmp(allEvents, eventsStruct(j).label))
                        allEvents{compt} = eventsStruct(j).label;
                        compt = compt + 1;
                    end
                end
            end
        end

        function [] = createHistoryEventButton(app, sFiles, type)
 
            % Folder to save the file
            folder = app.WorkingFolderPath;
            
            switch type
                case "history"
                    pathToFile = app.createProvenanceFile(sFiles, folder);
                    panel = app.HistoryPanel;
                    
                case "event"
                    pathToFile = app.createJsonEventFile(sFiles, folder);
                    panel = app.EventsDetectedPanel;
            end
            
            for i = 1:length(sFiles)
                 % Create Hyperlink
                 panelChildren = panel.Children;
                 [~, studyName] = app.getStudyNameFromsFile(sFiles{i});

                 link = pathToFile; 
                 button = uibutton(panel, 'ButtonPushedFcn', @(button,event) app.openFileWithButtonPushed(button, link), 'Text', studyName);

                 
                 % Button Size and Position
                 
                 height = 20;
                 positionPanel = panel.Position;
                 if isempty(panelChildren)
                     position = [1 positionPanel(4)-height*2 positionPanel(3)-2 height];
                 else
                     position = panelChildren(1).Position + [0 -height 0 0];
                 end
                 button.Position = position;
            end
        end

        function [TSVpath, JSONpath, PROVpath] = getBIDSpath(app, cFile, BIDSpath)
                            
                       
             % Get date of study and convert (01-JAN-2001 to 20010101)
             date = app.getDateFromBrainstormStudyMAT(cFile);
             dateSplit = split(date, '-');
             months = struct('JAN', '01', 'FEB', '02', 'MAR', '03', 'APR', '04', 'MAY', '05', 'JUN', '06', 'JUL', '07', 'AUG', '08', 'SEP', '09', 'OCT', '10', 'NOV', '11', 'DEC', '12');
             dateContracted = strcat(dateSplit{3}, months.(dateSplit{2}), dateSplit{1});

            
            % Get the number of subject folder (/sub-000X/)
            contentOfBIDSFolder = dir(BIDSpath);
            count = 0;
            for j = 1:length(contentOfBIDSFolder)
                
                if contains(contentOfBIDSFolder(j).name, 'sub-')
                    strSplit = split(contentOfBIDSFolder(j).name, '-');
                    strNum = strSplit{2};
                    strSubNumber=str2double(strNum);
                    
                    if strSubNumber > count
                        count = strSubNumber;
                    end
                end
            end
            
            
            % Build Each Path
            TSVpath = fullfile(BIDSpath, strcat('sub-', sprintf('%04d', count)), strcat('ses-', dateContracted), lower(app.AnalysisType), '/');
            JSONpath = fullfile(BIDSpath, strcat('sub-', sprintf('%04d', count)), strcat('ses-', dateContracted), lower(app.AnalysisType), '/');
            PROVpath = fullfile(BIDSpath, strcat('sub-', sprintf('%04d', count)), strcat('ses-', dateContracted), lower(app.AnalysisType), '/');
            
        end

        function edfFile = exportToEDF(app, cFiles, destination)
            % Convert brut datas in EDF file format and saves it in BIDS
            % folder
            
            % Input>    sFiles [cell]
            %           destination [char]: path indicating where to save EDF file
            
            for i = 1:length(cFiles)

                [~, studyName, ~, ~, channelFile] = app.getStudyNameFromsFile(cFiles(i));
                
                dest = convertStringsToChars(fullfile(destination, strcat(studyName, '.edf')));
                
                [edfFile] = export_data(cFiles{i}, channelFile, dest, []);
            end
        end
        
        function pathToFile = createTsvFile(app, cFiles, destination)
            % Create TSV file that lists the events with time and duration.
            
            % Input>    sFiles [cell]
            %           destination [char]: path indicating where to save the TSV file 
            
            
             % By default, file saved in Working Folder
            if ~exist('destination','var')
                destination = app.WorkingFolderPath;
            end
                 
            % Loop through all studies
            for i = 1:length(cFiles)
                
                % Initialiaze tsvFile
                tsvFile = strings([1,3]);
                count = 1;
                tsvFile(count,:) = ["onset" "duration" "trial type"];
                count = count + 1;
                
                
                studyMATfile=load(cFiles{i});
                eventStruct = studyMATfile.F.events;
            
                % Loop through all event types
                for j = 1:length(eventStruct)
            
                    % Loop through all time (each occurence of an event)
                    for k = 1:length(eventStruct(j).times)
            
                        tsvFile(count,:) = [eventStruct(j).times(k) 0 string((eventStruct(j).label))];
                        count = count + 1;
                    end
                end
            end

            pathToFile = fullfile(destination, strcat(cFiles{i}, '_Events.txt'));
            writematrix(tsvFile, pathToFile, 'Delimiter', 'tab');
  
        end

        function pathToFile = createProvenanceFile(app, cFiles, destination)
            % Create Json file containing information about the study
            % (history of all functions ran on it)
            
            % Input>    sFiles [cell]
            %           destination [char]: path to save JSON file
            
            
            % By default, file saved in Working Folder
            if ~exist('destination','var')
                destination = app.WorkingFolderPath;
            end
            
            % Iterate on all studies
            for i = 1:length(cFiles)
                
                % Load study.mat file
                [~, ~, studyMatFile, ~] = getStudyNameFromsFile(app, cFiles(i));
                studyMatFile = load(studyMatFile);
                
                % Iterate on all event
                for j = 1:height(studyMatFile.History)
                    activity = struct();
                    activity.id = studyMatFile.History{j,2};
                    activity.label = studyMatFile.History{j,3};
                    activity.command = 'button pushed';
                    activity.startedAtTime = studyMatFile.History{j,1}; 
                   
                    provenance.(strcat('ActivityNo', num2str(j))) = activity; 
                end
                
                % Build Provenance File Name
                fileName = strcat(studyName, '_Provenance.json');
            
                % Save as JSON file
                app.saveAsJSON(provenance, fileName)                
                
                % Cannot add path in 'saveAsJSON' arguments. Save by
                % default in current directory. If current directory is not
                % the destination, then copy/paste and delete.
                if string(destination) ~= string(pwd)
                    copyfile(fileName, destination);
                    delete(fileName);
                end
                
                pathToFile = fullfile(destination, filename);
                
            end
        end
        
        function pathToFile = createJsonEventFile(app, sFiles, destination)
            % Create JSON file that describes every event.
            
            % Input>    sFiles [cell]
            %           destination [char]: path indicating where to save the JSON file 

            if ~exist('destination','var')
                destination = app.AppFolderPath;
            end
            
            for i = 1:length(sFiles)

                allEvents = app.getEvents(sFiles(i));

                for j = 1:length(allEvents)
                    new_event = strrep(allEvents{j}, '-', '_');
                    new_event = strrep(new_event, ' ', '_');
                    
                    s__.(new_event) = 'Description';
                end
    
                s_.LongName = 'Event category';
                s_.Description = 'Indicator of type of action that is expected';
                s_.Levels = s__;
    
                s.trial_type = s_;
    
                [~, studyName] = app.getStudyNameFromsFile(sFiles{i});
                fileName = strcat(studyName, '_Event_Metadata.json');
                app.saveAsJSON(s, fileName)
                
                % Copy the json file in a new destination and delete the old one
                if string(destination) ~= string(pwd)
                    copyfile(fileName, destination);
                    delete(fileName);
                end
                
                pathToFile = fullfile(destination, filename);
            end
        end
              
        function [] = switchParent(app, obj, backColor, textColor)
            try 
                enfants = obj.Children;
                cls = class(obj);
                
                switch cls
                    case 'matlab.ui.Figure'
                       obj.Color = backColor; 
                       
                    case 'matlab.ui.container.Panel'
                        obj.BackgroundColor = backColor;
                        obj.ForegroundColor = textColor;
                           
                    case 'matlab.ui.container.CheckBoxTree'
                        obj.BackgroundColor = backColor;
                        obj.FontColor = textColor;
                           
                    case 'matlab.ui.container.TabGroup'
                        
                        %obj.BackgroundColor = backColor;
                        %obj.ForegroundColor = textColor;
                        
                    case 'matlab.ui.container.Tab'
                        obj.BackgroundColor = backColor;
                        %obj.ForegroundColor = textColor;
                           
                end
        
        
                for i = 1:length(enfants)
                    app.switchParent(enfants(i), backColor, textColor);
                end
                
            catch
                
                app.switchChildren(obj, backColor, textColor);
                
            end
        end
    
        function [subjectName, studyName, studyMatFile, brainstormStudyFile, channelFile] = getStudyNameFromsFile(app, cFile)
            Infos = app.getCurrentInformations;
            
            cFileSplit = split(cFile, '/');
            subjectName = cFileSplit{1};
            studyName = cFileSplit{2}(2:end);
            studyMatFileName = cFileSplit{3};
            
            studyFolder = fullfile(app.BsDatabasePath, Infos.ProtocolInfo.Comment, 'data', subjectName, strcat('@', studyName));
            
            studyMatFile = fullfile(studyFolder, studyMatFileName);
            brainstormStudyFile = fullfile(studyFolder, 'brainstormstudy.mat');
            
            % Name of channel file can vary between MEG and EEG
            allFiles = dir(studyFolder);
            for i = 1:length(allFiles)
                if contains(allFiles(i).name, 'channel')
                    channelFile = fullfile(studyFolder, allFiles(i).name);
                    break
                end
            end
        end
    
        function date = getDateFromBrainstormStudyMAT(app, cFile)
            
            [~, ~, ~, brainstormStudyFile] = app.getStudyNameFromsFile(cFile);
            bsStudy = load(brainstormStudyFile);
            date = bsStudy.DateOfStudy;
            date = upper(date);
            
            return
        end
        
        function [] = modifyBrainstormStudyMATDate(app, cFiles, date)
            % Date format: 01-JAN-2001

            [~, ~, ~, brainstormStudyFile] = app.getStudyNameFromsFile(cFiles);
            
            bsStudy = load(brainstormStudyFile);
            bsStudy.DateOfStudy = upper(date);
            save(brainstormStudyFile, '-struct', 'bsStudy');
            
        end
   
        function rawFilesPath = MEGReviewRawFiles(app)
            
            % Select Data or Noise file
                 choice = ["Data Files", "Noise Files", "Cancel"];
                 type = uiconfirm(app.UIFigure, 'Wich type of file do you want to import ?', 'Files to import', 'Options', choice);
                 switch type
                     case choice(1)
                         title = "Select the folder containing your data files";
                         ChannelAlign = 1;
                     case choice(2)
                         title = "Select the folder containing your noise files";
                         ChannelAlign = 0;
                     case choice(3)
                         return
                 end
                
                % Select Dataset 
                folder = app.getFolderPath(title);      
                
                % If only one dataset (folder) selected
                if (folder(end-2:end) == ".ds")
                    rawFilesPath = folder;
                
                % If the folder selected contains multiple dataset
                else
                    % Keep only data files (.ds)
                    FilesName = dir([folder filesep '*.' 'ds']);
                
                    % Saving FileNames in a cell (Path)
                    rawFilesPath = cell(1, length(FilesName));
                    for i=1:length(rawFilesPath)
                        rawFilesPath{i} = fullfile(FilesName(i).folder, FilesName(i).name);
                    end
                end
                
                
                % Building structure
                structure.ReviewRawFiles = struct();
                structure.ReviewRawFiles.SubjectName = subjectName;
                structure.ReviewRawFiles.ChannelAlign = ChannelAlign;
                structure.ReviewRawFiles.FileFormat = app.FileFormat;
                
                
                % Iterate through all selected file to Review
            for j = 1:length(rawFilesPath)

                % Review Raw Files
                structure.ReviewRawFiles.RawFilePath = rawFilesPath(j);
                cFile = Utility_Processes(structure);
                
                
                % Extract Recording Date and modifiy brainstormstudy.mat
                % (DateOfStudy) (Don't know where to find recording date
                % for meg)
                
            end
            
        end
        
        function rawFilesPath = EEGReviewRawFiles(app, subjectName)
            
            % Building File Manager Interface
                title = "Select your EEG data file";
                extensionIn = {'*.eeg'; '*.bin'};       % Extension supported
                
                [rawFilesPath, extension] = app.getFilePath(title, extensionIn);


                % Modify file format based on extension
                switch extension
                    case '.bin'
                        app.FileFormat = 'EEG-DELTAMED';
                        
                    case '.eeg'          
                        % Data Colected with Brainvision
                        app.FileFormat = 'EEG-BRAINAMP';
                        
                    otherwise 
                        return
                end 


                % Building Strucutre
                structure.ReviewRawFiles = struct();
                structure.ReviewRawFiles.SubjectName = subjectName;
                structure.ReviewRawFiles.ChannelAlign = 0;
                structure.ReviewRawFiles.FileFormat = app.FileFormat;
                
                
                % Iterate through all selected file to Review
            for j = 1:length(rawFilesPath)

                % Review Raw Files
                structure.ReviewRawFiles.RawFilePath = rawFilesPath(j);
                cFile = Utility_Processes(structure);
                
                % Get .vmrk file and extract recording date
                vmrkFilePath = strcat(rawFilesPath{j}(1:end-length(extension)), '.vmrk');
                recordingDate = app.getDateFromVMRK(vmrkFilePath);
                
                
                % Modify Date Of Study in brainstormstudy.mat
                app.modifyBrainstormStudyMATDate(cFile, recordingDate);
                
            end
                
        end
    end

    methods(Static)
        
        function allProtocols = getAllProtocols()
            % Return cell of the protocol structure for every protocol
            
            
            allProtocols = {};

            i = 1;
            while true
                bst_set('iProtocol', i);
                protocol = bst_get('ProtocolInfo');
                if isempty(protocol)
                    break
                end
                allProtocols{i} = protocol.Comment;
                i = i + 1;
            end
            
        end
                        
        function openFileWithButtonPushed(~, path)
            open(path)
        end
        
        function [] = switchChildren(obj, backColor, textColor)
            cls = class(obj);

            switch cls
                case 'matlab.ui.control.Button'
                    obj.FontColor = textColor;
                    obj.BackgroundColor = backColor;
        
                case 'matlab.ui.container.Panel'
                    %obj.BackgroundColor = backColor;
                    %obj.ForegroundColor = textColor;
                    %buttonIntab = obj.Children;
                    
                case 'matlab.ui.control.Hyperlink'
                    obj.VisitedColor = textColor;
                    obj.BackgroundColor = backColor;
                    obj.FontColor = textColor;
                            
                case 'matlab.ui.control.Label'
                    obj.BackgroundColor = backColor;
                    obj.FontColor = textColor;
                    
                case 'matlab.ui.control.EditField'
                    
                case 'matlab.ui.control.DropDown'
                    obj.FontColor = textColor;
                    obj.BackgroundColor = backColor;
                    
                case 'matlab.ui.control.CheckBoxTree'
                    obj.FontColor = textColor;
        
            end
        end
        
        function newDate = getDateFromVMRK(vmrkFilePath)
            
            fileid = fopen(vmrkFilePath, 'r');
            for i = 0:10  
                fgetl(fileid);
            end
            
            textline = fgetl(fileid);

            startDate = 24;
            year = textline(startDate:startDate+3);
            month = textline(startDate+4:startDate+5);
            day = textline(startDate+6:startDate+7);
            
            allMonth = {'JAN', 'FEB', 'MAR', 'APR', 'MAY', 'JUN', 'JUL', 'AUG', 'SEP', 'OCT', 'NOV', 'DEC'};
            strMonth = allMonth{str2double(month)};
            
            newDate = strcat(day, '-', strMonth, '-', year);
            
            return
        end
              
        function [] = modifyEDFDate (edfFilePath, date)
            %date format: 01-JAN-2001
            
            date = split(date, '-');
            days = date{1};
            strMonth = date{2};
            year = date{3};
            
            strMonth = upper(strMonth);
            
            monthsToNumber = struct('JAN', '01', 'FEB', '02', 'MAR', '03', 'APR', '04', 'MAY', '05', 'JUN', '06', 'JUL', '07', 'AUG', '08', 'SEP', '09', 'OCT', '10', 'NOV', '11', 'DEC', '12');
            month = monthsToNumber.(strMonth);
            
            hours = '99';
            minutes = '99';
            secondes = '99';
            
            
            % Copy files TXT file
            newFileName = strcat(edfFilePath(1:end-4), '_edf.txt');
            copyfile(edfFilePath, newFileName)
            % Extract line with wrong date from EDF file and replace date (at 2 places) with correct
            % date
            fileid = fopen(newFileName, 'r');
            textline = fgetl(fileid);
            index = strfind(textline, 'Startdate');
            
            % First replacement
            newline = strrep(textline, textline(index+10:index+10+10), strcat(days, '-', strMonth, '-', year));
            
            % Second replacement
            newline = strrep(newline, textline(169:184), strcat(days, '.', month, '.', year(3:4), hours, '.', minutes, '.', secondes));
            
            fclose(fileid);
            
            fileid = fopen(newFileName, 'r+');
            fprintf(fileid, '%c', newline);
            fclose(fileid);
            
            copyfile(newFileName, edfFilePath)
            delete(newFileName);

            return
        end
        
        
        % The next function has been taken here:
        % https://www.mathworks.com/matlabcentral/fileexchange/77284-structure-and-object-to-json?s_tid=srchtitle

        function saveAsJSON(data, jsonFileName)
            % saves the values in the structure 'data' to a file in JSON format.
            % Based on the work of Lior Kirsch at: https://uk.mathworks.com/matlabcentr
            % al/fileexchange/50965-structure-to-json
            %
            % Modification by Arthur Y.C. Liu 24/06/2020
            %
            % Example:
            %     data.name = 'chair';
            %     data.color = 'pink';
            %     data.eye = eye(3);
            %     data.metrics.imageSize = [1920; 1080];
            %     data.metrics.height = 0.3;
            %     data.metrics.width = 1.3;
            %     saveJSONfile(data, 'out.json');
            %
            % Output 'out.json':
            % {
            % 	"name" : "chair",
            % 	"color" : "pink",
            % 	"eye" :
            % 	[
            % 		1,
            % 		0,
            % 		0,
            % 		0,
            % 		1,
            % 		0,
            % 		0,
            % 		0,
            % 		1
            % 	],
            % 	"metrics" :
            % 	{
            % 		"imageSize" :
            % 		[
            % 			1920,
            % 			1080
            % 		],
            % 		"height" : 0.3,
            % 		"width" : 1.3
            % 	}
            % }
            fid = fopen(jsonFileName,'w');
            if isobject(data)
                data = toStruct(data);
            end
            writeElement(fid, data, '', true);
            fprintf(fid,'\n');
            fclose(fid);


            function writeElement(fid, data, tabs, isFirstLine)
                namesOfFields = fieldnames(data);
                numFields = length(namesOfFields);
                tabs = sprintf('%s\t', tabs);
                if nargin == 4
                    if isFirstLine
                        fprintf(fid,'%s{\n%s', tabs(1:end-1), tabs);
                    end
                else
                    fprintf(fid,'\n%s{\n%s', tabs(1:end-1), tabs);
                end
                for i = 1:numFields
                    currentField = namesOfFields{i};
                    currentElementValue = data.(currentField);
                    writeSingleElement(fid, currentField, currentElementValue, tabs);
                    if i == numFields
                        fprintf(fid,'\n%s}',  tabs(1:end-1));
                    else
                        fprintf(fid,',\n%s', tabs);
                    end
                end
            end

            function writeSingleElement(fid, currentField, currentElementValue, tabs)
                % if this is an array/matrix and not a string then iterate on every
                % element, if this is a single element write it
                if ~isstruct(currentElementValue) &&...
                        length(currentElementValue) > 1 && ~ischar(currentElementValue)
                    fprintf(fid,'"%s" : \n%s[\n%s\t',currentField, tabs, tabs);
                    tabs = sprintf('%s\t', tabs);
                    valLength = length(currentElementValue(:));
                    for m = 1:valLength
                        fprintf(fid,'%g' , currentElementValue(m));
                        if m == valLength
                            fprintf(fid,'\n%s]', tabs(1:end-1));
                        else
                            fprintf(fid,',\n%s', tabs);
                        end
                    end
                elseif isstruct(currentElementValue) &&...
                        length(currentElementValue) > 1 && ~ischar(currentElementValue)
                    fprintf(fid,'"%s" : \n%s[',currentField, tabs);
                    tabs = sprintf('%s\t', tabs);
                    valLength = length(currentElementValue(:));
                    for m = 1:valLength
                        writeElement(fid, currentElementValue(m), tabs);
                        if m == valLength
                            fprintf(fid,'\n%s]', tabs(1:end-1));
                        else
                            fprintf(fid,',');
                        end
                    end
                elseif isstruct(currentElementValue)
                    fprintf(fid,'"%s" : ',currentField);
                    writeElement(fid, currentElementValue, tabs);
                elseif isnumeric(currentElementValue) || islogical(currentElementValue)
                    fprintf(fid,'"%s" : %g' , currentField, currentElementValue);
                elseif isempty(currentElementValue)
                    fprintf(fid,'"%s" : "null"' , currentField);
                elseif isobject(currentElementValue)
                    currentElementValue = toStruct(currentElementValue);
                    fprintf(fid,'"%s" : ',currentField);
                    writeElement(fid, currentElementValue, tabs);
                else %ischar or something else ...
                    fprintf(fid,'"%s" : "%s"' , currentField, currentElementValue);
                end
            end

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.UIFigure.WindowState = 'maximized';

            % Input for Type of Analysis
            choice = ["EEG", "MEG"];
            rep = string(uiconfirm(app.UIFigure, 'What type of analysis do you want to conduct ?', 'Type of analysis', 'Options', choice));
            app.switchAnalysisTab(rep);

            
            % Define Working Folder
            title = "Select your Working Folder"; 
            wf = app.getFolderPath(title);
            
            if ~isequal(wf, 0)
                app.WorkingFolderPath = wf;
            else
                % If wf is empty, we keep the default Working Folder.
                uiconfirm(app.UIFigure, strcat("Default Working Folder will be : ", app.WorkingFolderPath), 'Default Working Folder');
            end
            
            app.WorkingFolderPath = fullfile(app.WorkingFolderPath, app.AppFolderName);
            mkdir(app.WorkingFolderPath)
            
            
            % Adding Paths
            addpath(app.ToolPath);        
            addpath(app.BsDatabasePath);
            addpath(app.BsToolboxPath);
            addpath(app.WorkingFolderPath);
            
            % Changing Directory
            cd(app.WorkingFolderPath);
            
            
            % Start Brainstorm without interface
            if ~brainstorm('status')
                brainstorm nogui;
            end
     
        end

        % Button pushed function: dateEDFButton
        function dateEDFButtonPushed(app, event)
            app.modifyEDFDate('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/rawrawb1_notch.edf');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            disp(app.AnalysisType);
            disp(app.AnalysisType == "MEG");
            disp(app.AnalysisType == "EEG");
        end

        % Button pushed function: TESTINGButton
        function TESTINGButtonPushed(app, event)
            
         
            % Convert brut datas in EDF file format and saves it in BIDS
            % folder
            
            % Input>    sFiles [cell]
            %           destination [char]: path indicating where to save EDF file
            
            sFiles = app.selectedStudies;
            
            destination = '/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/';
            
            for i = 1:length(sFiles)

                [subjectName, studyName] = app.getStudyNameFromsFile(sFiles{i});
                channelFile = app.ProtocolPath + "data/" + subjectName + "/@" + studyName + "/";
    
                all_files = dir(channelFile);
    
                for j = 1:length(all_files)
                    if contains(all_files(j).name, 'channel')
                        channelFile = channelFile + all_files(j).name;
                        break
                    end
                end
                dest = convertStringsToChars(fullfile(destination, studyName, '.edf'));
                assignin("base", "dest", dest);
                export_data(sFiles{i}, channelFile, dest, []);
            end
            
        end

        % Button pushed function: eeg_bids_edfButton
        function eeg_bids_edfButtonPushed(app, event)
            % Create BIDS folder and populates it through sub-function
            
            % Input>    sFiles [cell]
            
            %% STEPS to IMPLEMANT: 
            % 1. CONVERT OG STUDY TO EDF
            % 2. REVIEW EDF (CREATES EDF STUDY)
            % 3. CONVERT EDF STUDY TO BIDS
            % 4. CREATE SIDE FILES (EVENT/PROVENANCE) FROM THE OG
            % STUDY(!!!)
            
            
            % Input for BIDS folder
            BIDSfolderName = char(inputdlg('Name of BIDS folder', 'Name of BIDS folder'));
            if isempty(BIDSfolderName)
                return
            end
            BIDSpath = fullfile('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/', BIDSfolderName, '/');
            mkdir(BIDSpath);
            

            % Iterate for every study
            cFiles = app.selectedStudies;
            for i = 1:length(cFiles)
                
                % Export to EDF
                %destination = '/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/'; % Temporaire
                edfFilePath = app.exportToEDF(cFiles(i), app.WorkingFolderPath);
                
                date = app.getDateFromBrainstormStudyMAT(cFiles(i));
                
                app.modifyEDFDate(edfFilePath, date);
       
                
                % Reimport EDF file in Brainstorm 
                    % (it creates a new studythat will later be deleted)
                [subjectName] = app.getStudyNameFromsFile(cFiles(i));
                
                sFilesEDF = bst_process('CallProcess', 'process_import_data_raw', [], [], ...
                                    'subjectname',    subjectName, ...
                                    'datafile',       {edfFilePath, 'EEG-EDF'}, ...
                                    'channelreplace', 0, ...
                                    'channelalign',   0, ...
                                    'evtmode',        'value');


                % Modify date in new brainstormstudy.mat
                cFilesEDF = sFilesEDF.FileName;
                app.modifyBrainstormStudyMATDate(cFilesEDF, date)
                assignin("base", "sFilesEDF", cFilesEDF);


                % Convert EDF study to BIDS
                bst_process('CallProcess', 'process_export_bids', cFilesEDF, [], ...
                     'bidsdir',       {BIDSpath, 'BIDS'}, ...
                     'subscheme',     2, ...  % Number index
                     'sesscheme',     1, ...  % Acquisition date
                     'emptyroom',     'emptyroom, noise', ...
                     'defacemri',     0, ...
                     'overwrite',     0, ...
                     'powerline',     2, ...  % 60 Hz
                     'dewarposition', 'Upright', ...
                     'eegreference',  'Cz', ...
                     'edit',          struct(...
                     'ProjName',    [], ...
                     'ProjID',      [], ...
                     'ProjDesc',    [], ...
                     'Categories',  [], ...
                     'JsonDataset', ['{' 10 '  "License": "PD"' 10 '}'], ...
                     'JsonMeg',     ['{' 10 '  "TaskDescription": "My task"' 10 '}']));


                % Get Path for TSV, JSON and PROVENANCE files.
                [TSVpath, JSONpath, PROVpath] = app.getBIDSpath(cFiles(i), BIDSpath);
                
                
                assignin("base", "PROVpath", PROVpath);
                assignin("base", "EDFpath", EDFpath);
                assignin("base", "BIDSpath", BIDSpath) ;
                assignin("base", "TSVpath", TSVpath) ;
                
                
                % Create .tsv file (Events)
                app.createTsvFile(cFiles(i), TSVpath);
                
                
                % Create Json event description file
                app.createJsonEventFile(cFiles(i), JSONpath);
                
                
                % Create provenance file
                app.createProvenanceFile(cFiles(i), PROVpath);   

                
                % Update Tree and Check new Studies 
                    % (Order is importantsince the 'review raw files' of EDF study 
                    % will add a newstudy that we need to delete.
                app.updateTreeAndCheckNewStudies;
                bst_process('CallProcess', 'process_delete', cFiles{i}, [], ...
                'target', 2); % 1: fichier 2: folder 3: subjects
     
            end
            
            app.updateTree;
        end

        % Button pushed function: meg_bidsButton
        function meg_bidsButtonPushed(app, event)
            % Create BIDS folder and populates it through sub-function
            
            % Input>    sFiles [cell]
            
            % Input for BIDS folder
            BIDSfolderName = char(inputdlg('Name of BIDS folder', 'Name of BIDS folder'));
            if isempty(BIDSfolderName)
                return
            end
            BIDSpath = fullfile('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/', BIDSfolderName, '/');
            mkdir(BIDSpath);
            
            
            % Iterate for every Study
            for i = 1:length(cFiles)


                % Convert to BIDS
                bst_process('CallProcess', 'process_export_bids', cFiles(i), [], ...
                    'bidsdir',       {BIDSpath, 'BIDS'}, ...
                    'subscheme',     2, ...  % Number index
                    'sesscheme',     1, ...  % Acquisition date
                    'emptyroom',     'emptyroom, noise', ...
                    'defacemri',     0, ...
                    'overwrite',     0, ...
                    'powerline',     2, ...  % 60 Hz
                    'dewarposition', 'Upright', ...
                    'eegreference',  'Cz', ...
                    'edit',          struct(...
                    'ProjName',    [], ...
                    'ProjID',      [], ...
                    'ProjDesc',    [], ...
                    'Categories',  [], ...
                    'JsonDataset', ['{' 10 '  "License": "PD"' 10 '}'], ...
                    'JsonMeg',     ['{' 10 '  "TaskDescription": "My task"' 10 '}']));


                % Get Path for TSV, JSON and PROVENANCE files.
                [TSVpath, JSONpath, PROVpath]  = app.getBIDSpath(cFiles(i), BIDSpath);


                % Create .edf file
                app.exportToEDF(cFiles(i), EDFpath);

                
                % Create .tsv file (Events)
                app.createTsvFile(cFiles(i), TSVpath);
                
                
                % Create Json event description file
                app.createJsonEventFile(cFiles(i), JSONpath);
                
                
                % Create provenance file
                app.createProvenanceFile(cFiles(i), PROVpath);      
                
            end
            
            uiconfirm(app.UIFigure, 'BIDS created!', 'Files created');
            app.updateTree;
        end

        % Button pushed function: CreateProtocolButton
        function CreateProtocolButtonPushed(app, event)

            % Input for Protocol Name
            title = 'Create Protocol';
            question = 'Name of the protocol to create: ';
            ProtocolName = char(inputdlg(question, title));

            % Create Protocol
            if ~isempty(ProtocolName)
                gui_brainstorm('CreateProtocol', ProtocolName, 0, 0); % 0/1: Use default anatomy / Use default channel
            end

            % Create Protocol Path
            app.ProtocolPath = fullfile(app.BsDatabasePath, ProtocolName);
        end

        % Button pushed function: DeleteProtocolButton
        function DeleteProtocolButtonPushed(app, event)
            
            % Display all Protocols
            allProtocols = app.getAllProtocols;
            
            index = listdlg('Name', 'Protocol to delete', 'ListString', allProtocols);
            if isempty(index)
                return
            end  
            selection = uiconfirm(app.UIFigure, strcat("You're about to delete the following protocol: ", allProtocols{index}), "Protocol Delete");
            
            % Delete Protocol
            if selection == "OK"
                gui_brainstorm('DeleteProtocol', allProtocols{index});
                uiconfirm(app.UIFigure, "Protocol has been delete", "Protocol Delete");         
            end


            app.updateTree;

        end

        % Button pushed function: LoadProtocolButton
        function LoadProtocolButtonPushed(app, event)

            % Display all Protocol
            allProtocols = app.getAllProtocols;
            
            index = listdlg('Name', 'Choose Protocol to Load', 'ListString', allProtocols);
            if isempty(index)
                return
            end
            protocol_index = bst_get('Protocol', allProtocols{index});

            % Select Protocol
            bst_set('iProtocol', protocol_index);
            
            protocolName = allProtocols{index};
            app.ProtocolPath = fullfile(app.BsDatabasePath, protocolName);
            
           
            app.updateTree
        end

        % Button pushed function: CreateSubjectButton
        function CreateSubjectButtonPushed(app, event)

            % Input for Subject Name
            title = 'Name your subject';
            question = 'Subject Name: ';
            subjectName = char(inputdlg(question, title));

            if isempty(subjectName)
                return
            end
            
            % Input for type of Anatomy
            choice = ["Sepecify anatomy folder", "Use default anatomy", "Cancel"];
            rep = uiconfirm(app.UIFigure, 'Do you have a specific anatomy folder for your subjet or do you want to use the default anatomy ?', ...
                'Create Subject', 'Options', choice);
            
            switch rep
                
                case choice(1)
                    set(app.ProjectElectrodesonScalpButton,'Enable', 'on');
                    title = "Choose the anatomy folder";
                    AnatomyPath = app.getFolderPath(title);

                    if isempty(AnatomyPath)
                        return
                    end
                    
                    structure.ImportAnatomy = struct();
                    structure.ImportAnatomy.SubjectName = subjectName;
                    structure.ImportAnatomy.AnatomyPath = AnatomyPath;
                    structure.ImportAnatomy.AnalysisType = app.AnalysisType;
                    structure.ImportAnatomy.AnatomyFileFormat = app.AnatomyFileFormat;
                    
                    Utility_Processes(structure);

                case choice(2)
                    set(app.ProjectElectrodesonScalpButton,'Enable', 'off');
                    db_add_subject(SubjectName, [], 1, 0);

                case choice(3)
                    return
            end

            app.updateTree;

        end

        % Button pushed function: ReviewRawFilesButton
        function ReviewRawFilesButtonPushed(app, event)

            
            Infos = app.getCurrentInformations;
            
            % List of all Subjects
            liste = cell(1, length(Infos.Subjects.Subject));
            for i = 1:length(Infos.Subjects.Subject)
                liste{i} = Infos.Subjects.Subject(i).Name;
            end
            
            
            % Select Subject associated with the dataset
            index = listdlg('Name', 'Choose the Subject', 'ListString', liste);
            if isempty(index)
                return
            end
            subjectName = liste{index};


            % MEG Review Raw Files
            if app.AnalysisType == "MEG"
                 
                 app.MEGReviewRawFiles(subjectName);
    
            % EEG Review Raw Files
            elseif app.AnalysisType == "EEG"
                
                app.EEGReviewRawFiles(subjectName);

            end

            app.updateTreeAndCheckNewStudies;
        end

        % Button pushed function: NotchFilterButton
        function NotchFilterButtonPushed(app, event)

            sFiles = app.selectedStudies;
            
            % Input for Frequences
            choice = ["I am in North America (60 Hz, 120 Hz, 180 Hz, ...)", "I am in Europe (50 Hz, 100 Hz, 150 Hz, ...)", "Cancel"];
            rep = uiconfirm(app.UIFigure, 'Where has your protocol been conducted ?', 'Notch filter', 'Options', choice);

            switch rep
                case choice(1)
                    frequence = [60 120 180 240];
                case choice(2)
                    frequence = [50 100 150 200];
                case choice(3)
                    return
            end

            % Building Structure
            structure.NotchFilter.ToRun = true;
            structure.NotchFilter.Parameters = frequence;
            
            date = app.getDateFromBrainstormStudyMAT(sFiles);
            
            cFile = Pipeline_Template(structure, sFiles, app.SensorType);
            
%             sFiles = bst_process('CallProcess', 'process_notch', sFiles, [], ...
%                 'sensortypes', app.SensorType, ...
%                 'freqlist',    frequence, ...
%                 'cutoffW',     1, ...
%                 'useold',      0, ...
%                 'read_all',    0);


            app.modifyBrainstormStudyMATDate(cFile, date);
            app.updateTreeAndCheckNewStudies;
            
            
        end

        % Button pushed function: BandpassFilterButton
        function BandpassFilterButtonPushed(app, event)

            sFiles = app.selectedStudies;

            % Input for Frequences
            title = 'Enter the frequencies cut off';
            
            question = 'Lower cut off frequency (Hz): ';
            LowPass = string(inputdlg(question, title));

            question = 'Higher cut off frequency (Hz): ';
            HighPass = string(inputdlg(question, title));

            if isempty(HighPass) || isempty(LowPass)
                uialert(app.UIFigure, "Please enter an upper and lower frequency cutoff points.", 'Error')
                return
            end
            
            % Building Structure
            structure.BandPassFilter.ToRun = true;
            structure.BandPassFilter.Parameters = [str2double(LowPass) str2double(HighPass)];
            
            Pipeline_Template(structure, sFiles, app.SensorType);
            
%             bst_process('CallProcess', 'process_bandpass', sFiles, [], ...
%                 'sensortypes', app.SensorType, ...
%                 'highpass',    str2double(LowPass), ...
%                 'lowpass',     str2double(HighPass), ...
%                 'tranband',    0, ...
%                 'attenuation', 'strict', ...  % 60dB
%                 'ver',         '2019', ...  % 2019
%                 'mirror',      0, ...
%                 'read_all',    0);

            app.updateTreeAndCheckNewStudies;         
            
        end

        % Button pushed function: PowerSpectrumDensityButton
        function PowerSpectrumDensityButtonPushed(app, event)

            sFiles = app.selectedStudies;
            
            % Select Window Length
            if app.AnalysisType == "EEG"
                winLength = 10;
            elseif app.AnalysisType == "MEG"
                winLength = 4;
            end
            
            
            % Building Structure
            structure.PowerSpectrumDensity.ToRun = true;
            structure.PowerSpectrumDensity.WindowLength = winLength;
            
            Pipeline_Template(structure, sFiles, app.SensorType);

%             bst_process('CallProcess', 'process_psd', sFiles, [], ...
%                 'timewindow',  [], ...
%                 'win_length',  winLength, ...
%                 'win_overlap', 50, ...
%                 'clusters',    {}, ...
%                 'sensortypes', app.SensorType, ...
%                 'edit', struct(...
%                 'Comment',    'Power', ...
%                 'TimeBands',  [], ...
%                 'Freqs',      [], ...
%                 'ClusterFuncTime', 'none', ...
%                 'Measure',    'power', ...
%                 'Output',     'all', ...
%                 'SaveKernel', 0));
            
            app.updateTree;
            
        end

        % Button pushed function: ConvertEpochstoContinueButton
        function ConvertEpochstoContinueButtonPushed(app, event)

            sFiles = app.selectedStudies;
            
            % Building Structure
            structure.ConvertEpochToContinue.ToRun = true;
            
            Pipeline_Template(structure, sFiles, app.SensorType)

%             bst_process('CallProcess', 'process_ctf_convert', sFiles, [], ...
%                 'rectype', 2);  % 2: To Continue
        end

        % Button pushed function: DetectArtifactButton
        function DetectArtifactButtonPushed(app, event)
 % A REVOIR ONLY ONE STEP TO CHOOSE
            
            
            cFiles = app.selectedStudies;
            
            % Input for type of Artifact
            %choice = ["Cardiac", "Blink", "Low Frequency Artifact (eye/teeth movement) ?", ...
                %"High Frequency Artifact (muscle movement/sensor artifacts) ?", "Cancel"];
             
            choice = ["Cardiac", "Blink", "Low Frequency Artifact (eye/teeth movement)", "High Frequency Artifact (muscle movement/sensor artifacts)"];
            
            rep = uiconfirm(app.UIFigure, 'What to detect?', ...
                'Artifact detection', 'Options', choice);
            
            structure = struct();
            switch rep
                case choice(1)
                    structure.DetectArtifact.Cardiac = true;
                    structure.DetectArtifact.Channel = 'ECG';
                    structure.DetectArtifact.EventName = 'cardiac';
                    
                case choice(2)
                    structure.DetectArtifact.Blink = true;
                    structure.DetectArtifact.Channel = 'VEOG';
                    structure.DetectArtifact.EventName = 'blink';
                    
                case choice(3)
                    choice2 = ["Low Frequency Artifact (eye/teeth movement)", "High Frequency Artifact (muscle movement/sensor artifacts)", "Both"];
                    rep2 = uiconfirm(app.UIFigure, 'What to detect?', ...
                                'Artifact detection', 'Options', choice);
                    
                    switch rep2
                        
                        case choice2(1)
                            structure.DetectArtifact.Other = true;
                            structure.DetectArtifact.LowFrequence = 1;
                            structure.DetectArtifact.HighFrequence = 0;
                            
                        case choice2(2)
                            structure.DetectArtifact.Other = true;
                             structure.DetectArtifact.LowFrequence = 0;
                            structure.DetectArtifact.HighFrequence = 1;
                            
                        case choice2(3)
                            structure.DetectArtifact.Other = true;
                            structure.DetectArtifact.LowFrequence = 1;
                            structure.DetectArtifact.HighFrequence = 1;
                    end
                    
               otherwise
                    return
            end

            MEG_PIPELINE(structure, cFiles, app.SensorType)
            %app.detectArtifact(cFiles, type, channel, name);
        end

        % Button pushed function: RenameEventsButton
        function RenameEventsButtonPushed(app, event)

            sFiles = app.selectedStudies;

            % Display all Events
            choice = app.getEvents(sFiles);

            index = listdlg('ListString', choice, 'PromptString', 'Rename?');
            if length(index) ~= 1
                waitfor(errordlg("Choose one and only one event to rename."))
                return
            end
            oldName = choice{index};

            % Input for New Name
            title = 'Nouveau nom d''évenement';
            question = 'Nouveau nom ?';
            newName = char(inputdlg(question, title));

            if isempty(newName)
                waitfor(errordlg("Please enter a new event name."))
                return
            end
            
            % Changing Name
            bst_process('CallProcess', 'process_evt_rename', sFiles, [], ...
                'src',  oldName, ...
                'dest', newName);
        end

        % Button pushed function: DeleteEventsButton
        function DeleteEventsButtonPushed(app, event)
            sFiles = app.selectedStudies;

            % Display all Events
            choice = app.getEvents(sFiles);

            index = listdlg('ListString', choice, 'PromptString', 'delete?');

            if isempty(index)
                msgbox("Missing event to delete.")
                return
            end

            % Deleting Events
            for i = 1:length(index)

                bst_process('CallProcess', 'process_evt_delete', sFiles, [], ...
                    'eventname', choice{index(i)});
            end
        end

        % Button pushed function: RemoveSimultaneousEventsButton
        function RemoveSimultaneousEventsButtonPushed(app, event)
            sFiles = app.selectedStudies;

            % Display all Events
            choice = app.getEvents(sFiles);

            indexR = listdlg('ListString', choice, 'PromptString', 'Remove the event named: ');
            indexT = listdlg('ListString', choice, 'PromptString', 'When it is too close to the event: ');

            if length(indexR) > 1 || length(indexT) > 1
                waitfor(errordlg("Choose only one event."))
                return
            elseif isempty(indexR) || isempty(indexT)
                waitfor(errordlg("Missing event to remove or to targert."))
                return
            end

            remove = choice{indexR};
            target = choice{indexT};

            % Remove Simultaneaous Events
            bst_process('CallProcess', 'process_evt_remove_simult', sFiles, [], ...
                'remove', remove, ...
                'target', target, ...
                'dt',     0.25, ...
                'rename', 0);
            
        end

        % Button pushed function: SSPButton
        function SSPButtonPushed(app, event)
            sFiles = app.selectedStudies;

            % Display all Events
            choice = app.getEvents(sFiles);

            index = listdlg('ListString', choice, 'PromptString', 'Which event do you want to use to compute the SSP ?:');
            
            if length(index) > 1
                waitfor(errordlg("Choose only one event."))
                return
            elseif isempty(index)
                waitfor(errordlg("Missing event to remove or to targert."))
                return
            end

            eventName = choice{index};
            
            
            % Building Structure
            structure.SSP.ToRun = true;
            structure.SSP.EventName = eventName;
            
            Pipeline_Template(structure, sFiles, app.SensorType);

%             bst_process('CallProcess', 'process_ssp_ecg', sFiles, [], ...
%                 'eventname',   eventName, ...
%                 'sensortypes', app.SensorType, ...
%                 'usessp',      1, ...
%                 'select',      1);
% 
%             for i = 1:length(sFiles)
%                 view_timeseries(sFiles{i});
%                 panel_ssp_selection('OpenRaw');
%                 waitfor(errordlg("Click when you are done choosing"));
%             end
            
        end

        % Button pushed function: AddEEGPositionsButton
        function AddEEGPositionsButtonPushed(app, event)
            cFiles = app.selectedStudies;

            % Input for either Default Pattern or Import File
            choice = ["Use default pattern", "Import a file ", "Cancel"];
            rep = uiconfirm(app.UIFigure, 'Import a file or use a default ?', ...
                'EEG position', 'Options', choice);
         
            
            structure.AddEEGPosition.ToRun = true;
            
            switch rep
                
                case choice(1)
                    
                    % List of available cap
                    capTypes = {'Colin27: BrainProducts EasyCap 128'};
                    capNumber = {22};
                    
                    index = listdlg('Name', 'Choose your Cap!', 'ListString', capTypes, 'ListSize', [350, 150]); 
                    if isempty(index)
                        return
                    end
                    
                    % Selecting Cap
                    capNumber = capNumber{index};
                    
                    % Building Structure
                    structure.AddEEGPosition.FileOrigin = 'Default';
                    structure.AddEEGPosition.capNumber = capNumber{index};
                    
                    
                case choice(2)
                    
                    title = "Select the electrode file (it should have the *.elc extension)";
                    extension = {'*.elc'};
                    [file, ~] = app.getFilePath(title, extension);
                    
                    % Building Structure
                    structure.AddEEGPosition.FileOrigin = 'Import';
                    structure.AddEEGPosition.ChannelFile = file;
            end

                    
            Pipeline_Template(structure, cFiles, 'EEG');
        end

        % Button pushed function: RefineRegistrationButton
        function RefineRegistrationButtonPushed(app, event)
            cFiles = app.selectedStudies;

            structure.RefineRegistration.ToRun = true;
            Pipeline_Template(structure, cFiles, app.SensorType);
        end

        % Button pushed function: ProjectElectrodesonScalpButton
        function ProjectElectrodesonScalpButtonPushed(app, event)

            cFiles = app.selectedStudies;
            structure.ProjectElectrodeOnScalp.ToRun = true;
            Pipeline_Template(structure, cFiles, app.SensorType);
        end

        % Button pushed function: DetectHeartbeatsButton
        function DetectHeartbeatsButtonPushed(app, event)

            cFiles = app.selectedStudies;

            
            structure.DetectArtifact.Cardiac = true;
            structure.DetectArtifact.Channel = 'ECG';
            structure.DetectArtifact.EventName = 'cardiac';
            
            Pipeline_Template(structure, cFiles, app.SensorType)


            %app.detectArtifact(cFiles, type, channel, name);
        end

        % Button pushed function: ImportEventsButton
        function ImportEventsButtonPushed(app, event)

            cFiles = app.selectedStudies;

            title = "Select the event file (it should have the *.txt extension)";
            extension = {'*.txt'};
            
            [filePath, ~] = app.getFilePath(title, extension);
            
            title = 'Name the event';
            question = 'Name of the imported events:';
            newName = char(inputdlg(question, title));

            bst_process('CallProcess', 'process_evt_import', cFiles, [], ...
                'evtfile', {filePath, 'ARRAY-TIMES'}, ...
                'evtname', newName);
        end

        % Button pushed function: AverageReferenceButton
        function AverageReferenceButtonPushed(app, event)

            cFiles = app.selectedStudies;

            structure.AverageReference.ToRun = true;
            
            Pipeline_Template(structure, cFiles, app.SensorType);
 
        end

        % Button pushed function: ICAEEGButton
        function ICAEEGButtonPushed(app, event)

            display(event.Source);
            
            
            cFiles = app.selectedStudies;

            % Input for number of components
            title = 'ICA components';
            question = 'How many ICA components (nb electrode / 2): ';
            ICAcomponents = inputdlg(question, title);

            if isempty(ICAcomponents)
                return
            end

            
            % Building Structure
            structure.ICA.ToRun = true;
            structure.ICA.NumberOfComponents = str2double(ICAcomponents{1});
            
            Pipeline_Template(structure, cFiles, 'EEG');
        end

        % Button pushed function: ICAMEGButton
        function ICAMEGButtonPushed(app, event)
            
            app.ICAEEGButtonPushed();
        end

        % Selection change function: TabGroup1
        function TabGroup1SelectionChanged(app, event)
            app.switchAnalysisTab("TabGroup1");
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            app.switchAnalysisTab("TabGroup2");
        end

        % Value changed function: TypeofanalysisDropDown
        function TypeofanalysisDropDownValueChanged(app, event)
            app.switchAnalysisTab("DropDown");
        end

        % Button pushed function: OpenBrainstormButton
        function OpenBrainstormButtonPushed(app, event)
            brainstorm;
        end

        % Button pushed function: SelectDeselectallButton
        function SelectDeselectallButtonPushed(app, event)
            app.selectAllStudies;
        end

        % Button pushed function: DeleteStudySubjectButton
        function DeleteStudySubjectButtonPushed(app, event)
            cFiles = app.selectedStudies;

            % Choix des types de données a importer.
            choice = ["Delete Folder (recommended)", "Delete File", "Delete Subject", "Cancel"];
            type = uiconfirm(app.UIFigure, "Which file do you want to delete ?", "Files to delete", 'Options', choice);

            switch type
                case choice(1)
                    del = 2;
                case choice(2)
                    del = 1;
                case choice(3)
                    del = 3;
                case choice(4)
                    return
            end

            for i = 1:length(cFiles)
                bst_process('CallProcess', 'process_delete', cFiles(i), [], ...
                'target', del); % 1: fichier 2: folder 3: subjects
            end
            
            
            app.updateTree;
        end

        % Button pushed function: UpdateTreeButton
        function UpdateTreeButtonPushed(app, event)
            app.updateTree;
        end

        % Button pushed function: UpdateEventsButton
        function UpdateEventsButtonPushed(app, event)
            sFiles = app.selectedStudies;
            delete(app.EventsDetectedPanel.Children());
            app.createHistoryEventButton(sFiles, 'event')
        end

        % Button pushed function: UpdateHistoryButton
        function UpdateHistoryButtonPushed(app, event)
            sFiles = app.selectedStudies();
            delete(app.HistoryPanel.Children());
            app.createHistoryEventButton(sFiles, 'history')
        end

        % Button pushed function: AddTagButton
        function AddTagButtonPushed(app, event)
            sFiles = app.selectedStudies;

            title = 'Add tag';
            question = 'Tag name to add: ';
            Tag = char(inputdlg(question, title));
            
            if isempty(Tag)
                return
            end

            choice = ["Add Tag to the file Name", "Add tag to the file path", "Cancel"];

            rep = uiconfirm(app.UIFigure, 'where do you want to add the tag ?', ...
                'Add Tag', 'Options', choice);

            switch rep
                case choice(1)
                    bst_process('CallProcess', 'process_add_tag', sFiles, [], ...
                        'tag',           Tag, ...
                        'output',        1);

                case choice(2)
                    bst_process('CallProcess', 'process_add_tag', sFiles, [], ...
                        'tag',           Tag, ...
                        'output',        2);
                    
                case choice(3)
                    return
            end
        end

        % Callback function
        function ConverttoBIDSButtonPushed(app, event)
            sFiles = app.selectedStudies;
            app.convertToBIDS(sFiles);
        end

        % Callback function
        function CreateEventFiletsvButtonPushed(app, event)
            sFiles = app.selectedStudies;
            app.createTsvFile(sFiles);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            rep = uiconfirm(app.UIFigure, 'Do you want to close the app ?', 'Close request');

            if rep == "OK"
                delete(app)
            end

        end

        % Button pushed function: ColorMode
        function ColorModePushed(app, event)
           
            if app.ColorMode.Text == "Dark Mode"
                backColor = [0 0 0];
                textColor = [1 1 1];
                app.ColorMode.Text = "Bright Mode";
            elseif app.ColorMode.Text == "Bright Mode"
                backColor = [0.94 0.94 0.94];
                textColor = [0 0 0];
                app.ColorMode.Text = "Dark Mode";
            end

            app.switchParent(app.UIFigure, backColor, textColor);
            
        end

        % Button pushed function: OpenPIPBUILDButton
        function OpenPIPBUILDButtonPushed(app, event)
            % Open Pipeline Builder
            run('Pipeline_Builder.mlapp');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.UpdateTreeButton = uibutton(app.UIFigure, 'push');
            app.UpdateTreeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTreeButtonPushed, true);
            app.UpdateTreeButton.Text = 'Update Tree';
            app.CurrentProtocolEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentProtocolEditField.Tag = 'CurrentProto';
            app.CurrentProtocolEditField.Editable = 'off';
            app.CurrentProtocolLabel = uilabel(app.UIFigure);
            app.CurrentProtocolLabel.Text = 'Current Protocol:';
            app.CurrentSubjectEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentSubjectEditField.Tag = 'CurrentSubject';
            app.CurrentSubjectEditField.Editable = 'off';
            app.CurrentSubjectLabel = uilabel(app.UIFigure);
            app.CurrentSubjectLabel.Text = 'Current Subject:';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.EEGTabGr2 = uitab(app.TabGroup2);
            app.EEGTabGr2.Title = 'EEG';
            app.AverageReferenceButton = uibutton(app.EEGTabGr2, 'push');
            app.AverageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AverageReferenceButtonPushed, true);
            app.AverageReferenceButton.Text = 'Average Reference';
            app.ICAEEGButton = uibutton(app.EEGTabGr2, 'push');
            app.ICAEEGButton.ButtonPushedFcn = createCallbackFcn(app, @ICAEEGButtonPushed, true);
            app.ICAEEGButton.Text = 'ICA';
            app.MEGTabGr2 = uitab(app.TabGroup2);
            app.MEGTabGr2.Title = 'MEG';
            app.RenameEventsButton = uibutton(app.MEGTabGr2, 'push');
            app.RenameEventsButton.ButtonPushedFcn = createCallbackFcn(app, @RenameEventsButtonPushed, true);
            app.RenameEventsButton.Text = 'Rename Events';
            app.DeleteEventsButton = uibutton(app.MEGTabGr2, 'push');
            app.DeleteEventsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEventsButtonPushed, true);
            app.DeleteEventsButton.Text = 'Delete Events';
            app.RemoveSimultaneousEventsButton = uibutton(app.MEGTabGr2, 'push');
            app.RemoveSimultaneousEventsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSimultaneousEventsButtonPushed, true);
            app.RemoveSimultaneousEventsButton.Text = 'Remove Simultaneous Events';
            app.DetectArtifactButton = uibutton(app.MEGTabGr2, 'push');
            app.DetectArtifactButton.ButtonPushedFcn = createCallbackFcn(app, @DetectArtifactButtonPushed, true);
            app.DetectArtifactButton.Text = 'Detect Artifact';
            app.SSPButton = uibutton(app.MEGTabGr2, 'push');
            app.SSPButton.ButtonPushedFcn = createCallbackFcn(app, @SSPButtonPushed, true);
            app.SSPButton.Text = 'SSP';
            app.ICAMEGButton = uibutton(app.MEGTabGr2, 'push');
            app.ICAMEGButton.ButtonPushedFcn = createCallbackFcn(app, @ICAMEGButtonPushed, true);
            app.ICAMEGButton.Text = 'ICA';
            app.SelectDeselectallButton = uibutton(app.UIFigure, 'push');
            app.SelectDeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDeselectallButtonPushed, true);
            app.SelectDeselectallButton.Text = 'Select/Deselect all';
            app.TabGroup1 = uitabgroup(app.UIFigure);
            app.TabGroup1.SelectionChangedFcn = createCallbackFcn(app, @TabGroup1SelectionChanged, true);
            app.EEGTabGr1 = uitab(app.TabGroup1);
            app.EEGTabGr1.Title = 'EEG';
            app.AddEEGPositionsButton = uibutton(app.EEGTabGr1, 'push');
            app.AddEEGPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @AddEEGPositionsButtonPushed, true);
            app.AddEEGPositionsButton.Text = 'Add EEG Positions';
            app.RefineRegistrationButton = uibutton(app.EEGTabGr1, 'push');
            app.RefineRegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RefineRegistrationButtonPushed, true);
            app.RefineRegistrationButton.Text = 'Refine Registration';
            app.ProjectElectrodesonScalpButton = uibutton(app.EEGTabGr1, 'push');
            app.ProjectElectrodesonScalpButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectElectrodesonScalpButtonPushed, true);
            app.ProjectElectrodesonScalpButton.Text = 'Project Electrodes on Scalp';
            app.DetectHeartbeatsButton = uibutton(app.EEGTabGr1, 'push');
            app.DetectHeartbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectHeartbeatsButtonPushed, true);
            app.DetectHeartbeatsButton.Text = 'Detect Heartbeats';
            app.ImportEventsButton = uibutton(app.EEGTabGr1, 'push');
            app.ImportEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEventsButtonPushed, true);
            app.ImportEventsButton.Text = 'Import Events';
            app.MEGTabGr1 = uitab(app.TabGroup1);
            app.MEGTabGr1.Title = 'MEG';
            app.ConvertEpochstoContinueButton = uibutton(app.MEGTabGr1, 'push');
            app.ConvertEpochstoContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertEpochstoContinueButtonPushed, true);
            app.ConvertEpochstoContinueButton.Text = 'Convert Epochs to Continue';
            app.FrequencyAnalysisPanel = uipanel(app.UIFigure);
            app.FrequencyAnalysisPanel.Title = 'Frequency Analysis';
            app.NotchFilterButton = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.NotchFilterButton.ButtonPushedFcn = createCallbackFcn(app, @NotchFilterButtonPushed, true);
            app.NotchFilterButton.Text = 'Notch Filter';
            app.BandpassFilterButton = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.BandpassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BandpassFilterButtonPushed, true);
            app.BandpassFilterButton.Text = 'Band-pass Filter';
            app.PowerSpectrumDensityButton = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.PowerSpectrumDensityButton.ButtonPushedFcn = createCallbackFcn(app, @PowerSpectrumDensityButtonPushed, true);
            app.PowerSpectrumDensityButton.Text = 'Power Spectrum Density';
            app.Panel = uipanel(app.UIFigure);
            app.OpenBrainstormButton = uibutton(app.Panel, 'push');
            app.OpenBrainstormButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBrainstormButtonPushed, true);
            app.OpenBrainstormButton.Text = 'Open Brainstorm ';
            app.TypeofanalysisDropDown = uidropdown(app.Panel);
            app.TypeofanalysisDropDown.Items = {'EEG', 'MEG'};
            app.TypeofanalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofanalysisDropDownValueChanged, true);
            app.TypeofanalysisDropDown.Tag = 'FormatDeFichier';
            app.TypeofanalysisDropDown.Value = 'MEG';
            app.TypeofanalysisDropDownLabel = uilabel(app.Panel);
            app.TypeofanalysisDropDownLabel.Text = 'Type of analysis';
            app.AddTagButton = uibutton(app.Panel, 'push');
            app.AddTagButton.ButtonPushedFcn = createCallbackFcn(app, @AddTagButtonPushed, true);
            app.AddTagButton.Text = 'Add Tag';
            app.UpdateEventsButton = uibutton(app.Panel, 'push');
            app.UpdateEventsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEventsButtonPushed, true);
            app.UpdateEventsButton.Text = 'Update Events';
            app.UpdateHistoryButton = uibutton(app.Panel, 'push');
            app.UpdateHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateHistoryButtonPushed, true);
            app.UpdateHistoryButton.Text = 'Update History';
            app.ImportinDataBaseButton = uibutton(app.Panel, 'push');
            app.ImportinDataBaseButton.Text = 'Import in DataBase';
            app.ColorMode = uibutton(app.Panel, 'push');
            app.ColorMode.ButtonPushedFcn = createCallbackFcn(app, @ColorModePushed, true);
            app.ColorMode.Text = 'Dark Mode';
            app.eeg_bids_edfButton = uibutton(app.Panel, 'push');
            app.eeg_bids_edfButton.ButtonPushedFcn = createCallbackFcn(app, @eeg_bids_edfButtonPushed, true);
            app.eeg_bids_edfButton.Text = 'eeg_bids_edf';
            app.meg_bidsButton = uibutton(app.Panel, 'push');
            app.meg_bidsButton.ButtonPushedFcn = createCallbackFcn(app, @meg_bidsButtonPushed, true);
            app.meg_bidsButton.Text = 'meg_bids';
            app.SubjectStudyPanel = uipanel(app.UIFigure);
            app.SubjectStudyPanel.Title = 'Subject/Study';
            app.CreateSubjectButton = uibutton(app.SubjectStudyPanel, 'push');
            app.CreateSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSubjectButtonPushed, true);
            app.CreateSubjectButton.Text = 'Create Subject';
            app.DeleteStudySubjectButton = uibutton(app.SubjectStudyPanel, 'push');
            app.DeleteStudySubjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteStudySubjectButtonPushed, true);
            app.DeleteStudySubjectButton.Text = 'Delete Study/Subject';
            app.ReviewRawFilesButton = uibutton(app.SubjectStudyPanel, 'push');
            app.ReviewRawFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewRawFilesButtonPushed, true);
            app.ReviewRawFilesButton.Text = 'Review Raw Files';
            app.ProtocolPanel = uipanel(app.UIFigure);
            app.ProtocolPanel.Title = 'Protocol';
            app.CreateProtocolButton = uibutton(app.ProtocolPanel, 'push');
            app.CreateProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @CreateProtocolButtonPushed, true);
            app.CreateProtocolButton.Text = 'Create Protocol';
            app.LoadProtocolButton = uibutton(app.ProtocolPanel, 'push');
            app.LoadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProtocolButtonPushed, true);
            app.LoadProtocolButton.Text = 'Load Protocol';
            app.DeleteProtocolButton = uibutton(app.ProtocolPanel, 'push');
            app.DeleteProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProtocolButtonPushed, true);
            app.DeleteProtocolButton.Text = 'Delete Protocol';
            app.EEGHyperlink = uihyperlink(app.UIFigure);
            app.EEGHyperlink.URL = 'https://neuroimage.usc.edu/brainstorm/Tutorials/Epilepsy';
            app.EEGHyperlink.Text = 'Brainstorm EEG Analysis Tutorial';
            app.MEGHyperlink = uihyperlink(app.UIFigure);
            app.MEGHyperlink.URL = 'https://neuroimage.usc.edu/brainstorm/Tutorials';
            app.MEGHyperlink.Text = 'Brainstorm MEG Analysis Tutorial';
            app.HistoryPanel = uipanel(app.UIFigure);
            app.HistoryPanel.Title = 'History';
            app.EventsDetectedPanel = uipanel(app.UIFigure);
            app.EventsDetectedPanel.Title = 'Events Detected';
            app.TESTINGButton = uibutton(app.UIFigure, 'push');
            app.TESTINGButton.ButtonPushedFcn = createCallbackFcn(app, @TESTINGButtonPushed, true);
            app.TESTINGButton.Text = 'TESTING';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://github.com/CorentinLabelle/EEG-MEG-Analysis-Tool';
            app.Hyperlink.Text = 'GitHub';
            app.OpenPIPBUILDButton = uibutton(app.UIFigure, 'push');
            app.OpenPIPBUILDButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPIPBUILDButtonPushed, true);
            app.OpenPIPBUILDButton.Text = 'Open PIPBUILD';
            app.dateEDFButton = uibutton(app.UIFigure, 'push');
            app.dateEDFButton.ButtonPushedFcn = createCallbackFcn(app, @dateEDFButtonPushed, true);
            app.dateEDFButton.Text = 'date EDF';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_MEG_ANALYSIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end