classdef Pipeline_Builder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BIDSPanel                       matlab.ui.container.Panel
        BrowseBIDSButton                matlab.ui.control.Button
        BIDSFolderPathEditField         matlab.ui.control.EditField
        BIDSFolderPathEditFieldLabel    matlab.ui.control.Label
        BIDSFolderNameEditField         matlab.ui.control.EditField
        BIDSFolderNameEditFieldLabel    matlab.ui.control.Label
        PipelinePanel                   matlab.ui.container.Panel
        ClearPipelineButton             matlab.ui.control.Button
        ImportPipelineButton            matlab.ui.control.Button
        SavePipelineButton              matlab.ui.control.Button
        RemoveSimultaneousEventPanel    matlab.ui.container.Panel
        TargetEventEditField            matlab.ui.control.EditField
        TargetEventEditFieldLabel       matlab.ui.control.Label
        EventtoRemoveEditField          matlab.ui.control.EditField
        EventtoRemoveEditFieldLabel     matlab.ui.control.Label
        ChooseCapButtonGroup            matlab.ui.container.ButtonGroup
        Colin27BrainProductsEsayCap64Button  matlab.ui.control.RadioButton
        Colin27BrainProductsEasyCap128Button  matlab.ui.control.RadioButton
        TypeofArtifactPanel             matlab.ui.container.Panel
        HighFrequencymusclesensorCheckBox  matlab.ui.control.CheckBox
        LowFrequencyeyeteethCheckBox    matlab.ui.control.CheckBox
        BlinkCheckBox                   matlab.ui.control.CheckBox
        CardiacCheckBox                 matlab.ui.control.CheckBox
        EventNameSSPEditField           matlab.ui.control.EditField
        EventNameSSPEditFieldLabel      matlab.ui.control.Label
        MEGProcessesPanel               matlab.ui.container.Panel
        ConverttoBIDSMEGCheckBox        matlab.ui.control.CheckBox
        ICAMEGCheckBox                  matlab.ui.control.CheckBox
        RemoveSimultaneousEventCheckBox  matlab.ui.control.CheckBox
        SSPCheckBox                     matlab.ui.control.CheckBox
        DetectArtifactCheckBox          matlab.ui.control.CheckBox
        PowerSpectrumDensityMEGCheckBox  matlab.ui.control.CheckBox
        BandPassFilterMEGCheckBox       matlab.ui.control.CheckBox
        ConvertEpochtoContinueCheckBox  matlab.ui.control.CheckBox
        NotchFilterMEGCheckBox          matlab.ui.control.CheckBox
        EEGProcessesPanel               matlab.ui.container.Panel
        ConverttoBIDSEEGCheckBox        matlab.ui.control.CheckBox
        ICAEEGCheckBox                  matlab.ui.control.CheckBox
        AverageReferenceCheckBox        matlab.ui.control.CheckBox
        PowerSpectrumDensityEEGCheckBox  matlab.ui.control.CheckBox
        BandPassFilterEEGCheckBox       matlab.ui.control.CheckBox
        NotchFilterEEGCheckBox          matlab.ui.control.CheckBox
        ProjectElectrodeonScalpCheckBox  matlab.ui.control.CheckBox
        RefineRegistrationCheckBox      matlab.ui.control.CheckBox
        AddEEGPositionCheckBox          matlab.ui.control.CheckBox
        NumberofComponentsICAEditField  matlab.ui.control.NumericEditField
        NumberofComponentsICAEditFieldLabel  matlab.ui.control.Label
        PipelineTypeDropDown            matlab.ui.control.DropDown
        PipelineTypeDropDownLabel       matlab.ui.control.Label
        AddEEGPositionGroup             matlab.ui.container.ButtonGroup
        ImportFileButton                matlab.ui.control.RadioButton
        UseDefaultPatternButton         matlab.ui.control.RadioButton
        BandPassFrequencesPanel         matlab.ui.container.Panel
        HighCutOffHzEditField           matlab.ui.control.NumericEditField
        HighCutOffHzEditFieldLabel      matlab.ui.control.Label
        LowCutOffHzEditField            matlab.ui.control.NumericEditField
        LowCutOffHzEditFieldLabel       matlab.ui.control.Label
        NotchGroup                      matlab.ui.container.ButtonGroup
        Frequence                       matlab.ui.control.TextArea
        SpecificFrequenceButton         matlab.ui.control.RadioButton
        EuropeButton                    matlab.ui.control.RadioButton
        NorthAmericaButton              matlab.ui.control.RadioButton
        BrowsePipelineButton            matlab.ui.control.Button
        SaveinFolderEditField           matlab.ui.control.EditField
        SaveinFolderEditFieldLabel      matlab.ui.control.Label
        PipelineNameEditField           matlab.ui.control.EditField
        PipelineNameEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Description
        
        Path = '/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/';
        
        Pipeline = struct();
        
        AddEEGPosition = struct();
        RefineRegistration = struct();
        ProjectElectrodeOnScalp = struct();
        NotchFilter = struct();
        BandPassFilter = struct();
        PowerSpectrumDensity = struct();
        AverageReference = struct();
        ICA = struct();
    end
    
    methods (Access = private)
        
        function param = getNotchFilterParameters(app)
            
            % Get Notch Filter Parameters
            switch app.NotchGroup.SelectedObject
                case app.EuropeButton
                    param = [50, 100, 150, 200];
                    
                case app.NorthAmericaButton
                    param = [60, 120, 180, 240];
                    
                case app.SpecificFrequenceButton
                    % Assuming frequences are separated by commas.
                    frequences = split(app.Frequence.Value, ',');
                    nbFrequence = length(frequences);
                     
                    param = zeros(1, nbFrequence);
                    for i = 1:nbFrequence
                        param(i) = string(frequences{i});
                    end

                    return
            end
            
        end
                
        function processStructure = getEEGPipelineProcesses(app)
            
            processStructure = struct();
            
            if (app.RefineRegistrationCheckBox.Value)
                processStructure.RefineRegistration.ToRun = true;
            end
            
            if (app.ProjectElectrodeonScalpCheckBox.Value)
                processStructure.ProjectElectrodeOnScalp.ToRun = true;
            end
            
            if (app.AddEEGPositionCheckBox.Value)
                processStructure.AddEEGPosition.ToRun = true;
                processStructure.AddEEGPosition.FileType = app.AddEEGPositionGroup.SelectedObject.Text;
                
                % Get EEG Position Cap and CapNumber
                switch app.ChooseCapButtonGroup.SelectedObject.Text
                    case "Colin27: BrainProducts EasyCap 128"
                        capNumber = 22;
                    otherwise
                        capNumber = 0;
                end
    
                processStructure.AddEEGPosition.Cap = app.ChooseCapButtonGroup.SelectedObject.Text;
                processStructure.AddEEGPosition.CapNumber = capNumber;

            end
            
            if (app.NotchFilterEEGCheckBox.Value)
                processStructure.NotchFilter.ToRun = true;
                processStructure.NotchFilter.FrequenceType = app.NotchGroup.SelectedObject;
                processStructure.NotchFilter.Parameters = app.getNotchFilterParameters;
            end
            
            if (app.BandPassFilterEEGCheckBox.Value)
                processStructure.BandPassFilter.ToRun = true;
                processStructure.BandPassFilter.Parameters = [app.LowCutOffHzEditField.Value, app.HighCutOffHzEditField.Value];
            end
            
            if (app.PowerSpectrumDensityEEGCheckBox.Value)
                processStructure.PowerSpectrumDensity.ToRun = true;
                processStructure.PowerSpectrumDensity.WindowLength = 10;
            end
            
            if (app.AverageReferenceCheckBox.Value)
                processStructure.AverageReference.ToRun  = true;
            end
            
            if (app.ICAEEGCheckBox.Value)
                processStructure.ICA.ToRun = true;
                processStructure.ICA.NumberOfComponents = app.NumberofComponentsICAEditField.Value;
            end
            
            if (app.ConverttoBIDSEEGCheckBox.Value)
                processStructure.ConvertToBIDS.ToRun = true;
                processStructure.ConvertToBIDS.BIDSpath = fullfile(app.BIDSFolderPathEditField.Value, app.BIDSFolderNameEditField.Value);
            end
            
            return
        end
        
        function processStructure = getMEGPipelineProcesses(app)
            
            processStructure = struct();
            
            if (app.ConvertEpochtoContinueCheckBox.Value)
                processStructure.ConvertEpochToContinue.ToRun = true;
            end
            
            if (app.NotchFilterMEGCheckBox.Value)
                processStructure.NotchFilter.ToRun = true;
                processStructure.NotchFilter.FrequenceType = app.NotchGroup.SelectedObject;
                processStructure.NotchFilter.Parameters = app.getNotchFilterParameters;
            end
            
            if (app.BandPassFilterMEGCheckBox.Value)
                processStructure.BandPassFilter.ToRun = true;
                processStructure.BandPassFilter.Parameters = [app.LowCutOffHzEditField.Value, app.HighCutOffHzEditField.Value];
            end
            
            if (app.PowerSpectrumDensityMEGCheckBox.Value)
                processStructure.PowerSpectrumDensity.ToRun = true;
                processStructure.PowerSpectrumDensity.WindowLength = 4;
            end
        
            if (app.DetectArtifactCheckBox.Value)
                processStructure.DetectArtifact.toRun = true;
                
                if (app.CardiacCheckBox.Value)
                    processStructure.DetectArtifact.Cardiac.Channel = 'ECG';
                    processStructure.DetectArtifact.Cardiac.EventName = 'cardiac';
                end
                if (app.BlinkCheckBox.Value)
                    processStructure.DetectArtifact.Blink.Channel = 'VEOG';
                    processStructure.DetectArtifact.Blink.EventName = 'blink';
                end
                if (app.LowFrequencyeyeteethCheckBox.Value)
                    processStructure.DetectArtifact.Other.LowFrequence = 1;
                end
                if (app.HighFrequencymusclesensorCheckBox.Value)
                    processStructure.DetectArtifact.Other.HighFrequence = 1;
                end
            end
            
            if (app.RemoveSimultaneousEventCheckBox.Value)
                processStructure.RemoveSimultaneousEvents.ToRun = true;
                processStructure.RemoveSimultaneousEvents.EventToRemove = app.EventtoRemoveEditField.Value;
                processStructure.RemoveSimultaneousEvents.TargetEvent = app.TargetEventEditField.Value;
                
            end
            
            if (app.SSPCheckBox.Value)
                eventName = app.EventNameSSPEditField.Value;
                
                if (eventName == "cardiac")
                    processStructure.SSP.Cardiac.ToRun = true;
                    processStructure.SSP.Cardiac.EventName = eventName;
            
                elseif (eventName == "blink")
                    processStructure.SSP.Blink.ToRun = true;
                    processStructure.SSP.Blink.EventName = eventName;
            
                else
                    processStructure.SSP.Generic.ToRun = true;
                    processStructure.SSP.Generic.EventName = eventName;
                end
                
            end
            
            if (app.ICAMEGCheckBox.Value)
                processStructure.ICA.ToRun = true;
                processStructure.ICA.NumberOfComponents = app.NumberofComponentsICAEditField.Value;
            end
            
            if (app.ConverttoBIDSMEGCheckBox.Value)
                processStructure.ConvertToBIDS.ToRun = true;
                processStructure.ConvertToBIDS.BIDSpath = fullfile(app.BIDSFolderPathEditField.Value, app.BIDSFolderNameEditField.Value);
            end
            
        end
        
        function [] = fillCheckBoxWithStructure(app, pipelineStructure)
            
            fields = fieldnames(pipelineStructure.Pipeline.Processes);
            
            for i = 1:length(fields)
                
                field = fields{i};
                
                if field == "RefineRegistration"
                    app.RefineRegistrationCheckBox.Value = true;
                
                
                elseif field == "AddEEGPosition"
                    app.AddEEGPositionCheckBox.Value = true;

                              
                elseif field == "ProjectElectrodeOnScalp"
                    app.ProjectElectrodeonScalpCheckBox.Value = true;
                    
                    
                elseif field == "NotchFilter"
                    app.NotchFilterEEGCheckBox.Value = true;
                    
                    strFrequenceType = pipelineStructure.Pipeline.Processes.NotchFilter.FrequenceType.Text;
                    
                    if (contains(strFrequenceType, 'Europe'))
                        app.EuropeButton.Value = true;
                        
                    elseif (contains(strFrequenceType, 'America'))
                        app.NorthAmericaButton.Value = true;
                        
                    elseif (contains(strFrequenceType, 'Frequence'))
                       app.SpecificFrequenceButton.Value = true;
                       app.NotchGroupSelectionChanged;
                       
                       frequences = pipelineStructure.Pipeline.Processes.NotchFilter.Parameters;
                       app.Frequence.Value = '';
                       for j = 1:length(frequences)
                            app.Frequence.Value = strcat(app.Frequence.Value, sprintf('%.0f', frequences(j)), ', ');
                       end
                       app.Frequence.Value = app.Frequence.Value{1}(1:end-1);
                      
                        
                    end
                    
                elseif field == "BandPassFilter"
                    app.BandPassFilterEEGCheckBox.Value = true;
                    app.LowCutOffHzEditField.Value = pipelineStructure.Pipeline.Processes.BandPassFilter.Parameters(1);
                    app.HighCutOffHzEditField.Value = pipelineStructure.Pipeline.Processes.BandPassFilter.Parameters(2);


                elseif field == "PowerSpectrumDensity"
                    app.PowerSpectrumDensityEEGCheckBox.Value = true;
                    
                    
                elseif field == "AverageReference"
                    app.AverageReferenceCheckBox.Value = true;   
                    
                    
                elseif field == "ICA"
                    app.ICAEEGCheckBox.Value = true;
                    app.NumberofComponentsICAEditField.Value = pipelineStructure.Pipeline.Processes.ICA.NumberOfComponents;
                
                end
                
                app.refreshVisibility;
                
            end
            
            
        end
        
        function [] = refreshVisibility(app)
            
            app.AddEEGPositionCheckBoxValueChanged;
            app.NotchFilterEEGCheckBoxValueChanged;
            app.NotchFilterEEGCheckBoxValueChanged;
            app.BandPassFilterEEGCheckBoxValueChanged;
            app.BandPassFilterEEGCheckBoxValueChanged;
            app.ICAEEGCheckBoxValueChanged;
            app.ICAMEGCheckBoxValueChanged;
            app.DetectArtifactCheckBoxValueChanged;
            app.SSPCheckBoxValueChanged;
            app.RemoveSimultaneousEventCheckBoxValueChanged;
            
        end
    end
     
    methods(Static)
        
    function [] = savePipeline(fileName, filePath, structure)

             save(fullfile(filePath, fileName), "-struct", "structure");
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            choice = ["EEG", "MEG"];
            rep = string(uiconfirm(app.UIFigure, 'What type of analysis do you want to conduct ?', 'Type of analysis', 'Options', choice));
            
            switch rep
                case choice(1)
                    app.EEGProcessesPanel.Visible = true;
                    app.MEGProcessesPanel.Visible = false;
                    app.PipelineTypeDropDown.Value = "EEG";
                    
                case choice(2)
                   app.MEGProcessesPanel.Visible = true;
                   app.EEGProcessesPanel.Visible = false;
                   app.PipelineTypeDropDown.Value = "MEG";
            end
        end

        % Value changed function: PipelineTypeDropDown
        function PipelineTypeDropDownValueChanged(app, event)
            switch app.PipelineTypeDropDown.Value
                case 'EEG'
                    app.EEGProcessesPanel.Visible = true;
                    app.MEGProcessesPanel.Visible = false;
                    
                    checkBoxes = app.MEGProcessesPanel.Children;
                    for i = 1:length(checkBoxes)
                        checkBoxes(i).Value = false;
                    end
                    
                case 'MEG'
                    app.MEGProcessesPanel.Visible = true;
                    app.EEGProcessesPanel.Visible = false;
                    
                    checkBoxes = app.EEGProcessesPanel.Children;
                    for i = 1:length(checkBoxes)
                        checkBoxes(i).Value = false;
                    end
            end
            
            
            app.refreshVisibility;
            
        end

        % Button pushed function: BrowsePipelineButton
        function BrowsePipelineButtonPushed(app, event)
            path = uigetdir('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/');
            app.SaveinFolderEditField.Value = path;
            
            %fieldPosition = app.SaveinFolderEditField.Position;
            %assignin("base", "long", length(path))
            %app.SaveinFolderEditField.Position = [fieldPosition(1), fieldPosition(2), length(path) + 300, fieldPosition(4)];
        end

        % Value changed function: AddEEGPositionCheckBox
        function AddEEGPositionCheckBoxValueChanged(app, event)

            
            if app.AddEEGPositionCheckBox.Value
                
                app.AddEEGPositionGroup.Visible = true;
                app.AddEEGPositionGroupSelectionChanged;
                
            else
                
                app.AddEEGPositionGroup.Visible = false;
                app.ChooseCapButtonGroup.Visible = false;
                
            end
            
        end

        % Value changed function: NotchFilterEEGCheckBox
        function NotchFilterEEGCheckBoxValueChanged(app, event)
 
            if app.NotchFilterEEGCheckBox.Value
                app.NotchGroup.Visible = true;
            else
                app.NotchGroup.Visible = false;
            end
            
        end

        % Value changed function: NotchFilterMEGCheckBox
        function NotchFilterMEGCheckBoxValueChanged(app, event)
            
            if app.NotchFilterMEGCheckBox.Value
                app.NotchGroup.Visible = true;
            else
                app.NotchGroup.Visible = false;
            end
            
        end

        % Value changed function: BandPassFilterEEGCheckBox
        function BandPassFilterEEGCheckBoxValueChanged(app, event)

            if app.BandPassFilterEEGCheckBox.Value
                app.BandPassFrequencesPanel.Visible = true;
            else
                app.BandPassFrequencesPanel.Visible = false;
            end
            
        end

        % Value changed function: BandPassFilterMEGCheckBox
        function BandPassFilterMEGCheckBoxValueChanged(app, event)
           
            if app.BandPassFilterMEGCheckBox.Value
                app.BandPassFrequencesPanel.Visible = true;
            else
                app.BandPassFrequencesPanel.Visible = false;
            end
            
        end

        % Selection changed function: NotchGroup
        function NotchGroupSelectionChanged(app, event)
            switch app.NotchGroup.SelectedObject
                
                case app.EuropeButton
                    app.Frequence.Visible = false;
                    
                case app.NorthAmericaButton
                    app.Frequence.Visible = false;
                    
                case app.SpecificFrequenceButton
                    app.Frequence.Visible = true;
                    
            end
        end

        % Selection changed function: AddEEGPositionGroup
        function AddEEGPositionGroupSelectionChanged(app, event)
            switch app.AddEEGPositionGroup.SelectedObject
                
                case app.UseDefaultPatternButton
                    app.ChooseCapButtonGroup.Visible = true;
                    
                case app.ImportFileButton
                    app.ChooseCapButtonGroup.Visible = false;
            end
        end

        % Value changed function: Frequence
        function FrequenceValueChanged(app, event)
            value = app.Frequence.Value;
            
            position = app.Frequence.Position;
            
            app.Frequence.Position = [position(1) position(2) length(value{1}) + 100 position(4)];
            
        end

        % Value changed function: PipelineNameEditField
        function PipelineNameEditFieldValueChanged(app, event)
            value = app.PipelineNameEditField.Value;
            
            assignin("base", "value", value);
            
            %position = app.PipelineNameEditField.Position;
            
            %app.PipelineNameEditField.Position = [position(1) position(2) length(value) + 100 position(4)];
        end

        % Button pushed function: SavePipelineButton
        function SavePipelineButtonPushed(app, event)

            
            app.Pipeline = struct();
            
            app.Pipeline.Name = app.PipelineNameEditField.Value;
            
            app.Pipeline.Folder = app.SaveinFolderEditField.Value;
            
            app.Pipeline.Type = app.PipelineTypeDropDown.Value;
            
            if app.PipelineTypeDropDown.Value == "EEG"
                
                
                app.Pipeline.Processes = app.getEEGPipelineProcesses;
                
            elseif app.PipelineTypeDropDown.Value == "MEG"
                
                app.Pipeline.Processes = app.getMEGPipelineProcesses;
                
            end
            
            
            app.Pipeline.NumberOfProcesses = length(fieldnames(app.Pipeline.Processes));
            
            
            c = clock;
            c(6) = round(c(6));
            app.Pipeline.DateOfCreation = c;


            fileName = strcat(app.PipelineNameEditField.Value, '.mat');
            filePath = app.SaveinFolderEditField.Value;
            
            app.savePipeline(fileName, filePath, app.Pipeline)
        end

        % Button pushed function: ImportPipelineButton
        function ImportPipelineButtonPushed(app, event)
            
            [fileName, folderPath] = uigetfile('*.mat', 'Select MAT file', app.Path);
            
            app.PipelineNameEditField.Value = fileName(1:end-4);
            app.SaveinFolderEditField.Value = folderPath;

            app.Pipeline = struct();
            app.Pipeline = load(strcat(folderPath, fileName));
            
            
            app.fillCheckBoxWithStructure(app.Pipeline);
            
        end

        % Value changed function: ICAEEGCheckBox
        function ICAEEGCheckBoxValueChanged(app, event)
            
           if (app.ICAEEGCheckBox.Value)              
               app.NumberofComponentsICAEditField.Visible = true;
               app.NumberofComponentsICAEditFieldLabel.Visible = true;
           else
               app.NumberofComponentsICAEditField.Visible = false;
               app.NumberofComponentsICAEditFieldLabel.Visible = false;
           end
            
        end

        % Value changed function: DetectArtifactCheckBox
        function DetectArtifactCheckBoxValueChanged(app, event)

            if (app.DetectArtifactCheckBox.Value)
                app.TypeofArtifactPanel.Visible = true;
                
            else 
                app.TypeofArtifactPanel.Visible = false;
                
            end
            
        end

        % Value changed function: SSPCheckBox
        function SSPCheckBoxValueChanged(app, event)
            
            if (app.SSPCheckBox.Value)
                
                app.EventNameSSPEditField.Visible = true;
                app.EventNameSSPEditFieldLabel.Visible = true;
                
            else
                app.EventNameSSPEditField.Visible = false;
                app.EventNameSSPEditFieldLabel.Visible = false;
            end
            
            
        end

        % Value changed function: ICAMEGCheckBox
        function ICAMEGCheckBoxValueChanged(app, event)

            if (app.ICAMEGCheckBox.Value)              
                app.NumberofComponentsICAEditField.Visible = true;
                app.NumberofComponentsICAEditFieldLabel.Visible = true;             
            else
                app.NumberofComponentsICAEditField.Visible = false;
                app.NumberofComponentsICAEditFieldLabel.Visible = false;
            end
            
        end

        % Value changed function: RemoveSimultaneousEventCheckBox
        function RemoveSimultaneousEventCheckBoxValueChanged(app, event)
           
            if app.RemoveSimultaneousEventCheckBox.Value
                app.RemoveSimultaneousEventPanel.Visible = true;
            else
                app.RemoveSimultaneousEventPanel.Visible = false;
            end
            
        end

        % Value changed function: ConverttoBIDSEEGCheckBox
        function ConverttoBIDSEEGCheckBoxValueChanged(app, event)
            
            if (app.ConverttoBIDSEEGCheckBox.Value)              
                app.BIDSPanel.Visible = true;            
            else
                app.BIDSPanel.Visible = false; 
            end
            
        end

        % Value changed function: ConverttoBIDSMEGCheckBox
        function ConverttoBIDSMEGCheckBoxValueChanged(app, event)
            
            if (app.ConverttoBIDSMEGCheckBox.Value)              
                app.BIDSPanel.Visible = true;            
            else
                app.BIDSPanel.Visible = false; 
            end
            
        end

        % Button pushed function: BrowseBIDSButton
        function BrowseBIDSButtonPushed(app, event)
            path = uigetdir('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/');
            app.BIDSFolderPathEditField.Value = path;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PipelineNameEditFieldLabel = uilabel(app.UIFigure);
            app.PipelineNameEditFieldLabel.Text = 'Pipeline Name';
            app.PipelineNameEditField = uieditfield(app.UIFigure, 'text');
            app.PipelineNameEditField.ValueChangedFcn = createCallbackFcn(app, @PipelineNameEditFieldValueChanged, true);
            app.SaveinFolderEditFieldLabel = uilabel(app.UIFigure);
            app.SaveinFolderEditFieldLabel.Text = 'Save in Folder';
            app.SaveinFolderEditField = uieditfield(app.UIFigure, 'text');
            app.BrowsePipelineButton = uibutton(app.UIFigure, 'push');
            app.BrowsePipelineButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsePipelineButtonPushed, true);
            app.BrowsePipelineButton.Text = 'Browse';
            app.NotchGroup = uibuttongroup(app.UIFigure);
            app.NotchGroup.SelectionChangedFcn = createCallbackFcn(app, @NotchGroupSelectionChanged, true);
            app.NotchGroup.Title = 'Notch Frequences';
            app.NotchGroup.Visible = 'off';
            app.NorthAmericaButton = uiradiobutton(app.NotchGroup);
            app.NorthAmericaButton.Text = 'North America (60Hz, 120Hz, 180Hz, 240Hz)';
            app.NorthAmericaButton.Value = true;
            app.EuropeButton = uiradiobutton(app.NotchGroup);
            app.EuropeButton.Text = 'Europe (50Hz, 100Hz, 150Hz, 200Hz)';
            app.SpecificFrequenceButton = uiradiobutton(app.NotchGroup);
            app.SpecificFrequenceButton.Text = 'Specific Frequence:';
            app.Frequence = uitextarea(app.NotchGroup);
            app.Frequence.ValueChangedFcn = createCallbackFcn(app, @FrequenceValueChanged, true);
            app.Frequence.Visible = 'off';
            app.BandPassFrequencesPanel = uipanel(app.UIFigure);
            app.BandPassFrequencesPanel.Title = 'Band Pass Frequences';
            app.BandPassFrequencesPanel.Visible = 'off';
            app.LowCutOffHzEditFieldLabel = uilabel(app.BandPassFrequencesPanel);
            app.LowCutOffHzEditFieldLabel.Text = 'Low Cut Off (Hz)';
            app.LowCutOffHzEditField = uieditfield(app.BandPassFrequencesPanel, 'numeric');
            app.HighCutOffHzEditFieldLabel = uilabel(app.BandPassFrequencesPanel);
            app.HighCutOffHzEditFieldLabel.Text = 'High Cut Off (Hz)';
            app.HighCutOffHzEditField = uieditfield(app.BandPassFrequencesPanel, 'numeric');
            app.AddEEGPositionGroup = uibuttongroup(app.UIFigure);
            app.AddEEGPositionGroup.SelectionChangedFcn = createCallbackFcn(app, @AddEEGPositionGroupSelectionChanged, true);
            app.AddEEGPositionGroup.Title = 'Original EEG position file';
            app.AddEEGPositionGroup.Visible = 'off';
            app.UseDefaultPatternButton = uiradiobutton(app.AddEEGPositionGroup);
            app.UseDefaultPatternButton.Text = 'Use Default Pattern';
            app.UseDefaultPatternButton.Value = true;
            app.ImportFileButton = uiradiobutton(app.AddEEGPositionGroup);
            app.ImportFileButton.Text = 'Import File';
            app.PipelineTypeDropDownLabel = uilabel(app.UIFigure);
            app.PipelineTypeDropDownLabel.Text = 'Pipeline Type';
            app.PipelineTypeDropDown = uidropdown(app.UIFigure);
            app.PipelineTypeDropDown.Items = {'EEG', 'MEG'};
            app.PipelineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PipelineTypeDropDownValueChanged, true);
            app.PipelineTypeDropDown.Value = 'EEG';
            app.NumberofComponentsICAEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofComponentsICAEditFieldLabel.Visible = 'off';
            app.NumberofComponentsICAEditFieldLabel.Text = 'Number of Components (ICA)';
            app.NumberofComponentsICAEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofComponentsICAEditField.Visible = 'off';
            app.EEGProcessesPanel = uipanel(app.UIFigure);
            app.EEGProcessesPanel.Title = 'EEG Processes';
            app.EEGProcessesPanel.Visible = 'off';
            app.AddEEGPositionCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.AddEEGPositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddEEGPositionCheckBoxValueChanged, true);
            app.AddEEGPositionCheckBox.Text = 'Add EEG Position';
            app.RefineRegistrationCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.RefineRegistrationCheckBox.Text = 'Refine Registration';
            app.ProjectElectrodeonScalpCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.ProjectElectrodeonScalpCheckBox.Text = 'Project Electrode on Scalp';
            app.NotchFilterEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.NotchFilterEEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchFilterEEGCheckBoxValueChanged, true);
            app.NotchFilterEEGCheckBox.Text = 'Notch Filter';
            app.BandPassFilterEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.BandPassFilterEEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @BandPassFilterEEGCheckBoxValueChanged, true);
            app.BandPassFilterEEGCheckBox.Text = 'Band-Pass Filter';
            app.PowerSpectrumDensityEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.PowerSpectrumDensityEEGCheckBox.Text = 'Power Spectrum Density';
            app.AverageReferenceCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.AverageReferenceCheckBox.Text = 'Average Reference';
            app.ICAEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.ICAEEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ICAEEGCheckBoxValueChanged, true);
            app.ICAEEGCheckBox.Text = 'ICA';
            app.ConverttoBIDSEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.ConverttoBIDSEEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConverttoBIDSEEGCheckBoxValueChanged, true);
            app.ConverttoBIDSEEGCheckBox.Text = 'Convert to BIDS';
            app.MEGProcessesPanel = uipanel(app.UIFigure);
            app.MEGProcessesPanel.Title = 'MEG Processes';
            app.MEGProcessesPanel.Visible = 'off';
            app.NotchFilterMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.NotchFilterMEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchFilterMEGCheckBoxValueChanged, true);
            app.NotchFilterMEGCheckBox.Text = 'Notch Filter';
            app.ConvertEpochtoContinueCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.ConvertEpochtoContinueCheckBox.Text = 'Convert Epoch to Continue';
            app.BandPassFilterMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.BandPassFilterMEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @BandPassFilterMEGCheckBoxValueChanged, true);
            app.BandPassFilterMEGCheckBox.Text = 'Band-Pass Filter';
            app.PowerSpectrumDensityMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.PowerSpectrumDensityMEGCheckBox.Text = 'Power Spectrum Density';
            app.DetectArtifactCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.DetectArtifactCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetectArtifactCheckBoxValueChanged, true);
            app.DetectArtifactCheckBox.Text = 'Detect Artifact';
            app.SSPCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.SSPCheckBox.ValueChangedFcn = createCallbackFcn(app, @SSPCheckBoxValueChanged, true);
            app.SSPCheckBox.Text = 'SSP';
            app.RemoveSimultaneousEventCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.RemoveSimultaneousEventCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveSimultaneousEventCheckBoxValueChanged, true);
            app.RemoveSimultaneousEventCheckBox.Text = 'Remove Simultaneous Event';
            app.ICAMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.ICAMEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ICAMEGCheckBoxValueChanged, true);
            app.ICAMEGCheckBox.Text = 'ICA';
            app.ConverttoBIDSMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.ConverttoBIDSMEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConverttoBIDSMEGCheckBoxValueChanged, true);
            app.ConverttoBIDSMEGCheckBox.Text = 'Convert to BIDS';
            app.EventNameSSPEditFieldLabel = uilabel(app.UIFigure);
            app.EventNameSSPEditFieldLabel.Visible = 'off';
            app.EventNameSSPEditFieldLabel.Text = 'Event Name (SSP)';
            app.EventNameSSPEditField = uieditfield(app.UIFigure, 'text');
            app.EventNameSSPEditField.Visible = 'off';
            app.TypeofArtifactPanel = uipanel(app.UIFigure);
            app.TypeofArtifactPanel.Title = 'Type of Artifact';
            app.TypeofArtifactPanel.Visible = 'off';
            app.CardiacCheckBox = uicheckbox(app.TypeofArtifactPanel);
            app.CardiacCheckBox.Text = 'Cardiac';
            app.BlinkCheckBox = uicheckbox(app.TypeofArtifactPanel);
            app.BlinkCheckBox.Text = 'Blink';
            app.LowFrequencyeyeteethCheckBox = uicheckbox(app.TypeofArtifactPanel);
            app.LowFrequencyeyeteethCheckBox.Text = 'Low Frequency (eye/teeth)';
            app.HighFrequencymusclesensorCheckBox = uicheckbox(app.TypeofArtifactPanel);
            app.HighFrequencymusclesensorCheckBox.Text = 'High Frequency (muscle/sensor)';
            app.ChooseCapButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseCapButtonGroup.Title = 'Choose Cap';
            app.ChooseCapButtonGroup.Visible = 'off';
            app.Colin27BrainProductsEasyCap128Button = uiradiobutton(app.ChooseCapButtonGroup);
            app.Colin27BrainProductsEasyCap128Button.Text = 'Colin27: BrainProducts EasyCap 128';
            app.Colin27BrainProductsEasyCap128Button.Value = true;
            app.Colin27BrainProductsEsayCap64Button = uiradiobutton(app.ChooseCapButtonGroup);
            app.Colin27BrainProductsEsayCap64Button.Text = 'Colin27: BrainProducts EsayCap 64';
            app.RemoveSimultaneousEventPanel = uipanel(app.UIFigure);
            app.RemoveSimultaneousEventPanel.Title = 'Remove Simultaneous Event';
            app.RemoveSimultaneousEventPanel.Visible = 'off';
            app.EventtoRemoveEditFieldLabel = uilabel(app.RemoveSimultaneousEventPanel);
            app.EventtoRemoveEditFieldLabel.Text = 'Event to Remove';
            app.EventtoRemoveEditField = uieditfield(app.RemoveSimultaneousEventPanel, 'text');
            app.TargetEventEditFieldLabel = uilabel(app.RemoveSimultaneousEventPanel);
            app.TargetEventEditFieldLabel.Text = 'Target Event';
            app.TargetEventEditField = uieditfield(app.RemoveSimultaneousEventPanel, 'text');
            app.PipelinePanel = uipanel(app.UIFigure);
            app.PipelinePanel.Title = 'Pipeline';
            app.SavePipelineButton = uibutton(app.PipelinePanel, 'push');
            app.SavePipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SavePipelineButtonPushed, true);
            app.SavePipelineButton.Text = 'Save Pipeline';
            app.ImportPipelineButton = uibutton(app.PipelinePanel, 'push');
            app.ImportPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPipelineButtonPushed, true);
            app.ImportPipelineButton.Text = 'Import Pipeline';
            app.ClearPipelineButton = uibutton(app.PipelinePanel, 'push');
            app.ClearPipelineButton.Text = 'Clear Pipeline';
            app.BIDSPanel = uipanel(app.UIFigure);
            app.BIDSPanel.Title = 'BIDS';
            app.BIDSPanel.Visible = 'off';
            app.BIDSFolderNameEditFieldLabel = uilabel(app.BIDSPanel);
            app.BIDSFolderNameEditFieldLabel.Text = 'BIDS Folder Name';
            app.BIDSFolderNameEditField = uieditfield(app.BIDSPanel, 'text');
            app.BIDSFolderPathEditFieldLabel = uilabel(app.BIDSPanel);
            app.BIDSFolderPathEditFieldLabel.Text = 'BIDS Folder Path';
            app.BIDSFolderPathEditField = uieditfield(app.BIDSPanel, 'text');
            app.BrowseBIDSButton = uibutton(app.BIDSPanel, 'push');
            app.BrowseBIDSButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBIDSButtonPushed, true);
            app.BrowseBIDSButton.Text = 'Browse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pipeline_Builder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end