classdef TextInput < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        CancelButton  matlab.ui.control.Button
        EditField     matlab.ui.control.EditField
        OKButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, title)
            app.CallingApp = mainApp;
            app.UIFigure.Name = title;
            
            app.UIFigure.Position(1) = 500;
            app.UIFigure.Position(2) = 500;
            app.UIFigure.Position(3) = 300;
            
            if length(title) > 23
                app.UIFigure.Position(3) = app.UIFigure.Position(3) + (length(title) - 23) * 8;
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            returnTextInputValues(app.CallingApp, app.EditField.Value);
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            returnTextInputValues(app.CallingApp, '');
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            returnTextInputValues(app.CallingApp, '');
            delete(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'return'
                    app.OKButtonPushed(event);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Tag = 'tagEditField';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TextInput(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end