classdef Analysis_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisToolUIFigure            matlab.ui.Figure
        ProtocolMenu                    matlab.ui.container.Menu
        CreateProtocolMenu              matlab.ui.container.Menu
        ReloadCurrentProtocolMenu       matlab.ui.container.Menu
        LoadProtocolMenu                matlab.ui.container.Menu
        DeleteProtocolMenu              matlab.ui.container.Menu
        SubjectMenu                     matlab.ui.container.Menu
        CreateSubjectMenu               matlab.ui.container.Menu
        DeleteSubjectMenu               matlab.ui.container.Menu
        StudyMenu                       matlab.ui.container.Menu
        ReviewRawFilesMenu              matlab.ui.container.Menu
        SelectComponentsMenu            matlab.ui.container.Menu
        DeleteStudyMenu                 matlab.ui.container.Menu
        EventMenu                       matlab.ui.container.Menu
        RenameEventMenu                 matlab.ui.container.Menu
        DeleteEventMenu                 matlab.ui.container.Menu
        ConversionMenu                  matlab.ui.container.Menu
        ExporttoEDFMenu                 matlab.ui.container.Menu
        ExporttoBrainVisionMenu         matlab.ui.container.Menu
        ExporttoBIDSMenu                matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        AnalysisTypeMenu                matlab.ui.container.Menu
        EEGMenu                         matlab.ui.container.Menu
        MEGMenu                         matlab.ui.container.Menu
        OpenBrainstormMenu              matlab.ui.container.Menu
        AddTagMenu                      matlab.ui.container.Menu
        ColorModeMenu                   matlab.ui.container.Menu
        ReportsPanel                    matlab.ui.container.Panel
        BrainstormLogo                  matlab.ui.control.Image
        EeglabLogo                      matlab.ui.control.Image
        EegNetLogo                      matlab.ui.control.Image
        UlavalLogo                      matlab.ui.control.Image
        BrainCanadaLogo                 matlab.ui.control.Image
        CervoLogo                       matlab.ui.control.Image
        ProtocolPanel                   matlab.ui.container.Panel
        ExpandButton                    matlab.ui.control.Button
        CollapseButton                  matlab.ui.control.Button
        SelectRawButton                 matlab.ui.control.Button
        SelectImportedButton            matlab.ui.control.Button
        CurrentProtocolLabel            matlab.ui.control.Label
        CurrentProtocolEditField        matlab.ui.control.EditField
        UpdateTreeButton                matlab.ui.control.Button
        SelectDeselectallButton         matlab.ui.control.Button
        PostProcessPanel                matlab.ui.container.Panel
        ComputeSourcesButton            matlab.ui.control.Button
        AverageButton                   matlab.ui.control.Button
        RejectTrialsButton              matlab.ui.control.Button
        ImportinDataBaseButton          matlab.ui.control.Button
        BrainstormEegTutorialLink       matlab.ui.control.Hyperlink
        BrainstormMegTutorialLink       matlab.ui.control.Hyperlink
        GitHubLink                      matlab.ui.control.Hyperlink
        Tree                            matlab.ui.container.CheckBoxTree
        PipelinePanel                   matlab.ui.container.Panel
        RunPipelineButton               matlab.ui.control.Button
        ImportPipelineButton            matlab.ui.control.Button
        ClearPipelineButton             matlab.ui.control.Button
        OpenPipelineBuilderButton       matlab.ui.control.Button
        UpdateEventsButton              matlab.ui.control.Button
        UpdateHistoryButton             matlab.ui.control.Button
        ClearEventsButton               matlab.ui.control.Button
        ClearHistoryButton              matlab.ui.control.Button
        PipelineTextArea                matlab.ui.control.TextArea
        EventPanel                      matlab.ui.container.Panel
        HistoryPanel                    matlab.ui.container.Panel
        FrequencyPanel                  matlab.ui.container.Panel
        PowerSpectrumDensityButton      matlab.ui.control.Button
        BandpassFilterButton            matlab.ui.control.Button
        NotchFilterButton               matlab.ui.control.Button
        TabGroup1                       matlab.ui.container.TabGroup
        EEGTabGr1                       matlab.ui.container.Tab
        ImportEventsButton              matlab.ui.control.Button
        DetectHeartbeatsButton          matlab.ui.control.Button
        ProjectElectrodesonScalpButton  matlab.ui.control.Button
        RefineRegistrationButton        matlab.ui.control.Button
        AddEEGPositionsButton           matlab.ui.control.Button
        MEGTabGr1                       matlab.ui.container.Tab
        ConvertEpochstoContinueButton   matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        EEGTabGr2                       matlab.ui.container.Tab
        ICAEEGButton                    matlab.ui.control.Button
        AverageReferenceButton          matlab.ui.control.Button
        MEGTabGr2                       matlab.ui.container.Tab
        ICAMEGButton                    matlab.ui.control.Button
        SSPButton                       matlab.ui.control.Button
        DetectArtifactButton            matlab.ui.control.Button
        RemoveSimultaneousEventsButton  matlab.ui.control.Button
    end


    properties (Access = public)

        controller ControllerAnalysisTool;
        appManager App_Manager;
        
    end        

    methods (Access = public)
        
        function rep = list_dialog(~, question, choice, selectionMode)
            
            arguments
                ~
                question char
                choice cell
                selectionMode char = 'single';
            end
            
            longestChoice = max(cellfun(@length, choice));
            
            index = listdlg('PromptString', question, 'ListString', choice, ...
                'SelectionMode', selectionMode, 'ListSize', [200 + 8*(longestChoice-10), 400]);
            
            if isempty(index)
                rep = [];
                return
            end
            
            rep = cell(1, length(index));
            for i = 1:length(index)
                rep(i) = choice(index(i));
            end
            
            rep = string(rep);
        
        end
        
        function rep = input_dialog(~, question, size)
            
            arguments
                ~
                question char;
                size double = [1, 35];
            end
            
            rep = inputdlg(question, question, size);
            
            if isempty(rep)
                return
            end
            
            rep = rep{1};
            
        end
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, type)
            
            % Instantiate controller
            app.controller = ControllerAnalysisTool();
            
            % Instantiate app manager
            app.appManager = App_Manager(app);
            
            % Load logos
            app.appManager.loadLogos();
            
            % Input for Type of Analysis
            if nargin == 1
                choice = ["EEG", "MEG"];
                type = uiconfirm(app.AnalysisToolUIFigure, "What type of analysis do you want to conduct ?", ...
                    "Type of analysis", "Options", choice);
            end
            
            app.appManager.switchAnalysisType(upper(type));
            
            % Input for Working Folder
            if nargin == 1
                wf = uigetdir(app.controller.WorkingFolder, "Select your Working Folder");
            
                if ~isequal(wf, 0)
                    wf = replace(wf, '\', '/');
                    app.controller.setWorkingFolderPath(wf)
                else
                    % If wf is empty, we keep the default Working Folder.
                    uiconfirm(app.AnalysisToolUIFigure, "Default Working Folder will be : " + app.controller.getWorkingFolderPath,...
                        "Default Working Folder");
                end
            end
            
            % Create folder
            app.controller.createWorkingFolder();
            
            app.PipelineTextArea.Value = app.controller.printCurrentPipeline;            
            
        end

        % Button pushed function: OpenPipelineBuilderButton
        function OpenPipelineBuilderButtonPushed(app, event)
            
            % Open Pipeline Builder
            run('Pipeline_Builder.mlapp');
            
        end

        % Button pushed function: ImportPipelineButton
        function ImportPipelineButtonPushed(app, event)
            
            % User input Pipeline File
            [file, folder] = uigetfile(app.controller.getPipelineSupportedExtensionToGetFile, ...
                'Select file', app.controller.getPipelineSearchPath);
            
            % If Cancel
            if (file == 0)
                return
            end
            folder = replace(folder, '\', '/');
            
            % Update PipelineSearchPath
            app.controller.setPipelineSearchPath(folder);
            
            % Load Pipeline
            app.controller.loadPipeline(fullfile(folder, file));
            app.PipelineTextArea.Value = app.controller.printCurrentPipeline;
            
        end

        % Button pushed function: ClearPipelineButton
        function ClearPipelineButtonPushed(app, event)

            % Clear Pipeline
            app.controller.clearPipeline;
            app.PipelineTextArea.Value = app.controller.printCurrentPipeline;
           
        end

        % Button pushed function: RunPipelineButton
        function RunPipelineButtonPushed(app, event)

            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            app.controller.runPipeline(sFiles);
            
            linkOfLastReport = app.controller.getLinkOfLastReport();
            
            app.appManager.createPipelineReportButton(linkOfLastReport);
            
            app.appManager.update_Tree_And_Check_New_Studies();
            
        end

        % Button pushed function: NotchFilterButton
        function NotchFilterButtonPushed(app, event)

            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            % Input for Frequences
            choice = ["I am in North America (60 Hz, 120 Hz, 180 Hz, ...)", ...
                "I am in Europe (50 Hz, 100 Hz, 150 Hz, ...)", "Specific Frequence", "Cancel"];
            rep = uiconfirm(app.AnalysisToolUIFigure, 'Where has your protocol been conducted ?', ...
                'Notch filter', 'Options', choice);
            
            switch rep
                case choice(1)
                    frequence = [60 120 180 240];
                case choice(2)
                    frequence = [50 100 150 200];
                case choice(3)
                    question = 'Enter the frequences (Hz): ';
                    frequence = app.input_dialog(question);
                case choice(4)
                    return
            end
            
            % Apply Notch Filter
            parameter = struct();
            parameter.Frequence = frequence;
            process = app.controller.createProcess('Notch Filter', parameter);
            app.controller.runProcess(process, sFiles);
            
            app.appManager.update_Tree_And_Check_New_Studies();
   
        end

        % Button pushed function: BandpassFilterButton
        function BandpassFilterButtonPushed(app, event)

            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % Input for Frequences
            question = 'Lower cut off frequency (Hz): ';
            LowPass = str2double(app.input_dialog(question));

            question = 'Higher cut off frequency (Hz): ';
            HighPass = str2double(app.input_dialog(question));

            if isempty(HighPass) || isempty(LowPass)
                uialert(app.AnalysisToolUIFigure, "Please enter an upper and lower frequency cutoff points.", ...
                    'Error')
                return
            end

            % Apply Band-Pass Filter
            parameter = struct();
            parameter.Frequence = [LowPass HighPass];
            process = app.controller.createProcess('Band-Pass Filter', parameter);
            app.controller.runProcess(process, sFiles);
            
            app.appManager.update_Tree_And_Check_New_Studies();         
            
        end

        % Button pushed function: PowerSpectrumDensityButton
        function PowerSpectrumDensityButtonPushed(app, event)

            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            % Default Window Length
            windowLength = 10;
            
            % Apply Power Spectrum Density
            parameter = struct();
            parameter.WindowLength = windowLength;
            process = app.controller.createProcess('Power Spectrum Density', parameter);
            app.controller.runProcess(process, sFiles);

            
        end

        % Button pushed function: ConvertEpochstoContinueButton
        function ConvertEpochstoContinueButtonPushed(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            % Convert Epochs to Continue
            parameter = struct();
            parameter.ToRun = 3;
            process = app.controller.createProcess('Convert Epochs To Continue', parameter);
            app.controller.runProcess(process, sFiles);

        end

        % Button pushed function: DetectArtifactButton
        function DetectArtifactButtonPushed(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();
             
            % Input for type of artifact
            choice = ["Cardiac", "Blink", "Low Frequency Artifact (1-7 Hz; eye/teeth movement)", ...
                        "High Frequency Artifact (40-240 Hz; muscle movement/sensor artifacts)"];
            question = 'Choose Artifact';

            artifacts = app.list_dialog(question, choice);
            if isempty(artifacts)
                return
            end
            
            lowFrequence = 0;
            highFrequence = 0;

            % If cardiac artifact
            if (any(choice(1) == artifacts))
                channelName = 'ECG';

                % Verify if channel is in channel file
                app.controller.checkIfChannelIsInChannelFile(sFiles, channelName);
                
                parameters = struct();
                parameters.Channel = channelName;
                parameters.Event = 'cardiac';
                process = app.controller.createProcess('Detect Cardiac Artifact', parameters);
                app.controller.runProcess(process, sFiles);
                
            end
            
            % If blink artifact
            if (any(choice(2) == artifacts))
                channelName = 'VEOG';

                % Verify if channel is in channel file
                app.controller.checkIfChannelIsInChannelFile(sFiles, channelName);
                
                parameters = struct();
                parameters.Channel = channelName;
                parameters.Event = 'blink';
                process = app.controller.createProcess('Detect Blink Artifact', parameters);
                app.controller.runProcess(process, sFiles);
                                
            end

            % If low frequency artifact
            if (any(choice(3) == artifacts))
                    lowFrequence = 1;
            end
            
            % If high frequency artifact
            if (any(choice(4) == artifacts))
                    highFrequence = 1;
            end
            
            % If low or high frequence artifact has been selected
            if (lowFrequence ~= 0 || highFrequence ~= 0)
                
                parameters = struct();
                parameters.LowFrequences = lowFrequence;
                parameters.HighFrequences = highFrequence;
                process = app.controller.createProcess('Detect Other Artifact', parameters);
                app.controller.runProcess(process, sFiles);
                
            end
        end

        % Button pushed function: RemoveSimultaneousEventsButton
        function RemoveSimultaneousEventsButtonPushed(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % Display all Events
            choice = app.appManager.getEvents(sFiles);

            question = 'Remove the event named: ';
            eventRemove = app.list_dialog(question, choice);
            
            question = 'When it is too close to the event: ';
            eventTarget = app.list_dialog(question, choice);
            
            if isempty(eventRename) || isempty(eventTarget)
                waitfor(errordlg("Missing event to remove or to targert."))
                return
            end
            
            parameters = struct();
            parameters.EventToTarget = eventTarget;
            parameters.EventToRemove = eventRemove;
            process = app.controller.createProcess('Remove Simultaneaous Events', parameters);
            app.controller.runProcess(process, sFiles);
                        
        end

        % Button pushed function: SSPButton
        function SSPButtonPushed(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % Display all Events
            choice = app.appManager.getEvents(sFiles);
            question = 'Which event do you want to use to compute the SSP ?';

            eventName = app.list_dialog(question, choice);
            
            if isempty(eventName)
                waitfor(errordlg("Missing event to remove or to targert."))
                return
            end
            
            % If cardiac
            if (eventName == "cardiac")
                parameter = struct();
                parameter.Event = eventName;
                process = app.controller.createProcess('SSP Cardiac', parameter);
                app.controller.runProcess(process, sFiles);
            
            % If blink
            elseif (eventName == "blink")
                parameter = struct();
                parameter.Event = eventName;
                process = app.controller.createProcess('SSP Blink', parameter);
                app.controller.runProcess(process, sFiles);
               
            
            % If event different from cardiac or blink
            else
                parameter = struct();
                parameter.Event = eventName;
                process = app.controller.createProcess('SSP Generic', parameter);
                app.controller.runProcess(process, sFiles);

            end
            
        end

        % Button pushed function: AddEEGPositionsButton
        function AddEEGPositionsButtonPushed(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % Input for either Default Pattern or Import File
            choice = ["Use default pattern", "Import a file ", "Cancel"];
            rep = uiconfirm(app.AnalysisToolUIFigure, 'Import a file or use a default ?', ...
                'EEG position', 'Options', choice);
            
            switch rep
                
                % If default pattern
                case choice(1)
                    % List of available cap
                    caps = {'Colin27: BrainProducts EasyCap 128'};
                    cap = app.list_dialog('Choose your Cap!', caps);
                    
                    if isempty(cap)
                        return
                    end
                    
                    fileType = 'Use Default Pattern';
                    electrodeFile = char.empty();
                    
                % If import a file
                case choice(2)
                    % Select electrode file
                    title = "Select the electrode file (it should have the *.elc extension)";
                    extension = {'*.elc'};
                    
                    [fileNames, folder] = uigetfile(extension, title, ...
                                app.controller.getWorkingFolderPath, ...
                                'MultiSelect', 'off');
                    filePath = fullfile(fileNames, folder);
                    
                    
                    fileType = 'Import';
                    cap = char.empty();
                    electrodeFile = filePath;
                    
                % If no option selected
                otherwise
                    return
            end
            
            % Add EEG position
            parameters = struct();
            parameters.FileType = fileType;
            parameters.Cap = char(cap);
            parameters.ElectrodeFile = electrodeFile;
            process = app.controller.createProcess('Add EEG Position', parameters);
            app.controller.runProcess(process, sFiles);

        end

        % Button pushed function: RefineRegistrationButton
        function RefineRegistrationButtonPushed(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            % Refine registration
            process = app.controller.createProcess('Refine Registration');
            app.controller.runProcess(process, sFiles);

        end

        % Button pushed function: ProjectElectrodesonScalpButton
        function ProjectElectrodesonScalpButtonPushed(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            % Project electrodes on scalp
            process = app.controller.createProcess('Project Electrode On Scalp');
            app.controller.runProcess(process, sFiles);

        end

        % Button pushed function: DetectHeartbeatsButton
        function DetectHeartbeatsButtonPushed(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            % Verify if channel is in channel file
            channelName = 'ECG';
            app.controller.checkIfChannelIsInChannelFile(sFiles, channelName);
            
            % Detect cardiac artifact
            app.controller.detectCardiacArtifact(sFiles, channelName, 'cardiac')

        end

        % Button pushed function: ImportEventsButton
        function ImportEventsButtonPushed(app, event)

            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % Ask user for event .txt file
            title = "Select the event file (it should have the *.txt extension)";
            extension = {'*.txt'};
            
            [fileNames, folder] = uigetfile(extension, title, ...
                app.controller.getWorkingFolderPath, ...
                'MultiSelect', 'off');
            filePath = fullfile(fileNames, folder);
            
            % Ask user for event name
            question = 'Name of the imported events:';
            eventName = app.input_dialog(question);

            % Import events from file
            app.BasicFct.importEventFromFile(sFiles, filePath, eventName);

        end

        % Button pushed function: AverageReferenceButton
        function AverageReferenceButtonPushed(app, event)
            
            sFiles = app.appManager.selectedStudies();
            
            process = app.controller.createProcess('Average Reference');
            app.controller.runProcess(process, sFiles);
        end

        % Button pushed function: ICAEEGButton
        function ICAEEGButtonPushed(app, event)

            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % Ask user for number of components
            question = 'How many ICA components (nb electrode / 2): ';
            ICAcomponents = app.input_dialog(question);
            if isempty(ICAcomponents)
                return
            end

            % Convert input [str] to double
            nbComponents = str2double(ICAcomponents);
            
            % Apply ICA
            parameters = struct();
            parameters.NumberOfComponents = nbComponents;
            process = app.controller.createProcess('ICA', parameters);
            app.controller.runProcess(process, sFiles);

        end

        % Button pushed function: ICAMEGButton
        function ICAMEGButtonPushed(app, event)
            
            % Calls the same function that compute the ICA for EEG data.
                % It works because the button to comput ICA for MEG data is
                % only enabled when the App type is 'MEG'. When the app type is
                % 'MEG', the analyzer is MEG_Analyzer. So, it call the ICA
                % function for MEG data!

            app.ICAEEGButtonPushed();

        end

        % Button pushed function: SelectDeselectallButton
        function SelectDeselectallButtonPushed(app, event)
            
            % Select or deselect all studies from tree
            app.appManager.select_All_Studies();

        end

        % Button pushed function: UpdateTreeButton
        function UpdateTreeButtonPushed(app, event)
            
            % Update tree and check new studies
            app.appManager.update_Tree_And_Check_New_Studies();

        end

        % Button pushed function: UpdateEventsButton
        function UpdateEventsButtonPushed(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % Delete existing buttons
            delete(app.EventPanel.Children());

            % Create history or evetn buttons
            app.appManager.createEventButton(sFiles);

        end

        % Button pushed function: UpdateHistoryButton
        function UpdateHistoryButtonPushed(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % Delete existing buttons
            delete(app.HistoryPanel.Children());

            % Create history or evetn buttons
            app.appManager.createHistoryButton(sFiles);

        end

        % Button pushed function: ClearHistoryButton
        function ClearHistoryButtonPushed(app, event)
            
            % Delete existing buttons
            delete(app.HistoryPanel.Children);

        end

        % Button pushed function: ClearEventsButton
        function ClearEventsButtonPushed(app, event)
            
            % Delete existing buttons
            delete(app.EventPanel.Children);

        end

        % Button pushed function: ImportinDataBaseButton
        function ImportinDataBaseButtonPushed(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            % Get all events
            allEvents = app.appManager.BstUtil.getEvents(sFiles);

            % Ask user for the event
            eventCell = app.list_dialog('Import Events', allEvents, 'multiple');
            if isempty(eventCell)
                waitfor(errordlg("Please select at least one event"))
                return
            end    

            % Build evetn string. The function 'importEvents' takes a
            % string of event name separated by commas
            eventStr = '';
            for i = 1:length(eventCell)
                eventStr = strcat(eventStr, eventCell{i}, ',');
            end
            
            % Remove last comma
            eventStr = eventStr(1:end-1);
            
            
            % Input for Epoch Time
            question = 'Epoch Time in seconds (format -X.X ; X.X): ';
            epochTimeStr = app.input_dialog(question);
            epochTime = str2double(split(epochTimeStr, ';'));
            
            parameters = struct();
            parameters.Event = eventStr;
            parameters.Epoch = epochTime;
            % Import events for every sFile
            for i = 1:length(sFiles)
                parameters.Subject = sFiles(i).SubjectName;
                
                process = app.controller.createProcess('Import Events', parameters);
                app.controller.runProcess(process, sFiles(i));
            end
            
            % Update tree and check new studies
            app.appManager.update_Tree_And_Check_New_Studies();
            
        end

        % Button pushed function: RejectTrialsButton
        function RejectTrialsButtonPushed(app, event)

            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % Ask user for signal dectection type
            options = {'MEG gradio', 'MEG magneto', 'EEG', 'SEEG/ECOG', 'EOG', 'ECG'};
            selectedOptions = app.list_dialog('Signal Detection Type', options);
            
            if isempty(selectedOptions)
                return
            end

            MEGgrad = [];
            MEGmagneto = [];
            EEG = [];
            SEEG_ECOG = [];
            EOG = [];
            ECG = [];
            
            % If MEG gradio is selected
            if any(strcmp(options{1}, selectedOptions))
                
                question = 'MEG gradio minimum (fT): ';
                MEGgradMin = app.input_dialog(question);
    
                question = 'MEG gradio maximum (fT): ';
                MEGgradMax = app.input_dialog(question);
                
                MEGgrad = [MEGgradMin MEGgradMax];
            end
            
            % If MEG magneto is selected
            if (strcmp(options{2}, selectedOptions))
                
                question = 'MEG magneto minimum (fT): ';
                MEGmagnetoMin = app.input_dialog(question);
                
                question = 'MEG magneto maximum (fT): ';
                MEGmagnetoMax = app.input_dialog(question);
                
                MEGmagneto = [MEGmagnetoMin MEGmagnetoMax];
                
            end
            
            % If EEG is selected
            if (strcmp(options{3}, selectedOptions))
                
                question = 'EEG minimum (µV): ';
                EEGmin = app.input_dialog(question);
                
                question = 'EEG maximum (µV): ';
                EEGmax = app.input_dialog(question);
                
                EEG = [EEGmin EEGmax];
                
            end
            
            % If SEEG/ECOG is selected
            if (strcmp(options{4}, selectedOptions))
                
                question = 'SEEG/ECOG minimum (µV): ';
                SEEG_ECOGmin = app.input_dialog(question);
                
                question = 'SEEG/ECOG maximum (µV): ';
                SEEG_ECOGmax = app.input_dialog(question);
                
                SEEG_ECOG = [SEEG_ECOGmin SEEG_ECOGmax];
                
            end
            
            % If EOG is selected
            if (strcmp(options{5}, selectedOptions))
                
                question = 'EOG minimum (µV): ';
                EOGmin = app.input_dialog(question);
                
                question = 'EOG maximum (µV): ';
                EOGmax = app.input_dialog(question);
                
                EOG = [EOGmin EOGmax];
                
            end
            
            % If ECG is selected
            if (strcmp(options{6}, selectedOptions))
                
                question = 'ECG minimum (mV): ';
                ECGmin = app.input_dialog(question);
                
                question = 'ECG maximum (mV): ';
                ECGmax = app.input_dialog(question);
                
                ECG = [ECGmin ECGmax];
                 
            end
            
            % Reject bad trials
            parameters = struct();
            parameters.MEGGrad = MEGgrad;
            parameters.MEGGrad = MEGmagneto;
            parameters.MEGGrad = EEG;
            parameters.MEGGrad = SEEG_ECOG;
            parameters.MEGGrad = EOG;
            parameters.MEGGrad = ECG;
            
            process = app.controller.createProcess('Reject Bad Trials', parameters);
            app.controller.runProcess(process, sFiles);

        end

        % Button pushed function: ComputeSourcesButton
        function ComputeSourcesButtonPushed(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            % Compute sources
            parameters = struct();
            parameters.ToRun = true;
            process = app.controller.createProcess('Compute Sources', parameters);
            app.controller.runProcess(process, sFiles);

        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            % Ask user for average type
            averageTypes = ["Everything", "By Subject", "By Folder (subject average)", ...
                            "By Folder (grand average)", "By Trial Group (folder average)", ...
                            "By Trial Group (subject average)", "By Trial Group (grand average)"];
            
            averageType = app.list_dialog('Choose Type', averageTypes);
            
            if isempty(averageType)
                return
            end
            
            % Ask user for function type
            functionTypes = ["Arithmetic Average", "Average Absolute Values", "Root Mean Square", ...
                            "Standard Deviation", "Standard Error", ...
                            "Arithmetic Average + Standard Deviation", ...
                            "Arithmetic Average + Standard Error", "Median"];

            functionType = app.list_dialog('Choose type', functionTypes);
            
            if isempty(functionType)
                return
            end
            
            % Apply average
            parameters = struct();
            parameters.AverageType = averageType;
            parameters.AverageFuntion = functionsType;
            process = app.controller.createProcess('Average', parameters);
            app.controller.runProcess(process, sFiles);
            
        end

        % Menu selected function: CreateProtocolMenu
        function CreateProtocolMenuSelected(app, event)
            
            % Input for protocol name
            ProtocolName = app.input_dialog('Protocol Name');
            
            % Do nothing if protocol name is empty
            if isempty(ProtocolName)
                return
            end
            
            % If protocol with same name has already been created
            allProtocols = app.controller.getAllProtocols;
            if (any(strcmp(allProtocols, ProtocolName)))
                message = 'Cannot create Protocol as another Protocol has the same name';
                title = 'Cannot create Protocol';
                waitfor(uiconfirm(app.AnalysisToolUIFigure, message, title));
                return
            end
            
            % Create protocol
            app.controller.createProtocol(ProtocolName);
            
            % Update tree and load current protocol (it refreshes the
            % brainstorm interface)
            app.appManager.update_Tree();
            app.ReloadCurrentProtocolMenuSelected;

        end

        % Menu selected function: ReloadCurrentProtocolMenu
        function ReloadCurrentProtocolMenuSelected(app, event)
            
            % Reload current protocol
            db_reload_database(bst_get('iProtocol'));

        end

        % Menu selected function: LoadProtocolMenu
        function LoadProtocolMenuSelected(app, event)
            
            % Get all protocols
            allProtocols = app.controller.getAllProtocols;

            % Ask user to select protocol
            question = 'Choose Protocol to Load';
            protocolName = app.list_dialog(question, allProtocols);
            
            if isempty(protocolName)
                return
            end

            % Get protocol's index
            protocolIndex = app.controller.getProtocolIndex(protocolName);
            
            % Set protocol
            app.controller.setProtocol(protocolIndex);
            
            % Update tree
            app.appManager.update_Tree();

        end

        % Menu selected function: DeleteProtocolMenu
        function DeleteProtocolMenuSelected(app, event)
            
            % get all protocols
            allProtocols = app.controller.getAllProtocols;
            
            % Ask user to select protocol
            question = 'Protocol to delete';
            protocolName = app.list_dialog(question, allProtocols, 'multiple');
            
            if isempty(protocolName)
                return
            end
            
            % Confirm protocol to delete
            message = "You're about to delete the following protocol: ";
            for i = 1:length(protocolName)
                if i ~= 1
                    message = strcat(message, ', ');
                end
                message = strcat(message, protocolName{i});
            end
            selection = uiconfirm(app.AnalysisToolUIFigure, message, "Protocol Delete");
            
            % Delete protocol
            if selection == "OK"
                for i = 1:length(protocolName)
                    app.controller.deleteProtocol(protocolName{i})
                end
                uiconfirm(app.AnalysisToolUIFigure, "Protocol has been deleted", "Protocol Delete");         
            end

            % Update tree
            app.appManager.update_Tree();

        end

        % Menu selected function: CreateSubjectMenu
        function CreateSubjectMenuSelected(app, event)
            
            % Input for Subject Name
            question = 'Subject Name: ';
            subjectName = app.input_dialog(question);
            
            if isempty(subjectName)
                return
            end
            
            % Input for type of Anatomy
            choice = ["Specify anatomy folder", "Use default anatomy", "Cancel"];
            rep = uiconfirm(app.AnalysisToolUIFigure, 'Do you have a specific anatomy folder for your subjet or do you want to use the default anatomy ?', ...
                'Create Subject', 'Options', choice);
            
            switch rep

                % If specific anatomy
                case choice(1)  
                    title = "Choose the anatomy folder";
                    AnatomyPath = uigetdir(pwd, title);

                    if isequal(AnatomyPath, 0)
                        return
                    end
                    AnatomyPath = replace(AnatomyPath, '\', '/');

                    app.BasicFct.importAnatomy(subjectName, AnatomyPath, app.controller.getAnatomyFileFormat);
                    
                % If default anatomy
                case choice(2)
                    db_add_subject(subjectName, [], 1, 0);

                % If cancel
                case choice(3)
                    return

            end

            % Update tree
            app.appManager.update_Tree();

        end

        % Menu selected function: DeleteSubjectMenu
        function DeleteSubjectMenuSelected(app, event)
            
            % A REVOIR!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
            %AVEC FONCTION JUSTE EN HAUT!

            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % Get only the subjects selected
            subjects = unique({sFiles.SubjectName});
            
            message = "You're about to delete the following subject: ";
            for i = 1:length(subjects)
                if i ~= 1
                    message = message + ', ';
                end
                message = message + subjects{i};
            end
            rep = uiconfirm(app.AnalysisToolUIFigure, message, 'Subject to delete');
            
            if rep == "OK"
                del = 3;
                bst_process('CallProcess', 'process_delete', sFiles, [], ...
                'target', del); % 1: fichier 2: folder (recommended) 3: subjects
            end
                
            % Update tree
            app.appManager.update_Tree();

        end

        % Menu selected function: ReviewRawFilesMenu
        function ReviewRawFilesMenuSelected(app, event)
            
            % Get subjects in protocol
            protoSubjects = bst_get('ProtocolSubjects');
            
            % List of all Subjects
            subjects = cell(1, length(protoSubjects.Subject));
            for i = 1:length(protoSubjects.Subject)
                subjects{i} = protoSubjects.Subject(i).Name;
            end
            
            % Select Subject associated with the dataset
            subjectName = char(app.list_dialog('Select Subject', subjects));
            
            if isempty(subjectName)
                return
            end
            
            % Ask user for for path to dataset
            rawFilesPath = app.appManager.selectFilesToReviewRawFiles();
            
            % review raw files
            parameters = struct();
            parameters.Subjects = {subjectName};
            parameters.RawFiles = {rawFilesPath};
            process = app.controller.createProcess('Review Raw Files', parameters);
            app.controller.runProcess(process);

            % Update tree and check new studies
            app.appManager.update_Tree_And_Check_New_Studies();

        end

        % Menu selected function: DeleteStudyMenu
        function DeleteStudyMenuSelected(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % Get only the studies selected (?)
            studies = unique({sFiles.Condition});
            
            % Confirm the studies to delete
            message = "You're about to delete the following studies: ";
            for i = 1:length(studies)
                if i ~= 1
                    message = message + ', ';
                end
                message = message + studies{i};
            end
            
            rep = uiconfirm(app.AnalysisToolUIFigure, message, 'Subject to delete');
            
            % Delete studies
            if rep == "OK"
                app.controller.deleteStudy(sFiles);
            end
            
            % Update tree
            app.appManager.update_Tree();

        end

        % Menu selected function: RenameEventMenu
        function RenameEventMenuSelected(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % Get all events
            events = app.controller.getEvents(sFiles);

            % Ask user to select event to rename
            question = 'Chose the Event to rename';
            oldName = app.list_dialog(question, events);
            
            if isempty(oldName)
                return
            end

            % Input for New Name
            question = 'Enter the new Event Name';
            newName = app.input_dialog(question);

            if isempty(newName)
                waitfor(errordlg("Please enter a new event name."))
                return
            end
            
            % Rename Event
            app.controller.renameEvent(sFiles, oldName, newName);

        end

        % Menu selected function: DeleteEventMenu
        function DeleteEventMenuSelected(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % Get all Events
            events = app.controller.getEvents(sFiles);

            % Ask user to select event
            question = 'Select the Event to delete:';
            eventToDelete = app.list_dialog(question, events, 'n');
            
            if isempty(eventToDelete)
                msgbox("Missing event to delete.")
                return
            end

            % Delete Events
            for i = 1:length(eventToDelete)
                app.controller.deleteEvent(sFiles, eventToDelete{i});
            end

        end

        % Menu selected function: ExporttoEDFMenu
        function ExporttoEDFMenuSelected(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            % Ask user folder to save EDF file
            path = uigetdir(pwd, "Where do you want to save the EDF file");
            
            if isequal(path, 0)
                return
            end
            path = replace(path, '\', '/');
            
            % Export to EDF
            for i = 1:length(sFiles)
                app.controller.exportData(sFiles(i), path, '.edf');
            end

        end

        % Menu selected function: ExporttoBrainVisionMenu
        function ExporttoBrainVisionMenuSelected(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            % Ask user folder to save BrainVision file
            path = uigetdir(pwd, "Where do you want to save the BrainVision file");
            
            if isequal(path, 0)
                return
            end
            path = replace(path, '\', '/');
            
            % Export to BrainVision
            for i = 1:length(sFiles)
                app.controller.exportData(sFiles(i), path, '.eeg');
            end
            
        end

        % Menu selected function: ExporttoBIDSMenu
        function ExporttoBIDSMenuSelected(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            % Input for BIDS folder
            question = 'Name of BIDS folder';
            BidsFolderName = app.input_dialog(question);
            
            if isempty(BidsFolderName)
                return
            end
            
            % Ask user folder to save BIDS
            path = uigetdir(pwd, "Where do you want to save the BIDS folder");
            
            if isequal(path, 0)
                return
            end
            path = replace(path, '\', '/');
            
            % Build BIDS path
            bidsPath = fullfile(path, BidsFolderName);
            
            % Export to BIDS
            parameters = struct();
            parameters.Folder = bidsPath;
            process = app.controller.createProcess('Export To BIDS', parameters);
            app.controller.runProcess(process, sFiles);

        end

        % Menu selected function: OpenBrainstormMenu
        function OpenBrainstormMenuSelected(app, event)
            
            if ~isdeployed()
                brainstorm;
            end

        end

        % Menu selected function: AddTagMenu
        function AddTagMenuSelected(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();
            
            % Input for tag
            question = 'Tag name to add: ';
            tag = app.input_dialog(question);
            
            if isempty(Tag)
                return
            end

            % Ask user for type of tag
            choice = ["Add Tag to the file Name", "Add tag to the file path", "Cancel"];

            rep = uiconfirm(app.AnalysisToolUIFigure, 'where do you want to add the tag ?', ...
                'Add Tag', 'Options', choice);

            switch rep

                % If tag to file name
                case choice(1)
                    output = 1;

                % If tag to file path
                case choice(2)
                    output = 2;

                % If cancel
                case choice(3)
                    return 

            end
               
            % Add tag
            bst_process('CallProcess', 'process_add_tag', sFiles, [], ...
                    'tag',           tag, ...
                    'output',        output); 

        end

        % Menu selected function: ColorModeMenu
        function ColorModeMenuSelected(app, event)
            
            % If in dark mode
            if app.ColorModeMenu.Text == "Dark Mode"
                backColor = [0 0 0];
                textColor = [1 1 1];
                textColorHyperLink = [0 0.8 1];
                app.ColorModeMenu.Text = "Bright Mode";
                app.CervoLogo.ImageSource = 'CERVO_Dark.png';
                app.BrainCanadaLogo.ImageSource = 'BrainCanada_Dark.png';
                app.UlavalLogo.ImageSource = 'Ulaval_Dark.png';

            % If in bright mode
            elseif app.ColorModeMenu.Text == "Bright Mode"
                backColor = [1 1 1];
                textColor = [0 0 0];
                textColorHyperLink = [0 0.4 0.8];
                app.ColorModeMenu.Text = "Dark Mode";            
                app.CervoLogo.ImageSource = 'CERVO_Bright.png';
                app.BrainCanadaLogo.ImageSource = 'BrainCanada_Bright.png';
                app.UlavalLogo.ImageSource = 'Ulaval_Bright.png';

            end

            % Switch color mode
            app.appManager.switchBackgroundColor(backColor, textColor, textColorHyperLink);
            
        end

        % Menu selected function: SelectComponentsMenu
        function SelectComponentsMenuSelected(app, event)
            
            % Select checked studies
            sFiles = app.appManager.selectedStudies();

            % View components
            app.controller.viewComponents(sFiles);

        end

        % Selection change function: TabGroup1
        function TabGroup1SelectionChanged(app, event)
            
            % Switch analysis type
            app.appManager.switch_Analysis_Type(app.TabGroup1.SelectedTab.Title);

        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
           
            % Switch analysis type
            app.appManager.switch_Analysis_Type(app.TabGroup2.SelectedTab.Title);

        end

        % Menu selected function: EEGMenu
        function EEGMenuSelected(app, event)
            
            % Switch analysis type
            app.appManager.switch_Analysis_Type('EEG');

        end

        % Menu selected function: MEGMenu
        function MEGMenuSelected(app, event)
            
            % Switch analysis type
            app.appManager.switch_Analysis_Type('MEG');
            
        end

        % Button pushed function: SelectImportedButton
        function SelectImportedButtonPushed(app, event)
            
            % Select imported studies
            app.appManager.selectImportedStudies();

            
        end

        % Button pushed function: SelectRawButton
        function SelectRawButtonPushed(app, event)
            
            % Select raw studies
            app.appManager.selectRawStudies();

        end

        % Button pushed function: CollapseButton
        function CollapseButtonPushed(app, event)
            
            % Collapse tree
            app.Tree.collapse;

        end

        % Button pushed function: ExpandButton
        function ExpandButtonPushed(app, event)
            
            % Expand tree
            app.Tree.expand;

        end

        % Close request function: AnalysisToolUIFigure
        function AnalysisToolUIFigureCloseRequest(app, event)

            % Confirm user wants to close the app
            rep = uiconfirm(app.AnalysisToolUIFigure, 'Do you want to close the app ?', 'Close request');
            
            if rep == "OK"

                % Remove paths
                %app.controller.removePaths();

                % Delete app
                delete(app)

            end

        end

        % Callback function
        function TestBrainstormCompileButtonPushed(app, event)
            
            a = bst_get('UserDir');
            b = bst_get('ProtocolInfo');
            
            waitfor(msgbox(strcat('Le User directory de Brainsotrm est: '), a));
            waitfor(msgbox(strcat('Le protocol actuellement sélectionné est: '), b.Comment))
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalysisToolUIFigure = uifigure('Visible', 'off');
            app.AnalysisToolUIFigure.Name = 'Analysis Tool';
            app.AnalysisToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @AnalysisToolUIFigureCloseRequest, true);
            app.ProtocolMenu = uimenu(app.AnalysisToolUIFigure);
            app.ProtocolMenu.Text = 'Protocol';
            app.CreateProtocolMenu = uimenu(app.ProtocolMenu);
            app.CreateProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateProtocolMenuSelected, true);
            app.CreateProtocolMenu.Text = 'Create Protocol';
            app.ReloadCurrentProtocolMenu = uimenu(app.ProtocolMenu);
            app.ReloadCurrentProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloadCurrentProtocolMenuSelected, true);
            app.ReloadCurrentProtocolMenu.Text = 'Reload Current Protocol';
            app.LoadProtocolMenu = uimenu(app.ProtocolMenu);
            app.LoadProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProtocolMenuSelected, true);
            app.LoadProtocolMenu.Text = 'Load Protocol';
            app.DeleteProtocolMenu = uimenu(app.ProtocolMenu);
            app.DeleteProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteProtocolMenuSelected, true);
            app.DeleteProtocolMenu.Text = 'Delete Protocol';
            app.SubjectMenu = uimenu(app.AnalysisToolUIFigure);
            app.SubjectMenu.Text = 'Subject';
            app.CreateSubjectMenu = uimenu(app.SubjectMenu);
            app.CreateSubjectMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateSubjectMenuSelected, true);
            app.CreateSubjectMenu.Text = 'Create Subject';
            app.DeleteSubjectMenu = uimenu(app.SubjectMenu);
            app.DeleteSubjectMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteSubjectMenuSelected, true);
            app.DeleteSubjectMenu.Text = 'Delete Subject';
            app.StudyMenu = uimenu(app.AnalysisToolUIFigure);
            app.StudyMenu.Text = 'Study';
            app.ReviewRawFilesMenu = uimenu(app.StudyMenu);
            app.ReviewRawFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ReviewRawFilesMenuSelected, true);
            app.ReviewRawFilesMenu.Text = 'Review Raw Files';
            app.SelectComponentsMenu = uimenu(app.StudyMenu);
            app.SelectComponentsMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectComponentsMenuSelected, true);
            app.SelectComponentsMenu.Text = 'Select Components';
            app.DeleteStudyMenu = uimenu(app.StudyMenu);
            app.DeleteStudyMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteStudyMenuSelected, true);
            app.DeleteStudyMenu.Text = 'Delete Study';
            app.EventMenu = uimenu(app.AnalysisToolUIFigure);
            app.EventMenu.Text = 'Event';
            app.RenameEventMenu = uimenu(app.EventMenu);
            app.RenameEventMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameEventMenuSelected, true);
            app.RenameEventMenu.Text = 'Rename Event';
            app.DeleteEventMenu = uimenu(app.EventMenu);
            app.DeleteEventMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteEventMenuSelected, true);
            app.DeleteEventMenu.Text = 'Delete Event';
            app.ConversionMenu = uimenu(app.AnalysisToolUIFigure);
            app.ConversionMenu.Text = 'Conversion';
            app.ExporttoEDFMenu = uimenu(app.ConversionMenu);
            app.ExporttoEDFMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoEDFMenuSelected, true);
            app.ExporttoEDFMenu.Text = 'Export to EDF';
            app.ExporttoBrainVisionMenu = uimenu(app.ConversionMenu);
            app.ExporttoBrainVisionMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoBrainVisionMenuSelected, true);
            app.ExporttoBrainVisionMenu.Text = 'Export to BrainVision';
            app.ExporttoBIDSMenu = uimenu(app.ConversionMenu);
            app.ExporttoBIDSMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoBIDSMenuSelected, true);
            app.ExporttoBIDSMenu.Text = 'Export to BIDS';
            app.OptionsMenu = uimenu(app.AnalysisToolUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.AnalysisTypeMenu = uimenu(app.OptionsMenu);
            app.AnalysisTypeMenu.Text = 'Analysis Type';
            app.EEGMenu = uimenu(app.AnalysisTypeMenu);
            app.EEGMenu.MenuSelectedFcn = createCallbackFcn(app, @EEGMenuSelected, true);
            app.EEGMenu.Separator = 'on';
            app.EEGMenu.Text = 'EEG';
            app.MEGMenu = uimenu(app.AnalysisTypeMenu);
            app.MEGMenu.MenuSelectedFcn = createCallbackFcn(app, @MEGMenuSelected, true);
            app.MEGMenu.Checked = 'on';
            app.MEGMenu.Text = 'MEG';
            app.OpenBrainstormMenu = uimenu(app.OptionsMenu);
            app.OpenBrainstormMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenBrainstormMenuSelected, true);
            app.OpenBrainstormMenu.Text = 'Open Brainstorm';
            app.AddTagMenu = uimenu(app.OptionsMenu);
            app.AddTagMenu.MenuSelectedFcn = createCallbackFcn(app, @AddTagMenuSelected, true);
            app.AddTagMenu.Text = 'Add Tag';
            app.ColorModeMenu = uimenu(app.OptionsMenu);
            app.ColorModeMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorModeMenuSelected, true);
            app.ColorModeMenu.Text = 'Dark Mode';
            app.TabGroup2 = uitabgroup(app.AnalysisToolUIFigure);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.EEGTabGr2 = uitab(app.TabGroup2);
            app.EEGTabGr2.Title = 'EEG';
            app.AverageReferenceButton = uibutton(app.EEGTabGr2, 'push');
            app.AverageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AverageReferenceButtonPushed, true);
            app.AverageReferenceButton.Text = 'Average Reference';
            app.ICAEEGButton = uibutton(app.EEGTabGr2, 'push');
            app.ICAEEGButton.ButtonPushedFcn = createCallbackFcn(app, @ICAEEGButtonPushed, true);
            app.ICAEEGButton.Text = 'ICA';
            app.MEGTabGr2 = uitab(app.TabGroup2);
            app.MEGTabGr2.Title = 'MEG';
            app.RemoveSimultaneousEventsButton = uibutton(app.MEGTabGr2, 'push');
            app.RemoveSimultaneousEventsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSimultaneousEventsButtonPushed, true);
            app.RemoveSimultaneousEventsButton.Text = 'Remove Simultaneous Events';
            app.DetectArtifactButton = uibutton(app.MEGTabGr2, 'push');
            app.DetectArtifactButton.ButtonPushedFcn = createCallbackFcn(app, @DetectArtifactButtonPushed, true);
            app.DetectArtifactButton.Text = 'Detect Artifact';
            app.SSPButton = uibutton(app.MEGTabGr2, 'push');
            app.SSPButton.ButtonPushedFcn = createCallbackFcn(app, @SSPButtonPushed, true);
            app.SSPButton.Text = 'SSP';
            app.ICAMEGButton = uibutton(app.MEGTabGr2, 'push');
            app.ICAMEGButton.ButtonPushedFcn = createCallbackFcn(app, @ICAMEGButtonPushed, true);
            app.ICAMEGButton.Text = 'ICA';
            app.TabGroup1 = uitabgroup(app.AnalysisToolUIFigure);
            app.TabGroup1.SelectionChangedFcn = createCallbackFcn(app, @TabGroup1SelectionChanged, true);
            app.EEGTabGr1 = uitab(app.TabGroup1);
            app.EEGTabGr1.Title = 'EEG';
            app.AddEEGPositionsButton = uibutton(app.EEGTabGr1, 'push');
            app.AddEEGPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @AddEEGPositionsButtonPushed, true);
            app.AddEEGPositionsButton.Text = 'Add EEG Positions';
            app.RefineRegistrationButton = uibutton(app.EEGTabGr1, 'push');
            app.RefineRegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RefineRegistrationButtonPushed, true);
            app.RefineRegistrationButton.Text = 'Refine Registration';
            app.ProjectElectrodesonScalpButton = uibutton(app.EEGTabGr1, 'push');
            app.ProjectElectrodesonScalpButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectElectrodesonScalpButtonPushed, true);
            app.ProjectElectrodesonScalpButton.Text = 'Project Electrodes on Scalp';
            app.DetectHeartbeatsButton = uibutton(app.EEGTabGr1, 'push');
            app.DetectHeartbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectHeartbeatsButtonPushed, true);
            app.DetectHeartbeatsButton.Text = 'Detect Heartbeats';
            app.ImportEventsButton = uibutton(app.EEGTabGr1, 'push');
            app.ImportEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEventsButtonPushed, true);
            app.ImportEventsButton.Text = 'Import Events';
            app.MEGTabGr1 = uitab(app.TabGroup1);
            app.MEGTabGr1.Title = 'MEG';
            app.ConvertEpochstoContinueButton = uibutton(app.MEGTabGr1, 'push');
            app.ConvertEpochstoContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertEpochstoContinueButtonPushed, true);
            app.ConvertEpochstoContinueButton.Text = 'Convert Epochs to Continue';
            app.FrequencyPanel = uipanel(app.AnalysisToolUIFigure);
            app.FrequencyPanel.Title = 'FREQUENCY';
            app.NotchFilterButton = uibutton(app.FrequencyPanel, 'push');
            app.NotchFilterButton.ButtonPushedFcn = createCallbackFcn(app, @NotchFilterButtonPushed, true);
            app.NotchFilterButton.Text = 'Notch Filter';
            app.BandpassFilterButton = uibutton(app.FrequencyPanel, 'push');
            app.BandpassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BandpassFilterButtonPushed, true);
            app.BandpassFilterButton.Text = 'Band-pass Filter';
            app.PowerSpectrumDensityButton = uibutton(app.FrequencyPanel, 'push');
            app.PowerSpectrumDensityButton.ButtonPushedFcn = createCallbackFcn(app, @PowerSpectrumDensityButtonPushed, true);
            app.PowerSpectrumDensityButton.Text = 'Power Spectrum Density';
            app.HistoryPanel = uipanel(app.AnalysisToolUIFigure);
            app.HistoryPanel.Title = 'HISTORY';
            app.EventPanel = uipanel(app.AnalysisToolUIFigure);
            app.EventPanel.Title = 'EVENTS';
            app.PipelineTextArea = uitextarea(app.AnalysisToolUIFigure);
            app.ClearHistoryButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.ClearHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearHistoryButtonPushed, true);
            app.ClearHistoryButton.Text = 'Clear History';
            app.ClearEventsButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.ClearEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearEventsButtonPushed, true);
            app.ClearEventsButton.Text = 'Clear Events';
            app.UpdateHistoryButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.UpdateHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateHistoryButtonPushed, true);
            app.UpdateHistoryButton.Text = 'Update History';
            app.UpdateEventsButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.UpdateEventsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEventsButtonPushed, true);
            app.UpdateEventsButton.Text = 'Update Events';
            app.PipelinePanel = uipanel(app.AnalysisToolUIFigure);
            app.PipelinePanel.Title = 'PIPELINE';
            app.OpenPipelineBuilderButton = uibutton(app.PipelinePanel, 'push');
            app.OpenPipelineBuilderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPipelineBuilderButtonPushed, true);
            app.OpenPipelineBuilderButton.Text = 'Open Pipeline Builder';
            app.ClearPipelineButton = uibutton(app.PipelinePanel, 'push');
            app.ClearPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPipelineButtonPushed, true);
            app.ClearPipelineButton.Text = 'Clear Pipeline';
            app.ImportPipelineButton = uibutton(app.PipelinePanel, 'push');
            app.ImportPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPipelineButtonPushed, true);
            app.ImportPipelineButton.Text = 'Import Pipeline';
            app.RunPipelineButton = uibutton(app.PipelinePanel, 'push');
            app.RunPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @RunPipelineButtonPushed, true);
            app.RunPipelineButton.Text = 'Run Pipeline';
            app.Tree = uitree(app.AnalysisToolUIFigure, 'checkbox');
            app.GitHubLink = uihyperlink(app.AnalysisToolUIFigure);
            app.GitHubLink.URL = 'https://github.com/CorentinLabelle/EEG-MEG-Analysis-Tool';
            app.GitHubLink.Text = 'GitHub/CorentinLabelle/AnalysisTool';
            app.BrainstormMegTutorialLink = uihyperlink(app.AnalysisToolUIFigure);
            app.BrainstormMegTutorialLink.URL = 'https://neuroimage.usc.edu/brainstorm/Tutorials';
            app.BrainstormMegTutorialLink.Text = 'Brainstorm MEG Tutorial';
            app.BrainstormEegTutorialLink = uihyperlink(app.AnalysisToolUIFigure);
            app.BrainstormEegTutorialLink.URL = 'https://neuroimage.usc.edu/brainstorm/Tutorials/Epilepsy';
            app.BrainstormEegTutorialLink.Text = 'Brainstorm EEG Tutorial';
            app.ImportinDataBaseButton = uibutton(app.AnalysisToolUIFigure, 'push');
            app.ImportinDataBaseButton.ButtonPushedFcn = createCallbackFcn(app, @ImportinDataBaseButtonPushed, true);
            app.ImportinDataBaseButton.Text = 'Import in DataBase';
            app.PostProcessPanel = uipanel(app.AnalysisToolUIFigure);
            app.PostProcessPanel.Title = 'POST-PROCESS';
            app.RejectTrialsButton = uibutton(app.PostProcessPanel, 'push');
            app.RejectTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @RejectTrialsButtonPushed, true);
            app.RejectTrialsButton.Text = 'Reject Trials';
            app.AverageButton = uibutton(app.PostProcessPanel, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.ComputeSourcesButton = uibutton(app.PostProcessPanel, 'push');
            app.ComputeSourcesButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeSourcesButtonPushed, true);
            app.ComputeSourcesButton.Text = 'Compute Sources';
            app.ProtocolPanel = uipanel(app.AnalysisToolUIFigure);
            app.ProtocolPanel.Title = 'PROTOCOL';
            app.SelectDeselectallButton = uibutton(app.ProtocolPanel, 'push');
            app.SelectDeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDeselectallButtonPushed, true);
            app.SelectDeselectallButton.Text = 'Select/Deselect all';
            app.UpdateTreeButton = uibutton(app.ProtocolPanel, 'push');
            app.UpdateTreeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTreeButtonPushed, true);
            app.UpdateTreeButton.Text = 'Update Tree';
            app.CurrentProtocolEditField = uieditfield(app.ProtocolPanel, 'text');
            app.CurrentProtocolEditField.Tag = 'CurrentProto';
            app.CurrentProtocolEditField.Editable = 'off';
            app.CurrentProtocolLabel = uilabel(app.ProtocolPanel);
            app.CurrentProtocolLabel.Text = 'Current Protocol:';
            app.SelectImportedButton = uibutton(app.ProtocolPanel, 'push');
            app.SelectImportedButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImportedButtonPushed, true);
            app.SelectImportedButton.Text = 'Select Imported';
            app.SelectRawButton = uibutton(app.ProtocolPanel, 'push');
            app.SelectRawButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRawButtonPushed, true);
            app.SelectRawButton.Text = 'Select Raw';
            app.CollapseButton = uibutton(app.ProtocolPanel, 'push');
            app.CollapseButton.ButtonPushedFcn = createCallbackFcn(app, @CollapseButtonPushed, true);
            app.CollapseButton.Text = 'Collapse';
            app.ExpandButton = uibutton(app.ProtocolPanel, 'push');
            app.ExpandButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandButtonPushed, true);
            app.ExpandButton.Text = 'Expand';
            app.CervoLogo = uiimage(app.AnalysisToolUIFigure);
            app.BrainCanadaLogo = uiimage(app.AnalysisToolUIFigure);
            app.UlavalLogo = uiimage(app.AnalysisToolUIFigure);
            app.EegNetLogo = uiimage(app.AnalysisToolUIFigure);
            app.EeglabLogo = uiimage(app.AnalysisToolUIFigure);
            app.BrainstormLogo = uiimage(app.AnalysisToolUIFigure);
            app.ReportsPanel = uipanel(app.AnalysisToolUIFigure);
            app.ReportsPanel.Title = 'Reports';
            % Show the figure after all components are created
            app.AnalysisToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis_Tool(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AnalysisToolUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AnalysisToolUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisToolUIFigure)
        end
    end
end