classdef Pipeline_Builder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PipelineBuilderUIFigure         matlab.ui.Figure
        PSDPanel                        matlab.ui.container.Panel
        WindowLengthEditField           matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel      matlab.ui.control.Label
        ReviewRawFilesPanel             matlab.ui.container.Panel
        ClearSubjectsButton             matlab.ui.control.Button
        DeleteSubjectButton             matlab.ui.control.Button
        AddSubjectButton                matlab.ui.control.Button
        ReviewRawFilesTextArea          matlab.ui.control.TextArea
        BIDSPanel                       matlab.ui.container.Panel
        BrowseBIDSButton                matlab.ui.control.Button
        BIDSFolderPathEditField         matlab.ui.control.EditField
        BIDSFolderPathEditFieldLabel    matlab.ui.control.Label
        BIDSFolderNameEditField         matlab.ui.control.EditField
        BIDSFolderNameEditFieldLabel    matlab.ui.control.Label
        PipelinePanel                   matlab.ui.container.Panel
        ClearPipelineButton             matlab.ui.control.Button
        ImportPipelineButton            matlab.ui.control.Button
        SavePipelineButton              matlab.ui.control.Button
        RemoveSimultaneousEventPanel    matlab.ui.container.Panel
        TargetEventEditField            matlab.ui.control.EditField
        TargetEventEditFieldLabel       matlab.ui.control.Label
        EventtoRemoveEditField          matlab.ui.control.EditField
        EventtoRemoveEditFieldLabel     matlab.ui.control.Label
        ChooseCapButtonGroup            matlab.ui.container.ButtonGroup
        Colin27BrainProductsEasyCap64Button  matlab.ui.control.RadioButton
        Colin27BrainProductsEasyCap128Button  matlab.ui.control.RadioButton
        TypeofArtifactPanel             matlab.ui.container.Panel
        HighFrequencymusclesensorCheckBox  matlab.ui.control.CheckBox
        LowFrequencyeyeteethCheckBox    matlab.ui.control.CheckBox
        BlinkCheckBox                   matlab.ui.control.CheckBox
        CardiacCheckBox                 matlab.ui.control.CheckBox
        EventNameSSPEditField           matlab.ui.control.EditField
        EventNameSSPEditFieldLabel      matlab.ui.control.Label
        MEGProcessesPanel               matlab.ui.container.Panel
        MEGReviewRawFilesCheckBox       matlab.ui.control.CheckBox
        ConverttoBIDSMEGCheckBox        matlab.ui.control.CheckBox
        ICAMEGCheckBox                  matlab.ui.control.CheckBox
        RemoveSimultaneousEventCheckBox  matlab.ui.control.CheckBox
        SSPCheckBox                     matlab.ui.control.CheckBox
        DetectArtifactCheckBox          matlab.ui.control.CheckBox
        PowerSpectrumDensityMEGCheckBox  matlab.ui.control.CheckBox
        BandPassFilterMEGCheckBox       matlab.ui.control.CheckBox
        ConvertEpochtoContinueCheckBox  matlab.ui.control.CheckBox
        NotchFilterMEGCheckBox          matlab.ui.control.CheckBox
        EEGProcessesPanel               matlab.ui.container.Panel
        EEGReviewRawFilesCheckBox       matlab.ui.control.CheckBox
        ConverttoBIDSEEGCheckBox        matlab.ui.control.CheckBox
        ICAEEGCheckBox                  matlab.ui.control.CheckBox
        AverageReferenceCheckBox        matlab.ui.control.CheckBox
        PowerSpectrumDensityEEGCheckBox  matlab.ui.control.CheckBox
        BandPassFilterEEGCheckBox       matlab.ui.control.CheckBox
        NotchFilterEEGCheckBox          matlab.ui.control.CheckBox
        ProjectElectrodeonScalpCheckBox  matlab.ui.control.CheckBox
        RefineRegistrationCheckBox      matlab.ui.control.CheckBox
        AddEEGPositionCheckBox          matlab.ui.control.CheckBox
        NumberofComponentsICAEditField  matlab.ui.control.NumericEditField
        NumberofComponentsICAEditFieldLabel  matlab.ui.control.Label
        PipelineTypeDropDown            matlab.ui.control.DropDown
        PipelineTypeDropDownLabel       matlab.ui.control.Label
        AddEEGPositionGroup             matlab.ui.container.ButtonGroup
        ImportFileButton                matlab.ui.control.RadioButton
        UseDefaultPatternButton         matlab.ui.control.RadioButton
        BandPassFrequencesPanel         matlab.ui.container.Panel
        HighCutOffHzEditField           matlab.ui.control.NumericEditField
        HighCutOffHzEditFieldLabel      matlab.ui.control.Label
        LowCutOffHzEditField            matlab.ui.control.NumericEditField
        LowCutOffHzEditFieldLabel       matlab.ui.control.Label
        NotchGroup                      matlab.ui.container.ButtonGroup
        Frequence                       matlab.ui.control.TextArea
        SpecificFrequenceButton         matlab.ui.control.RadioButton
        EuropeButton                    matlab.ui.control.RadioButton
        NorthAmericaButton              matlab.ui.control.RadioButton
        BrowsePipelineButton            matlab.ui.control.Button
        SaveinFolderEditField           matlab.ui.control.EditField
        SaveinFolderEditFieldLabel      matlab.ui.control.Label
        PipelineNameEditField           matlab.ui.control.EditField
        PipelineNameEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        controller ControllerPipelineBuilder;
        manager PipelineBuilderManager;
        fileChooser
        textInput
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, type, fileChooser, textInput)
            
            % Clear persistent variables
            app.controller.clearSubjects();
            
            % Instantiate controller
            app.controller = ControllerPipelineBuilder();
            
            % Instantiate manager
            app.manager = PipelineBuilderManager(app);
                            
            if nargin == 1
                choice = ["EEG", "MEG"];
                type = string(uiconfirm(app.PipelineBuilderUIFigure, 'What type of analysis do you want to conduct ?', 'Type of analysis', 'Options', choice));
                app.fileChooser = DefaultFileChooser();
                app.textInput = DefaultTextInput();
            
            elseif nargin == 2
                app.fileChooser = DefaultFileChooser();
                app.textInput = DefaultTextInput();
                type = upper(type);
                
            elseif nargin == 3
                type = upper(type);
                app.fileChooser = fileChooser;
                app.textInput = DefaultTextInput();
                
            elseif nargin == 4
                type = upper(type);
                app.fileChooser = fileChooser;
                app.textInput = textInput;
                
            end
                
            switch type
                % If EEG
                case 'EEG'
                    app.EEGProcessesPanel.Visible = true;
                    app.MEGProcessesPanel.Visible = false;
                    
                % If MEG
                case 'MEG'
                   app.EEGProcessesPanel.Visible = false;
                   app.MEGProcessesPanel.Visible = true;
            end
            
            % Set type to drop down menu
            app.PipelineTypeDropDown.Value = upper(type);

            % Set type to controller
            app.controller.switchType(type);

        end

        % Button pushed function: SavePipelineButton
        function SavePipelineButtonPushed(app, event)

            % Clear pipeline
            app.controller.clearPipeline();
            
            % Get pipeline's name and extension
            [~, filename, extension] = fileparts(app.PipelineNameEditField.Value);

            % If name is empty
            if isempty(filename)
                uialert(app.PipelineBuilderUIFigure, ...
                    'Enter the name of the pipeline',...
                    'Error');
                 uiwait(gcbf)
                 return
                 
            % If extension is empty
            elseif isempty(extension)
                uialert(app.PipelineBuilderUIFigure, ...
                    'Enter the extension of the pipeline',...
                    'Error');
                 uiwait(gcbf)
                 return

            % If name and extension are not empty
            else

                % Assign name to pipeline
                app.controller.setPipelineName(filename);

                % Assign extension to pipeline
                app.controller.setPipelineExtension(extension);

            end
            
            % Assign folder to pipeline
            app.controller.setPipelineFolder(app.SaveinFolderEditField.Value);
            
            % If currrent type is EEG
            if app.PipelineTypeDropDown.Value == "EEG"
                app.manager.getEEGPipelineProcesses();

            % If currrent type is MEG
            elseif app.PipelineTypeDropDown.Value == "MEG"
                %Pip.Processes = app.getMEGPipelineProcesses;
            end
                      
            % Save pipeline
            app.controller.savePipeline();

        end

        % Button pushed function: ImportPipelineButton
        function ImportPipelineButtonPushed(app, event)
          
            % Ask user for pipeline to import            
            [fileName, folderPath] = app.fileChooser.chooseFile(...
                app.controller.getPipelineSupportedExtensionToGetFile, ...
                'Select file', ...
                app.controller.getPipelineSearchPath);
            %[fileName, folderPath, status] = app.fileChooser.chooseFile('*.*');
            
            if isequal(fileName, 0)
                return
            end
            
            % Assign search path
            app.controller.setPipelineSearchPath(folderPath);
            
            % Load pipeline
            app.controller.loadPipeline(fullfile(folderPath, fileName));
            
            % Reset the interface
            app.ClearPipelineButtonPushed;
            
            % Update interface with pipeline
            app.manager.fillCheckBoxWithStructure(app.controller.getPipeline);
            
        end

        % Button pushed function: ClearPipelineButton
        function ClearPipelineButtonPushed(app, event)
            
            % Get EEG and MEG checkboxes
            eegChildren = app.EEGProcessesPanel.Children;
            megChildren = app.MEGProcessesPanel.Children;
            
            children = [eegChildren; megChildren];
            
            for i = 1:length(children)
                
                if isa(children(i), 'matlab.ui.control.CheckBox')
                
                    % Set value to false (unchecked)
                    children(i).Value = false;
                    
                end
            end
            
            % Empty name and folder values
            app.PipelineNameEditField.Value = '';
            app.SaveinFolderEditField.Value = '';

            % Show/Hide panels based on checked checkboxes
            app.manager.refreshVisibility();

        end

        % Button pushed function: BrowsePipelineButton
        function BrowsePipelineButtonPushed(app, event)
            
            % Ask user for a folder to save pipeline
            path = uigetdir(app.controller.getPipelineSearchPath());
            if isequal(path, 0)
                return
            end
            path = replace(path, '\', '/');

            % Update field
            app.SaveinFolderEditField.Value = path;

        end

        % Button pushed function: BrowseBIDSButton
        function BrowseBIDSButtonPushed(app, event)
            
            % Ask user for folder to save BIDS
            path = uigetdir(app.controller.getBidsSearchPath);
            if isequal(path, 0)
                return
            end
            path = replace(path, '\', '/');

            % Assign BIDS search path
            app.controller.setBidsSearchPath(path);
            
            % Update field
            app.BIDSFolderPathEditField.Value = path;

        end

        % Selection changed function: NotchGroup
        function NotchGroupSelectionChanged(app, event)
            switch app.NotchGroup.SelectedObject
                
                case app.EuropeButton
                    app.Frequence.Visible = false;
                    
                case app.NorthAmericaButton
                    app.Frequence.Visible = false;
                    
                case app.SpecificFrequenceButton
                    app.Frequence.Visible = true;
                    
            end
        end

        % Selection changed function: AddEEGPositionGroup
        function AddEEGPositionGroupSelectionChanged(app, event)
            switch app.AddEEGPositionGroup.SelectedObject
                
                case app.UseDefaultPatternButton
                    app.ChooseCapButtonGroup.Visible = true;
                    
                case app.ImportFileButton
                    app.ChooseCapButtonGroup.Visible = false;
            end
        end

        % Value changed function: PipelineTypeDropDown
        function PipelineTypeDropDownValueChanged(app, event)
            
            switch app.PipelineTypeDropDown.Value

                case 'EEG'
                    app.EEGProcessesPanel.Visible = true;
                    app.MEGProcessesPanel.Visible = false;
                    
                    checkBoxes = app.MEGProcessesPanel.Children;
                    for i = 1:length(checkBoxes)
                        checkBoxes(i).Value = false;
                    end
                    
                    app.controller.switchType("EEG");
                    
                case 'MEG'
                    app.MEGProcessesPanel.Visible = true;
                    app.EEGProcessesPanel.Visible = false;
                    
                    checkBoxes = app.EEGProcessesPanel.Children;
                    for i = 1:length(checkBoxes)
                        checkBoxes(i).Value = false;
                    end
                    
                    
                    app.controller.switchType("MEG");
            end
            
            app.manager.refreshVisibility;
            
        end

        % Value changed function: EEGReviewRawFilesCheckBox
        function EEGReviewRawFilesCheckBoxValueChanged(app, event)

            if app.EEGReviewRawFilesCheckBox.Value
                app.ReviewRawFilesPanel.Visible = true;
            else
                app.ReviewRawFilesPanel.Visible = false;
            end
            
        end

        % Value changed function: MEGReviewRawFilesCheckBox
        function MEGReviewRawFilesCheckBoxValueChanged(app, event)
            
            if app.MEGReviewRawFilesCheckBox.Value
                app.ReviewRawFilesPanel.Visible = true;
            else
                app.ReviewRawFilesPanel.Visible = false;
            end
            
        end

        % Value changed function: AddEEGPositionCheckBox
        function AddEEGPositionCheckBoxValueChanged(app, event)
            
            if app.AddEEGPositionCheckBox.Value
                app.AddEEGPositionGroup.Visible = true;
                app.AddEEGPositionGroupSelectionChanged;
            else
                app.AddEEGPositionGroup.Visible = false;
                app.ChooseCapButtonGroup.Visible = false;
            end
            
        end

        % Value changed function: NotchFilterEEGCheckBox
        function NotchFilterEEGCheckBoxValueChanged(app, event)
 
            if app.NotchFilterEEGCheckBox.Value
                app.NotchGroup.Visible = true;
            else
                app.NotchGroup.Visible = false;
            end
            
        end

        % Value changed function: NotchFilterMEGCheckBox
        function NotchFilterMEGCheckBoxValueChanged(app, event)
            
            if app.NotchFilterMEGCheckBox.Value
                app.NotchGroup.Visible = true;
            else
                app.NotchGroup.Visible = false;
            end
            
        end

        % Value changed function: BandPassFilterEEGCheckBox
        function BandPassFilterEEGCheckBoxValueChanged(app, event)

            if app.BandPassFilterEEGCheckBox.Value
                app.BandPassFrequencesPanel.Visible = true;
            else
                app.BandPassFrequencesPanel.Visible = false;
            end
            
        end

        % Value changed function: BandPassFilterMEGCheckBox
        function BandPassFilterMEGCheckBoxValueChanged(app, event)
           
            if app.BandPassFilterMEGCheckBox.Value
                app.BandPassFrequencesPanel.Visible = true;
            else
                app.BandPassFrequencesPanel.Visible = false;
            end
            
        end

        % Value changed function: PowerSpectrumDensityEEGCheckBox
        function PowerSpectrumDensityEEGCheckBoxValueChanged(app, event)
            
            if app.PowerSpectrumDensityEEGCheckBox.Value
                app.PSDPanel.Visible = true;            
            else
                app.PSDPanel.Visible = false; 
            end
            
        end

        % Value changed function: PowerSpectrumDensityMEGCheckBox
        function PowerSpectrumDensityMEGCheckBoxValueChanged(app, event)
            
            if (app.PowerSpectrumDensityMEGCheckBox.Value)              
                app.PSDPanel.Visible = true;            
            else
                app.PSDPanel.Visible = false; 
            end
            
        end

        % Value changed function: Frequence
        function FrequenceValueChanged(app, event)
            
            value = app.Frequence.Value;
            app.Frequence.Position(3) = length(value{1}) + 100;

        end

        % Value changed function: ICAEEGCheckBox
        function ICAEEGCheckBoxValueChanged(app, event)
            
           if (app.ICAEEGCheckBox.Value)              
               app.NumberofComponentsICAEditField.Visible = true;
               app.NumberofComponentsICAEditFieldLabel.Visible = true;
           else
               app.NumberofComponentsICAEditField.Visible = false;
               app.NumberofComponentsICAEditFieldLabel.Visible = false;
           end
            
        end

        % Value changed function: DetectArtifactCheckBox
        function DetectArtifactCheckBoxValueChanged(app, event)

            if (app.DetectArtifactCheckBox.Value)
                app.TypeofArtifactPanel.Visible = true;
            else 
                app.TypeofArtifactPanel.Visible = false;
            end
            
        end

        % Value changed function: SSPCheckBox
        function SSPCheckBoxValueChanged(app, event)
            
            if (app.SSPCheckBox.Value)
                app.EventNameSSPEditField.Visible = true;
                app.EventNameSSPEditFieldLabel.Visible = true;
            else
                app.EventNameSSPEditField.Visible = false;
                app.EventNameSSPEditFieldLabel.Visible = false;
            end
            
            
        end

        % Value changed function: ICAMEGCheckBox
        function ICAMEGCheckBoxValueChanged(app, event)

            if (app.ICAMEGCheckBox.Value)              
                app.NumberofComponentsICAEditField.Visible = true;
                app.NumberofComponentsICAEditFieldLabel.Visible = true;             
            else
                app.NumberofComponentsICAEditField.Visible = false;
                app.NumberofComponentsICAEditFieldLabel.Visible = false;
            end
            
        end

        % Value changed function: RemoveSimultaneousEventCheckBox
        function RemoveSimultaneousEventCheckBoxValueChanged(app, event)
           
            if app.RemoveSimultaneousEventCheckBox.Value
                app.RemoveSimultaneousEventPanel.Visible = true;
            else
                app.RemoveSimultaneousEventPanel.Visible = false;
            end
            
        end

        % Value changed function: ConverttoBIDSEEGCheckBox
        function ConverttoBIDSEEGCheckBoxValueChanged(app, event)
            
            if (app.ConverttoBIDSEEGCheckBox.Value)              
                app.BIDSPanel.Visible = true;            
            else
                app.BIDSPanel.Visible = false; 
            end
            
        end

        % Value changed function: ConverttoBIDSMEGCheckBox
        function ConverttoBIDSMEGCheckBoxValueChanged(app, event)
            
            if (app.ConverttoBIDSMEGCheckBox.Value)              
                app.BIDSPanel.Visible = true;            
            else
                app.BIDSPanel.Visible = false; 
            end
            
        end

        % Button pushed function: AddSubjectButton
        function AddSubjectButtonPushed(app, event)
            
            % Input for Subject Name
            subject_name = app.textInput.enterText('Enter the Subject Name:', 'Subject Name');

            if isempty(subject_name)
                return
            end
            
            [rawFilesPath, folder] = app.fileChooser.chooseFile(...
                app.controller.getSupportedDatasetFormatToGetFile(), ...
                'Select Data File', ...
                app.controller.getRawDataSearchPath(), 'on');
            
            if isequal(rawFilesPath, 0)
                return
            end

            % Assign data search path
            app.controller.setRawDataSearchPath(folder);
            
            rawFilesPath =  fullfile(folder, rawFilesPath);
            app.controller.addSubject(subject_name, rawFilesPath);
            
            app.ReviewRawFilesTextArea.Value = app.controller.convertReviewRawFilesParametersToCharacters();
            
        end

        % Button pushed function: DeleteSubjectButton
        function DeleteSubjectButtonPushed(app, event)
            
            % Get current subjects and files
            [subjects, ~] = app.controller.getReviewRawFilesParameters();

            % Ask user for subject to delete
            index = listdlg('ListString', subjects, 'PromptString', 'Select the subject to delete', 'Name', 'Delete Subject');
            if ~isequal(index, 0)
                % Delete subject
                app.controller.deleteSubjectInReviewRawFilesParameters(index);
            end

            % Refresh text area
            app.ReviewRawFilesTextArea.Value = app.controller.convertReviewRawFilesParametersToCharacters();

        end

        % Button pushed function: ClearSubjectsButton
        function ClearSubjectsButtonPushed(app, event)
            
            % Clear review raw files parameters
            app.controller.clearSubjects();

            % Reset review raw files text area
            app.ReviewRawFilesTextArea.Value = '';

        end

        % Close request function: PipelineBuilderUIFigure
        function PipelineBuilderUIFigureCloseRequest(app, event)
            
            % Confirm user wants to close the app
            rep = uiconfirm(app.PipelineBuilderUIFigure, 'Do you want to close the app ?', 'Close request');
            
            if rep == "OK"

                % Delete app
                delete(app)

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PipelineBuilderUIFigure = uifigure('Visible', 'off');
            app.PipelineBuilderUIFigure.Name = 'Pipeline Builder';
            app.PipelineBuilderUIFigure.CloseRequestFcn = createCallbackFcn(app, @PipelineBuilderUIFigureCloseRequest, true);
            app.PipelineNameEditFieldLabel = uilabel(app.PipelineBuilderUIFigure);
            app.PipelineNameEditFieldLabel.Text = 'Pipeline Name';
            app.PipelineNameEditField = uieditfield(app.PipelineBuilderUIFigure, 'text');
            app.SaveinFolderEditFieldLabel = uilabel(app.PipelineBuilderUIFigure);
            app.SaveinFolderEditFieldLabel.Text = 'Save in Folder';
            app.SaveinFolderEditField = uieditfield(app.PipelineBuilderUIFigure, 'text');
            app.BrowsePipelineButton = uibutton(app.PipelineBuilderUIFigure, 'push');
            app.BrowsePipelineButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsePipelineButtonPushed, true);
            app.BrowsePipelineButton.Text = 'Browse';
            app.NotchGroup = uibuttongroup(app.PipelineBuilderUIFigure);
            app.NotchGroup.SelectionChangedFcn = createCallbackFcn(app, @NotchGroupSelectionChanged, true);
            app.NotchGroup.Title = 'Notch Frequences';
            app.NotchGroup.Visible = 'off';
            app.NorthAmericaButton = uiradiobutton(app.NotchGroup);
            app.NorthAmericaButton.Text = 'North America (60Hz, 120Hz, 180Hz, 240Hz)';
            app.NorthAmericaButton.Value = true;
            app.EuropeButton = uiradiobutton(app.NotchGroup);
            app.EuropeButton.Text = 'Europe (50Hz, 100Hz, 150Hz, 200Hz)';
            app.SpecificFrequenceButton = uiradiobutton(app.NotchGroup);
            app.SpecificFrequenceButton.Text = 'Specific Frequence:';
            app.Frequence = uitextarea(app.NotchGroup);
            app.Frequence.ValueChangedFcn = createCallbackFcn(app, @FrequenceValueChanged, true);
            app.Frequence.Visible = 'off';
            app.BandPassFrequencesPanel = uipanel(app.PipelineBuilderUIFigure);
            app.BandPassFrequencesPanel.Title = 'Band Pass Frequences';
            app.BandPassFrequencesPanel.Visible = 'off';
            app.LowCutOffHzEditFieldLabel = uilabel(app.BandPassFrequencesPanel);
            app.LowCutOffHzEditFieldLabel.Text = 'Low Cut Off (Hz)';
            app.LowCutOffHzEditField = uieditfield(app.BandPassFrequencesPanel, 'numeric');
            app.HighCutOffHzEditFieldLabel = uilabel(app.BandPassFrequencesPanel);
            app.HighCutOffHzEditFieldLabel.Text = 'High Cut Off (Hz)';
            app.HighCutOffHzEditField = uieditfield(app.BandPassFrequencesPanel, 'numeric');
            app.AddEEGPositionGroup = uibuttongroup(app.PipelineBuilderUIFigure);
            app.AddEEGPositionGroup.SelectionChangedFcn = createCallbackFcn(app, @AddEEGPositionGroupSelectionChanged, true);
            app.AddEEGPositionGroup.Title = 'Original EEG position file';
            app.AddEEGPositionGroup.Visible = 'off';
            app.UseDefaultPatternButton = uiradiobutton(app.AddEEGPositionGroup);
            app.UseDefaultPatternButton.Text = 'Use Default Pattern';
            app.UseDefaultPatternButton.Value = true;
            app.ImportFileButton = uiradiobutton(app.AddEEGPositionGroup);
            app.ImportFileButton.Text = 'Import File';
            app.PipelineTypeDropDownLabel = uilabel(app.PipelineBuilderUIFigure);
            app.PipelineTypeDropDownLabel.Text = 'Pipeline Type';
            app.PipelineTypeDropDown = uidropdown(app.PipelineBuilderUIFigure);
            app.PipelineTypeDropDown.Items = {'EEG', 'MEG'};
            app.PipelineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PipelineTypeDropDownValueChanged, true);
            app.PipelineTypeDropDown.Value = 'EEG';
            app.NumberofComponentsICAEditFieldLabel = uilabel(app.PipelineBuilderUIFigure);
            app.NumberofComponentsICAEditFieldLabel.Visible = 'off';
            app.NumberofComponentsICAEditFieldLabel.Text = 'Number of Components (ICA)';
            app.NumberofComponentsICAEditField = uieditfield(app.PipelineBuilderUIFigure, 'numeric');
            app.NumberofComponentsICAEditField.Visible = 'off';
            app.EEGProcessesPanel = uipanel(app.PipelineBuilderUIFigure);
            app.EEGProcessesPanel.Title = 'EEG Processes';
            app.EEGProcessesPanel.Visible = 'off';
            app.AddEEGPositionCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.AddEEGPositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddEEGPositionCheckBoxValueChanged, true);
            app.AddEEGPositionCheckBox.Text = 'Add EEG Position';
            app.RefineRegistrationCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.RefineRegistrationCheckBox.Text = 'Refine Registration';
            app.ProjectElectrodeonScalpCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.ProjectElectrodeonScalpCheckBox.Text = 'Project Electrode on Scalp';
            app.NotchFilterEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.NotchFilterEEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchFilterEEGCheckBoxValueChanged, true);
            app.NotchFilterEEGCheckBox.Text = 'Notch Filter';
            app.BandPassFilterEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.BandPassFilterEEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @BandPassFilterEEGCheckBoxValueChanged, true);
            app.BandPassFilterEEGCheckBox.Text = 'Band-Pass Filter';
            app.PowerSpectrumDensityEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.PowerSpectrumDensityEEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @PowerSpectrumDensityEEGCheckBoxValueChanged, true);
            app.PowerSpectrumDensityEEGCheckBox.Text = 'Power Spectrum Density';
            app.AverageReferenceCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.AverageReferenceCheckBox.Text = 'Average Reference';
            app.ICAEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.ICAEEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ICAEEGCheckBoxValueChanged, true);
            app.ICAEEGCheckBox.Text = 'ICA';
            app.ConverttoBIDSEEGCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.ConverttoBIDSEEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConverttoBIDSEEGCheckBoxValueChanged, true);
            app.ConverttoBIDSEEGCheckBox.Text = 'Convert to BIDS';
            app.EEGReviewRawFilesCheckBox = uicheckbox(app.EEGProcessesPanel);
            app.EEGReviewRawFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EEGReviewRawFilesCheckBoxValueChanged, true);
            app.EEGReviewRawFilesCheckBox.Text = 'Review Raw Files';
            app.MEGProcessesPanel = uipanel(app.PipelineBuilderUIFigure);
            app.MEGProcessesPanel.Title = 'MEG Processes';
            app.MEGProcessesPanel.Visible = 'off';
            app.NotchFilterMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.NotchFilterMEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchFilterMEGCheckBoxValueChanged, true);
            app.NotchFilterMEGCheckBox.Text = 'Notch Filter';
            app.ConvertEpochtoContinueCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.ConvertEpochtoContinueCheckBox.Text = 'Convert Epoch to Continue';
            app.BandPassFilterMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.BandPassFilterMEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @BandPassFilterMEGCheckBoxValueChanged, true);
            app.BandPassFilterMEGCheckBox.Text = 'Band-Pass Filter';
            app.PowerSpectrumDensityMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.PowerSpectrumDensityMEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @PowerSpectrumDensityMEGCheckBoxValueChanged, true);
            app.PowerSpectrumDensityMEGCheckBox.Text = 'Power Spectrum Density';
            app.DetectArtifactCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.DetectArtifactCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetectArtifactCheckBoxValueChanged, true);
            app.DetectArtifactCheckBox.Text = 'Detect Artifact';
            app.SSPCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.SSPCheckBox.ValueChangedFcn = createCallbackFcn(app, @SSPCheckBoxValueChanged, true);
            app.SSPCheckBox.Text = 'SSP';
            app.RemoveSimultaneousEventCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.RemoveSimultaneousEventCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveSimultaneousEventCheckBoxValueChanged, true);
            app.RemoveSimultaneousEventCheckBox.Text = 'Remove Simultaneous Event';
            app.ICAMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.ICAMEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ICAMEGCheckBoxValueChanged, true);
            app.ICAMEGCheckBox.Text = 'ICA';
            app.ConverttoBIDSMEGCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.ConverttoBIDSMEGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConverttoBIDSMEGCheckBoxValueChanged, true);
            app.ConverttoBIDSMEGCheckBox.Text = 'Convert to BIDS';
            app.MEGReviewRawFilesCheckBox = uicheckbox(app.MEGProcessesPanel);
            app.MEGReviewRawFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MEGReviewRawFilesCheckBoxValueChanged, true);
            app.MEGReviewRawFilesCheckBox.Text = 'Review Raw Files';
            app.EventNameSSPEditFieldLabel = uilabel(app.PipelineBuilderUIFigure);
            app.EventNameSSPEditFieldLabel.Visible = 'off';
            app.EventNameSSPEditFieldLabel.Text = 'Event Name (SSP)';
            app.EventNameSSPEditField = uieditfield(app.PipelineBuilderUIFigure, 'text');
            app.EventNameSSPEditField.Visible = 'off';
            app.TypeofArtifactPanel = uipanel(app.PipelineBuilderUIFigure);
            app.TypeofArtifactPanel.Title = 'Type of Artifact';
            app.TypeofArtifactPanel.Visible = 'off';
            app.CardiacCheckBox = uicheckbox(app.TypeofArtifactPanel);
            app.CardiacCheckBox.Text = 'Cardiac';
            app.BlinkCheckBox = uicheckbox(app.TypeofArtifactPanel);
            app.BlinkCheckBox.Text = 'Blink';
            app.LowFrequencyeyeteethCheckBox = uicheckbox(app.TypeofArtifactPanel);
            app.LowFrequencyeyeteethCheckBox.Text = 'Low Frequency (eye/teeth)';
            app.HighFrequencymusclesensorCheckBox = uicheckbox(app.TypeofArtifactPanel);
            app.HighFrequencymusclesensorCheckBox.Text = 'High Frequency (muscle/sensor)';
            app.ChooseCapButtonGroup = uibuttongroup(app.PipelineBuilderUIFigure);
            app.ChooseCapButtonGroup.Title = 'Choose Cap';
            app.ChooseCapButtonGroup.Visible = 'off';
            app.Colin27BrainProductsEasyCap128Button = uiradiobutton(app.ChooseCapButtonGroup);
            app.Colin27BrainProductsEasyCap128Button.Text = 'Colin27: BrainProducts EasyCap 128';
            app.Colin27BrainProductsEasyCap128Button.Value = true;
            app.Colin27BrainProductsEasyCap64Button = uiradiobutton(app.ChooseCapButtonGroup);
            app.Colin27BrainProductsEasyCap64Button.Text = 'Colin27: BrainProducts EasyCap 64';
            app.RemoveSimultaneousEventPanel = uipanel(app.PipelineBuilderUIFigure);
            app.RemoveSimultaneousEventPanel.Title = 'Remove Simultaneous Event';
            app.RemoveSimultaneousEventPanel.Visible = 'off';
            app.EventtoRemoveEditFieldLabel = uilabel(app.RemoveSimultaneousEventPanel);
            app.EventtoRemoveEditFieldLabel.Text = 'Event to Remove';
            app.EventtoRemoveEditField = uieditfield(app.RemoveSimultaneousEventPanel, 'text');
            app.TargetEventEditFieldLabel = uilabel(app.RemoveSimultaneousEventPanel);
            app.TargetEventEditFieldLabel.Text = 'Target Event';
            app.TargetEventEditField = uieditfield(app.RemoveSimultaneousEventPanel, 'text');
            app.PipelinePanel = uipanel(app.PipelineBuilderUIFigure);
            app.PipelinePanel.Title = 'Pipeline';
            app.SavePipelineButton = uibutton(app.PipelinePanel, 'push');
            app.SavePipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SavePipelineButtonPushed, true);
            app.SavePipelineButton.Text = 'Save Pipeline';
            app.ImportPipelineButton = uibutton(app.PipelinePanel, 'push');
            app.ImportPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPipelineButtonPushed, true);
            app.ImportPipelineButton.Text = 'Import Pipeline';
            app.ClearPipelineButton = uibutton(app.PipelinePanel, 'push');
            app.ClearPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPipelineButtonPushed, true);
            app.ClearPipelineButton.Text = 'Clear Pipeline';
            app.BIDSPanel = uipanel(app.PipelineBuilderUIFigure);
            app.BIDSPanel.Title = 'BIDS';
            app.BIDSPanel.Visible = 'off';
            app.BIDSFolderNameEditFieldLabel = uilabel(app.BIDSPanel);
            app.BIDSFolderNameEditFieldLabel.Text = 'BIDS Folder Name';
            app.BIDSFolderNameEditField = uieditfield(app.BIDSPanel, 'text');
            app.BIDSFolderPathEditFieldLabel = uilabel(app.BIDSPanel);
            app.BIDSFolderPathEditFieldLabel.Text = 'BIDS Folder Path';
            app.BIDSFolderPathEditField = uieditfield(app.BIDSPanel, 'text');
            app.BrowseBIDSButton = uibutton(app.BIDSPanel, 'push');
            app.BrowseBIDSButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBIDSButtonPushed, true);
            app.BrowseBIDSButton.Text = 'Browse';
            app.ReviewRawFilesPanel = uipanel(app.PipelineBuilderUIFigure);
            app.ReviewRawFilesPanel.Title = 'Review Raw Files';
            app.ReviewRawFilesPanel.Visible = 'off';
            app.ReviewRawFilesTextArea = uitextarea(app.ReviewRawFilesPanel);
            app.AddSubjectButton = uibutton(app.ReviewRawFilesPanel, 'push');
            app.AddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubjectButtonPushed, true);
            app.AddSubjectButton.Text = 'Add Subject';
            app.DeleteSubjectButton = uibutton(app.ReviewRawFilesPanel, 'push');
            app.DeleteSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSubjectButtonPushed, true);
            app.DeleteSubjectButton.Text = 'Delete Subject';
            app.ClearSubjectsButton = uibutton(app.ReviewRawFilesPanel, 'push');
            app.ClearSubjectsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSubjectsButtonPushed, true);
            app.ClearSubjectsButton.Text = 'Clear Subjects';
            app.PSDPanel = uipanel(app.PipelineBuilderUIFigure);
            app.PSDPanel.Title = 'PSD';
            app.PSDPanel.Visible = 'off';
            app.WindowLengthEditFieldLabel = uilabel(app.PSDPanel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.PSDPanel, 'numeric');
            % Show the figure after all components are created
            app.PipelineBuilderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pipeline_Builder(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PipelineBuilderUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PipelineBuilderUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PipelineBuilderUIFigure)
        end
    end
end