classdef ListInput < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ListBox       matlab.ui.control.ListBox
        CancelButton  matlab.ui.control.Button
        OKButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, choice, title, multiselect)
            app.UIFigure.Name = title;
            app.UIFigure.Position(1) = 500;
            app.UIFigure.Position(2) = 300;
            app.CallingApp = mainApp;
            
            c = cell(1,length(choice));
            for i = 1:length(choice)
                c{i} = choice{i};
            end
            app.ListBox.Items = choice;
            app.ListBox.Multiselect = multiselect;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            returnTextInputValues(app.CallingApp, app.ListBox.Value);
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            returnTextInputValues(app.CallingApp, '');
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            returnTextInputValues(app.CallingApp, '');
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ListBox = uilistbox(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ListInput(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end